classdef OlympiaConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        SaveTool                        matlab.ui.container.toolbar.PushTool
        InfoTool                        matlab.ui.container.toolbar.PushTool
        CloseTool                       matlab.ui.container.toolbar.PushTool
        LogpanelPanel                   matlab.ui.container.Panel
        IdleLamp                        matlab.ui.control.Lamp
        IdleLampLabel                   matlab.ui.control.Label
        ExportlogButton                 matlab.ui.control.Button
        ClearlogButton                  matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        SavingLabel                     matlab.ui.control.Label
        OperationsLabel                 matlab.ui.control.Label
        MainsettingsLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        IncludeconfigIDCheckBox         matlab.ui.control.CheckBox
        TaskSelectionButtonGroup        matlab.ui.container.ButtonGroup
        GeneralButton                   matlab.ui.control.ToggleButton
        LongjumpingButton               matlab.ui.control.ToggleButton
        RunningButton                   matlab.ui.control.ToggleButton
        WalkingButton                   matlab.ui.control.ToggleButton
        SbjBrowseButton                 matlab.ui.control.Button
        SbjPathEditField                matlab.ui.control.EditField
        ConfirmCalObjButton             matlab.ui.control.Button
        AddCalObjButton                 matlab.ui.control.Button
        CalobjDropDown                  matlab.ui.control.DropDown
        SelectthebiomechanicalmodeltobeappliedLabel_2  matlab.ui.control.Label
        AddModelButton                  matlab.ui.control.Button
        ConfirmModelButton              matlab.ui.control.Button
        ModelDropDown                   matlab.ui.control.DropDown
        SelectthebiomechanicalmodeltobeappliedLabel  matlab.ui.control.Label
        SelectpathtostoretheconfigfileButton  matlab.ui.control.Button
        Path2saveEditField              matlab.ui.control.EditField
        FilenameEditField               matlab.ui.control.EditField
        ConfignameLabel                 matlab.ui.control.Label
        CalibratedmarkersButton         matlab.ui.control.Button
        VirtualmarkersreconstructionmethodButton  matlab.ui.control.Button
        UpdateSegmentsButton            matlab.ui.control.Button
        INAIL_2                         matlab.ui.control.Image
        INAIL                           matlab.ui.control.Image
        UNIPD_2                         matlab.ui.control.Image
        LeftProsthAlignmentPanel        matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SAPEditField_2                  matlab.ui.control.NumericEditField
        SAPLabel_2                      matlab.ui.control.Label
        TAPEditField_2                  matlab.ui.control.NumericEditField
        TAPEditField_2Label             matlab.ui.control.Label
        PreflexionEditField_2           matlab.ui.control.NumericEditField
        PreflexionLabel_2               matlab.ui.control.Label
        IDEditField_2                   matlab.ui.control.EditField
        IDEditField_2Label              matlab.ui.control.Label
        RightProsthAlignmentPanel       matlab.ui.container.Panel
        IDEditField                     matlab.ui.control.EditField
        PreflexionEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TAPEditField                    matlab.ui.control.NumericEditField
        cmLabel                         matlab.ui.control.Label
        SAPEditField                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        SAPLabel                        matlab.ui.control.Label
        TAPEditFieldLabel               matlab.ui.control.Label
        PreflexionLabel                 matlab.ui.control.Label
        IDEditFieldLabel                matlab.ui.control.Label
        RightProsthKneeSet              matlab.ui.container.ButtonGroup
        Image3                          matlab.ui.control.Image
        RightOttobock3S80Button         matlab.ui.control.RadioButton
        LeftProsthKneeSet               matlab.ui.container.ButtonGroup
        Image3_2                        matlab.ui.control.Image
        LeftOttobock3S80Button          matlab.ui.control.RadioButton
        RightRPFset                     matlab.ui.container.ButtonGroup
        PrototypeLabel                  matlab.ui.control.Label
        RightPrototypeCheckBox          matlab.ui.control.CheckBox
        RightInternalRefEditFieldLabel  matlab.ui.control.Label
        RightSerialNEditFieldLabel      matlab.ui.control.Label
        RightModelDropDownLabel         matlab.ui.control.Label
        RightManufacturerDropDownLabel  matlab.ui.control.Label
        RightCATSpinnerLabel            matlab.ui.control.Label
        CSfoot_starClamp_2              matlab.ui.control.Image
        CCfoot_srdClamp_2               matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        RightCFootwstarclampButton      matlab.ui.control.RadioButton
        RightCFootwsquareclampButton    matlab.ui.control.RadioButton
        RightJFootButton                matlab.ui.control.RadioButton
        RightManufacturerDropDown       matlab.ui.control.DropDown
        RightModelDropDown              matlab.ui.control.DropDown
        RightCATSpinner                 matlab.ui.control.Spinner
        RightSerialNEditField           matlab.ui.control.EditField
        RightInternalRefEditField       matlab.ui.control.EditField
        LeftLLAmpLabel                  matlab.ui.control.Label
        RightLLAmpLabel                 matlab.ui.control.Label
        LeftULAmpLabel                  matlab.ui.control.Label
        RightULAmpLabel                 matlab.ui.control.Label
        RightULDropDown                 matlab.ui.control.DropDown
        LeftULDropDown                  matlab.ui.control.DropDown
        RightLLDropDown                 matlab.ui.control.DropDown
        LeftLLDropDown                  matlab.ui.control.DropDown
        LeftRPFset                      matlab.ui.container.ButtonGroup
        LeftPrototypeCheckBox           matlab.ui.control.CheckBox
        PrototypeLabel_2                matlab.ui.control.Label
        LeftInternalRefEditFieldLabel   matlab.ui.control.Label
        LeftCFootwstarclampButton       matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        LeftCFootwsquareclampButton     matlab.ui.control.RadioButton
        CCfoot_srdClamp                 matlab.ui.control.Image
        LeftJFootButton                 matlab.ui.control.RadioButton
        CSfoot_starClamp                matlab.ui.control.Image
        LeftManufacturerDropDown        matlab.ui.control.DropDown
        LeftModelDropDown               matlab.ui.control.DropDown
        LeftCATSpinner                  matlab.ui.control.Spinner
        LeftSerialNEditField            matlab.ui.control.EditField
        LeftSerialNEditFieldLabel       matlab.ui.control.Label
        LeftInternalRefEditField        matlab.ui.control.EditField
        LeftModelDropDownLabel          matlab.ui.control.Label
        LeftManufacturerDropDownLabel   matlab.ui.control.Label
        LeftCATSpinnerLabel             matlab.ui.control.Label
        ModelConfiguratorLabel          matlab.ui.control.Label
        OLYMPIA                         matlab.ui.control.Image
        UNIPD                           matlab.ui.control.Image
        ParticipantsettingPanel         matlab.ui.container.Panel
        SessionDateLabel                matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        SexatbirthDropDown              matlab.ui.control.DropDown
        SexatbirthDropDownLabel         matlab.ui.control.Label
        DiagnosisDropDown               matlab.ui.control.DropDown
        DiagnosisDropDownLabel          matlab.ui.control.Label
        DiagnosisEditField              matlab.ui.control.EditField
        SessionDatePicker               matlab.ui.control.DatePicker
        SessionIDEditField              matlab.ui.control.EditField
        ConfigurationEditField          matlab.ui.control.EditField
        ConfigurationEditFieldLabel     matlab.ui.control.Label
        SessionIDEditFieldLabel         matlab.ui.control.Label
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        MasskgSpinner                   matlab.ui.control.Spinner
        MasskgSpinnerLabel              matlab.ui.control.Label
        LowerBodyImg                    matlab.ui.control.Image
        UpperBodyImg                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        attribute = "_att";
        subject;
        GeneralSeg;
        GeneralJoints;
        UpperLimbSeg;
        UpperLimbJoints;
        RightLLseg;
        RightLLJoints;
        LeftLLseg;
        LeftLLJoints;
        CalObject;
        CalObjectList;
        segments;
        joints;
        landmarks_to_create;
        sbjroot;
        markertemplate; % Description
        markerinfo;
        CSdef;
        KinDef;
        MarkerSet;
        KinematicModel;
        selectedRows; % Description
        LogRecord = cell(0);
        lampon = [0.29, 0.86, 0.25] % Green lamp light (on status)
        lampoff = [0.80, 0.80, 0.80]; % Gray lamp light (off)
        lamperr = [0.86, 0.01, 0.01]; % Red lamp light (error)
    end

    properties (Constant)
        Name = "OlympiaConfigurator";
        Version = "2.8.1";
        Author = "Di Marco, Roberto";
        Summary = "updated on 2026-May-06";
        Description = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainroot, task)
            % function startupFcn(app, mainroot, xmlchoice, jsonchoice, task)
            arguments
                app;
                mainroot (1,1) string = "";
                %xmlchoice (1,1) logical = 1;
                %jsonchoice (1,1) logical = 1;
                task (1,1) string = "running";
            end

            for i = 1: length(app.TaskSelectionButtonGroup.Buttons)
                if strcmpi(app.TaskSelectionButtonGroup.Buttons(i).Text, task)
                    app.TaskSelectionButtonGroup.Buttons(i).Value = 1;
                    app.LeftRPFset.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.RightProsthAlignmentPanel.Visible = "off";
                else
                    app.TaskSelectionButtonGroup.Buttons(i).Value = 0;
                    app.LeftRPFset.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.RightProsthAlignmentPanel.Visible = "on";
                end
            end
            
            model_list = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), 'Models', 'list.models'), 'FileType', 'xml', 'AttributeSuffix', app.attribute);
            try
                app.CalObjectList = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()),"CalibrationObjects", "Wands.calobj"), "FileType", "xml", "AttributeSuffix", app.attribute);
            catch
                % app.CalObjectList = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "CalibrationObjects", "JSON", "Wands.calobj"), "FileType", "json");
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No models found in the working directory.']};
                app.LogTextArea.Value = app.LogRecord;  
            end
            app.ModelDropDown.Items = [model_list.Model.(strcat("label", app.attribute))]';
            app.ModelDropDown.Value = app.ModelDropDown.Items(1);
            app.CalobjDropDown.Items = ["None"; fieldnames(app.CalObjectList)];
            app.CalobjDropDown.Value = app.CalobjDropDown.Items(1);

            try
                app.SbjPathEditField.Value = mainroot;
                app.sbjroot = mainroot;
                sbjpath = fileparts(app.SbjPathEditField.Value);
                [~, sbjname] = fileparts(sbjpath);
                
                app.subject = readstruct(fullfile(sbjpath, strcat(sbjname, ".subject")), "FileType", "xml", "AttributeSuffix", app.attribute);
                side = {"left", "right"};

                if isfield(app.subject, "amputation")
                    % check if stored amputation are already given
                    limbs = fieldnames(app.subject.amputation);
                    for i = 1:length(limbs)
                        for j = 1:2
                            if strcmp(limbs{i}, "upperlimb") && strcmp(side{j}, "left")
                                app.LeftULDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                            elseif strcmp(limbs{i}, "upperlimb") && strcmp(side{j}, "right")
                                app.RightULDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                            elseif strcmp(limbs{i}, "lowerlimb") && strcmp(side{j}, "left")
                                app.LeftLLDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                                LeftLLDropDownValueChanged(app);
                            else % strcmp(limbs{i}, "lowerlimb") && strcmp(side{j}, "right")
                                app.RightLLDropDown.Value = app.subject.amputation.(limbs{i}).(side{j});
                                RightLLDropDownValueChanged(app)
                            end

                        end
                    end
                    
                    SwitchValueChanged(app)

                else
                    app.LeftLLDropDown.Value = "none";
                    app.LeftULDropDown.Value = "none";
                    app.RightLLDropDown.Value = "none";
                    app.RightULDropDown.Value = "none";

                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "off";

                    app.RightProsthAlignmentPanel.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "off";
                end
            catch
                if ~strcmp(mainroot, "")
                    app.SbjPathEditField.Value = mainroot;
                    app.sbjroot = mainroot;
                end
                app.LeftLLDropDown.Value = "none";
                app.LeftULDropDown.Value = "none";
                app.RightLLDropDown.Value = "none";
                app.RightULDropDown.Value = "none";

                app.LeftProsthAlignmentPanel.Visible = "off";
                app.LeftProsthKneeSet.Visible = "off";
                app.LeftRPFset.Visible = "off";

                app.RightProsthAlignmentPanel.Visible = "off";
                app.RightProsthKneeSet.Visible = "off";
                app.RightRPFset.Visible = "off";
                %msg = msgbox("Please select or create a Subject folder to save the config file!", "No subject configuration file was found", "warn", "modal");
                %uiwait(msg);
            end
            
            %app.xmlCheckBox.Value = xmlchoice;
            %app.jsonCheckBox.Value = jsonchoice;

            % DEPRECATED IN VERSION 1.1 - All informations are now in one *.MarkerSet file: app.markerinfo = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.markerinfo"), "FileType", "xml", "AttributeSuffix", "");
            % app.coordtemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.coord"), "FileType", "xml", "AttributeSuffix", "");
            % app.kintemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Sequences.kinematics"), "FileType", "xml", "AttributeSuffix", "");
            
            % app.landmarks_to_create = repmat(struct('labels', '', 'method', '', 'points', {}), [1, 2]);
            % app.landmarks_to_create(1).labels = "LHJC";
            % app.landmarks_to_create(1).method = "Bell";
            % app.landmarks_to_create(1).points = ["LASIS", "RASIS", "LPSIS", "RPSIS"];
            % 
            % app.landmarks_to_create(2).labels = "RHJC";
            % app.landmarks_to_create(2).method = "Bell";
            % app.landmarks_to_create(2).points = ["LASIS", "RASIS", "LPSIS", "RPSIS"];

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - READY: The OlympiaConfigurator has correctly been set.']};
            app.LogTextArea.Value = app.LogRecord;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
                case "Full body"
                    app.LeftLLDropDown.Visible = "on";
                    app.LeftLLAmpLabel.Visible = "on";
                    app.RightLLDropDown.Visible = "on";
                    app.RightLLAmpLabel.Visible = "on";
                    app.LeftULDropDown.Visible = "on";
                    app.LeftULAmpLabel.Visible = "on";
                    app.RightULDropDown.Visible = "on";
                    app.RightULAmpLabel.Visible = "on";
                    app.UpperBodyImg.Visible = "on";
                    app.LowerBodyImg.Visible = "on";
                    % app.GeneralSeg = {"Head", "Trunk", "Pelvis"};
                    % app.GeneralJoints = {"Cervical", "Back"};
                    % app.UpperLimbSeg = {"LeftArm", "LeftForearm", "RightArm", "RightForearm"};
                    % app.UpperLimbJoints = {"LeftShoulder", "LeftElbow", "RightShoulder", "RightElbow"};
                case "Lower body"
                    app.LeftLLDropDown.Visible = "on";
                    app.LeftLLAmpLabel.Visible = "on";
                    app.RightLLDropDown.Visible = "on";
                    app.RightLLAmpLabel.Visible = "on";
                    app.LeftULDropDown.Visible = "off";
                    app.LeftULAmpLabel.Visible = "off";
                    app.RightULDropDown.Visible = "off";
                    app.RightULAmpLabel.Visible = "off";
                    app.UpperBodyImg.Visible = "off";
                    app.LowerBodyImg.Visible = "on";
                    % app.GeneralSeg = {"Pelvis"};
                    % app.GeneralJoints = {};
                    % app.UpperLimbSeg = {};
                    % app.UpperLimbJoints = {};
            end
        end

        % Value changed function: RightLLDropDown
        function RightLLDropDownValueChanged(app, event)
            switch app.RightLLDropDown.Value
                case "none"
                    app.RightProsthAlignmentPanel.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    % app.RightLLseg = {"rthigh", "rshank", "rfoot"};
                    % app.RightLLJoints = {"rh", "rk", "ra"};
                case "TT"
                    %panels appear but no knee
                    app.RightProsthAlignmentPanel.Visible = "on";
                    app.RightProsthKneeSet.Visible = "off";
                    app.RightRPFset.Visible = "on";
                    app.RightJFootButton.Value = 1;
                    app.RightCFootwsquareclampButton.Enable = "off";
                    app.RightCFootwstarclampButton.Enable = "off";
                    % app.RightLLseg = {"rthigh", "rsocket", "rfootclamp_TT", "rprostFoot_prox", "rprostFoot_dist", "rhjc_k", "rgt_k"}; %"rprostFoot_dist", 
                    % app.RightLLJoints = {"rh", "rktt", "rpa"};
                case "TF"
                    %all panels appear"
                    app.RightProsthAlignmentPanel.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.RightCFootwsquareclampButton.Enable = "on";
                    app.RightCFootwstarclampButton.Enable = "on";
                    % app.RightLLseg = {"rsocket", "rsocketclamp", "rprostKnee_prox", "rprostKnee_dist", "rfootclamp_TF", "rprostFoot_prox", "rprostFoot_dist", "rhjc_k", "rgt_k"};
                    % app.RightLLJoints = {"rhtf", "rpk", "rpa"};
            end
        end

        % Value changed function: LeftLLDropDown
        function LeftLLDropDownValueChanged(app, event)
            switch app.LeftLLDropDown.Value
                case "none"
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "off";
                    % app.LeftLLseg = {"lthigh", "lshank", "lfoot"};
                    % app.LeftLLJoints = {"lh", "lk", "la"};
                case "TT"
                    %panels appear but no knee
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.LeftRPFset.Visible = "on";
                    app.LeftJFootButton.Value = 1;
                    app.LeftCFootwsquareclampButton.Enable = "off";
                    app.LeftCFootwstarclampButton.Enable = "off";
                    % app.LeftLLseg = {"lsocket", "lfootclamp_TT", "lprostFoot_prox", "lprostFoot_dist", "lhjc_k", "lgt_k"}; %"lprostFoot_dist", 
                    % app.LeftLLJoints = {"lh", "lktt", "lpa"};
                case "TF"
                    %all panels appear"
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.LeftRPFset.Visible = "on";
                    app.LeftCFootwsquareclampButton.Enable = "on";
                    app.LeftCFootwstarclampButton.Enable = "on";
                    % app.LeftLLseg = {"lsocket", "lsocketclamp", "lprostKnee_prox", "lprostKnee_dist", "lfootclamp_TF", "lprostFoot_prox", "lprostFoot_dist", "lhjc_k", "lgt_k"};
                    % app.LeftLLJoints = {"lhtf", "lpk", "lpa"};
            end
        end

        % Callback function: SaveTool
        function SAVEButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            if or(strcmp(app.FilenameEditField.Value, ""), strcmp(app.FilenameEditField.Value, ''))
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: The configuration must have a name.']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: File not saved.']};
                app.LogTextArea.Value = app.LogRecord;                
                %wrn = warndlg("The configuration must have a name", "No config name", "modal");
                %uiwait(wrn);
                return;
            else
                if isfile(fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, ".configuration")))
                    answer = questdlg({"A configuration file with same name already exists,", "do you want to overwrite that?"}, "Config exists", "Yes", "No", "No");
                    switch answer
                        case "Yes"
                            % do nothing
                        case "No"
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: File not saved (chose another name for the file).']};
                            app.LogTextArea.Value = app.LogRecord;

                            %wrn = warndlg("Choose another name", "Choose Config name", "modal");
                            %uiwait(wrn);
                            return;
                    end
                end
            end

            config.subject.("ID" + app.attribute) = app.ParticipantIDEditField.Value;
            config.subject.("gender" + app.attribute) = app.SexatbirthDropDown.Value;
            config.session.("ID" + app.attribute) = app.SessionIDEditField.Value;
            config.session.("date" + app.attribute) = string(app.SessionDatePicker.Value,"yyyy-MM-dd");
            config.("label" + app.attribute) = app.ConfigurationEditField.Value;
            
            config.mass.("value" + app.attribute) = app.MasskgSpinner.Value;
            config.mass.("unit" + app.attribute) = "kg";

            if strcmpi(app.DiagnosisDropDown, "other")
                config.subject.("diagnosis" + app.attribute) = app.DiagnosisEditField.Value;
            else
                config.subject.("diagnosis" + app.attribute) = app.DiagnosisDropDown.Value;
            end

            config.subject.("right_upper_body" + app.attribute) = app.RightULDropDown.Value;
            config.subject.("left_upper_body" + app.attribute) = app.LeftULDropDown.Value;

            config.subject.("right_lower_body" + app.attribute) = app.RightLLDropDown.Value;
            config.subject.("left_lower_body" + app.attribute) = app.LeftLLDropDown.Value;

            switch app.RightLLDropDown.Value
                case "TF"
                    %Right TF
                    config.medical_device.right.("type" + app.attribute) = "Running Specific Prosthesis";
                    
                    %alignment
                    config.medical_device.right.alignment.("ID" + app.attribute) = app.IDEditField.Value;
                    config.medical_device.right.alignment.("angle" + app.attribute) = app.PreflexionEditField.Value;
                    config.medical_device.right.alignment.("unit" + app.attribute) = "deg";
                    config.medical_device.right.alignment.TAP.("value" + app.attribute) = app.TAPEditField.Value;
                    config.medical_device.right.alignment.TAP.("unit" + app.attribute) = "cm";
                    config.medical_device.right.alignment.SAP.("value" + app.attribute) = app.SAPEditField.Value;
                    config.medical_device.right.alignment.SAP.("unit" + app.attribute) = "degree";

                    % prosthetic knee
                    if app.RightOttobock3S80Button.Value
                            config.medical_device.right.knee.("manufacturer" + app.attribute) = "Ottobock";
                            config.medical_device.right.knee.("model" + app.attribute) = "3S80";
                    end

                    % prosthetic foot
                    config.medical_device.right.foot.("manufacturer" + app.attribute) = app.RightManufacturerDropDown.Value;
                    config.medical_device.right.foot.("model" + app.attribute) = app.RightModelDropDown.Value;
                    config.medical_device.right.foot.("prototype" + app.attribute) = app.RightPrototypeCheckBox.Value;
                    config.medical_device.right.foot.("category" + app.attribute) = app.RightCATSpinner.Value;
                    config.medical_device.right.foot.("serial_number" + app.attribute) = app.RightSerialNEditField.Value;
                    config.medical_device.right.foot.("internal_ref" + app.attribute) = app.RightInternalRefEditField.Value;

                case "TT"
                    %Right TT
                    config.medical_device.right.("type" + app.attribute) = "Running Specific Prosthesis";

                    %alignment
                    config.medical_device.right.alignment.("ID" + app.attribute) = app.IDEditField.Value;
                    config.medical_device.right.alignment.("angle" + app.attribute) = app.PreflexionEditField.Value;
                    config.medical_device.right.alignment.("unit" + app.attribute) = "deg";
                    config.medical_device.right.alignment.TAP.("value" + app.attribute) = app.TAPEditField.Value;
                    config.medical_device.right.alignment.TAP.("unit" + app.attribute) = "cm";
                    config.medical_device.right.alignment.SAP.("value" + app.attribute) = app.SAPEditField.Value;
                    config.medical_device.right.alignment.SAP.("unit" + app.attribute) = "degree";

                    % prosthetic foot
                    config.medical_device.right.foot.("manufacturer" + app.attribute) = app.RightManufacturerDropDown.Value;
                    config.medical_device.right.foot.("model" + app.attribute) = app.RightModelDropDown.Value;
                    config.medical_device.right.foot.("prototype" + app.attribute) = app.RightPrototypeCheckBox.Value;
                    config.medical_device.right.foot.("category" + app.attribute) = app.RightCATSpinner.Value;
                    config.medical_device.right.foot.("serial_number" + app.attribute) = app.RightSerialNEditField.Value;
                    config.medical_device.right.foot.("internal_ref" + app.attribute) = app.RightInternalRefEditField.Value;

                case "none"
                    % Right sound limb - NO MEDICAL DEVICE STORED IN THE
                    % CONFIG FILE
            end
            switch app.LeftLLDropDown.Value
                case "TF"
                    %Left TF
                    config.medical_device.left.("type" + app.attribute) = "Running Specific Prosthesis";

                    %alignment
                    config.medical_device.left.alignment.("ID" + app.attribute) = app.IDEditField_2.Value;
                    config.medical_device.left.alignment.("angle" + app.attribute) = app.PreflexionEditField_2.Value;
                    config.medical_device.left.alignment.("unit" + app.attribute) = "deg";
                    config.medical_device.left.alignment.TAP.("value" + app.attribute) = app.TAPEditField_2.Value;
                    config.medical_device.left.alignment.TAP.("unit" + app.attribute) = "cm";
                    config.medical_device.left.alignment.SAP.("value" + app.attribute) = app.SAPEditField_2.Value;
                    config.medical_device.left.alignment.SAP.("unit" + app.attribute) = "degree";

                    % prosthetic knee
                    if app.LeftOttobock3S80Button.Value
                            config.medical_device.left.knee.("manufacturer" + app.attribute) = "Ottobock";
                            config.medical_device.left.knee.("model" + app.attribute) = "3S80";
                    end

                    % prosthetic foot
                    config.medical_device.left.foot.("manufacturer" + app.attribute) = app.LeftManufacturerDropDown.Value;
                    config.medical_device.left.foot.("model" + app.attribute) = app.LeftModelDropDown.Value;
                    config.medical_device.left.foot.("prototype" + app.attribute) = app.LeftPrototypeCheckBox.Value;
                    config.medical_device.left.foot.("category" + app.attribute) = app.LeftCATSpinner.Value;
                    config.medical_device.left.foot.("serial_number" + app.attribute) = app.LeftSerialNEditField.Value;
                    config.medical_device.left.foot.("internal_ref" + app.attribute) = app.LeftInternalRefEditField.Value;

                case "TT"
                    %Left TT
                    config.medical_device.left.("type" + app.attribute) = "Running Specific Prosthesis";

                    %alignment
                    config.medical_device.left.alignment.("ID" + app.attribute) = app.IDEditField_2.Value;
                    config.medical_device.left.alignment.("angle" + app.attribute) = app.PreflexionEditField_2.Value;
                    config.medical_device.left.alignment.("unit" + app.attribute) = "deg";
                    config.medical_device.left.alignment.TAP.("value" + app.attribute) = app.TAPEditField_2.Value;
                    config.medical_device.left.alignment.TAP.("unit" + app.attribute) = "cm";
                    config.medical_device.left.alignment.SAP.("value" + app.attribute) = app.SAPEditField_2.Value;
                    config.medical_device.left.alignment.SAP.("unit" + app.attribute) = "degree";

                    % prosthetic foot
                    config.medical_device.left.foot.("manufacturer" + app.attribute) = app.LeftManufacturerDropDown.Value;
                    config.medical_device.left.foot.("model" + app.attribute) = app.LeftModelDropDown.Value;
                    config.medical_device.left.foot.("prototype" + app.attribute) = app.LeftPrototypeCheckBox.Value;
                    config.medical_device.left.foot.("category" + app.attribute) = app.LeftCATSpinner.Value;
                    config.medical_device.left.foot.("serial_number" + app.attribute) = app.LeftSerialNEditField.Value;
                    config.medical_device.left.foot.("internal_ref" + app.attribute) = app.LeftInternalRefEditField.Value;
                    
                case "none"
                    %Left sound limb - NO MEDICAL DEVICE STORED IN THE
                    % CONFIG FILE
            end
            
            % CONFIG FILE
            %config_json = config;
            %config_json.MarkerSet = app.MarkerSet;
            %config_json.KinematicModel = app.KinematicModel;
            config.MarkerSet = app.MarkerSet;
            config.KinematicModel = app.KinematicModel;
            
            %    
            % field = fieldnames(app.MarkerSet);
            % for i = 1:numel(field)
            %     config.MarkerSet.(field{i}).Attributes = app.MarkerSet.(field{i});
            % end
            % clear field;
            % %
            % field = fieldnames(app.KinematicModel);
            % for i = 1:numel(field)
            %     if strcmp(field{i}, "Segment")
            %         subfields = fieldnames(app.KinematicModel.(field{i}));
            %         select = logical(matches(subfields, "CoordSys"));
            %         config.KinematicModel.(field{i}).Attributes = app.KinematicModel.(field{i});
            %         config.KinematicModel.(field{i}).Attributes = rmfield(config.KinematicModel.(field{i}).Attributes, "CoordSys");
            %         config.KinematicModel.(field{i}).Value = app.KinematicModel.(field{i});
            %         config.KinematicModel.(field{i}).Value = rmfield(config.KinematicModel.(field{i}).Value, subfields(~select));
            %     else
            %         config.KinematicModel.(field{i}).Attributes = app.KinematicModel.(field{i});
            %     end
            % end
            % 
            
            try
                if app.IncludeconfigIDCheckBox.Value
                    filename = app.FilenameEditField.Value + "_" + app.ConfigurationEditField.Value;
                else
                    filename = app.FilenameEditField.Value;
                end
                writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(filename, ".xml")), "StructNodeName", "configuration", "AttributeSuffix", app.attribute, "FileType", "xml");
            catch ME
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Configuration file not saved.']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', ME.message]};
                app.LogTextArea.Value = app.LogRecord;
                app.IdleLamp.Color = app.lamperr;
                app.IdleLampLabel.Text = "Error!";
            end

            % DEPRECATED in version 2.6 (20-Jan-2026): All config files 
            % will be saved as XML only.
            % if app.xmlCheckBox.Value && ~app.jsonCheckBox.Value
            %     writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
            % elseif ~app.xmlCheckBox.Value && app.jsonCheckBox.Value
            %     writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
            %     temp = readstruct(fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", "", "FileType", "xml");
            %     delete(fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")));
            %     writestruct(temp, fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_json.configuration")), "FileType", "json");
            % elseif app.xmlCheckBox.Value && app.jsonCheckBox.Value
            %     writestruct(config, fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", app.attribute, "FileType", "xml");
            %     temp = readstruct(fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_xml.configuration")), "StructNodeName", "config_info", "AttributeSuffix", "", "FileType", "xml");
            %     writestruct(temp, fullfile(app.Path2saveEditField.Value, strcat(app.FilenameEditField.Value, "_json.configuration")), "FileType", "json");
            % else
            %     app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Select at least one file format between XML or JSON.']};
            %     app.LogTextArea.Value = app.LogRecord;
            % 
            %     % err = errordlg("Select at least one file format between XML or JSON.", "Select a file format!", "modal");
            %     % uiwait(err);
            % end

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Done: Configuration saved!']};
            app.LogTextArea.Value = app.LogRecord;

            % msg = msgbox("Configuration saved!", "modal");
            % uiwait(msg);
            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: CalibratedmarkersButton
        function CalibratedmarkersButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)
            
            tocalibrate = [app.MarkerSet.Marker(and(~matches([app.MarkerSet.Marker.(strcat("type", app.attribute))], "V"), [app.MarkerSet.Marker.(strcat("calibrated", app.attribute))] == 1)).(strcat("label", app.attribute))];
            
            for i = 1:length(app.MarkerSet.VirtualMarkerDef)
                tocalibrate(matches(tocalibrate, app.MarkerSet.VirtualMarkerDef(i).(strcat("label", app.attribute)))) = [];
            end
            
            app.MarkerSet.CalibratedMarkerDef = repmat(struct("label", [], "wand", [], "source", []), 1, length(tocalibrate));
            for i = 1:length(tocalibrate)
                app.MarkerSet.CalibratedMarkerDef(i).(strcat("label", app.attribute)) = tocalibrate(i);
                if strcmp(app.CalobjDropDown.Value, "None")
                    app.MarkerSet.CalibratedMarkerDef(i).(strcat("wand", app.attribute)) = "";
                else
                    app.MarkerSet.CalibratedMarkerDef(i).(strcat("wand", app.attribute)) = string(app.CalobjDropDown.Value);
                end
                app.MarkerSet.CalibratedMarkerDef(i).(strcat("source", app.attribute)) = "";
            end

            %app.SAVEButton.Enable = "on";
            app.SaveTool.Enable = "on";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Done: Markers to be calibrated exported.']};
            app.LogTextArea.Value = app.LogRecord;

            % msg = msgbox("Markers to calibrate exported", "Done!", "modal");
            % uiwait(msg)
            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: VirtualmarkersreconstructionmethodButton
        function VirtualmarkersreconstructionmethodButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            fig = uifigure("Name", "Check virtual markers that have to be calculated");
            fig.Position = [250 250 1000 510];

            % Create ListBoxLabel
            VirtualMarkerListBoxLabel = uilabel(fig);
            VirtualMarkerListBoxLabel.HorizontalAlignment = 'left';
            VirtualMarkerListBoxLabel.Position = [10 470 100 30];
            VirtualMarkerListBoxLabel.Text = {'List of virtual', 'markers:'};
            VirtualMarkerListBoxLabel.FontWeight = 'bold';

            % Create ListBox
            VirtualMarkerListBox = uilistbox(fig);
            VirtualMarkerListBox.Position = [10 50 100 410];
            VirtualMarkerListBox.Items = [app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("type", app.attribute))], "V")).(strcat("label", app.attribute))];
            VirtualMarkerListBox.Multiselect = "off";

            % Create ListBoxLabel
            ReconstructionMethodLabel = uilabel(fig);
            ReconstructionMethodLabel.HorizontalAlignment = 'left';
            ReconstructionMethodLabel.Position = [120 470 150 30];
            ReconstructionMethodLabel.Text = {'Select the reconstruction', 'method'};
            ReconstructionMethodLabel.FontWeight = 'bold';
            
            % Create ListBox
            ReconstructionMethodListBox = uilistbox(fig);
            ReconstructionMethodListBox.Position = [120 310 150 150];
            ReconstructionMethodListBox.Items = {'Bell', 'Centroid'};
            ReconstructionMethodListBox.Multiselect = "off";

            % Create ListBoxLabel
            CompleteMarkerListBoxLabel = uilabel(fig);
            CompleteMarkerListBoxLabel.HorizontalAlignment = 'left';
            CompleteMarkerListBoxLabel.VerticalAlignment = 'top';
            CompleteMarkerListBoxLabel.Position = [280 470 100 30];
            CompleteMarkerListBoxLabel.Text = 'Markerset labels:';
            CompleteMarkerListBoxLabel.FontWeight = 'bold';

            % Create ListBox
            CompleteMarkerListBox = uilistbox(fig);
            CompleteMarkerListBox.Position = [280 50 100 410];
            CompleteMarkerListBox.Items = [app.MarkerSet.Marker.(strcat("label", app.attribute))];
            CompleteMarkerListBox.Multiselect = "on";

            % Create the table
            VirtualMarkerTable = uitable(fig);
            VirtualMarkerTable.ColumnName = {'Label', 'Method', 'Source'};
            VirtualMarkerTable.ColumnEditable = [false, true, true];
            VirtualMarkerTable.Position = [500 50 490 410]; % Specify position and size of the table
            VirtualMarkerTable.Data = [cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("label", app.attribute))])', ...
                                       cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("method", app.attribute))])', ...
                                       cellstr([app.MarkerSet.VirtualMarkerDef.(strcat("source", app.attribute))])'];
            VirtualMarkerTable.Multiselect = "on";
            VirtualMarkerTable.CellSelectionCallback = @(src, event) SelectedRow(app, event);

            uicontrol('Parent', fig, 'Position', [390 280 100 20], 'String', 'Add >>',...
                'Callback', @(src, in) AddButtonPushed(app, {VirtualMarkerListBox, ReconstructionMethodListBox, CompleteMarkerListBox, VirtualMarkerTable}),...
                'FontSize', 9, 'FontWeight', 'bold');

            uicontrol('Parent', fig, 'Position', [390 230 100 20], 'String', '<< Remove',...
                'Callback', @(src, in) RemoveButtonPushed(app, VirtualMarkerTable),...
                'FontSize', 9, 'FontWeight', 'bold');

            uicontrol('Parent', fig, 'Position', [480 10 100 25], 'String', 'Save and Close',...
                'Callback', @(src, in) SaveCloseButtonPushed(app, {VirtualMarkerTable, fig}),...
                'BackgroundColor', [0.7373 0.8706 0.5647], 'FontSize', 9, 'FontWeight', 'bold');

            function SelectedRow(app, event)
                app.selectedRows = unique(event.Indices(:,1)); 
            end

            function AddButtonPushed(~, in) %in = {VirtualMarkerListBox, ReconstructionMethodListBox, CompleteMarkerListBox, VirtualMarkerTable}
                in{4}.Data = [in{4}.Data; [cellstr(in{1}.Value) cellstr(in{2}.Value) cellstr(strjoin(in{3}.Value, ","))]];
            end

            function RemoveButtonPushed(app, in) %in = VirtualMarkerTable
                in.Data(app.selectedRows, :)=[];
            end

            function SaveCloseButtonPushed(app, in) %in = {VirtualMarkerTable, fig}
                for i = 1:size(in{1}.Data, 1)
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("label", app.attribute)) = in{1}.Data{i, 1};
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("method", app.attribute)) = in{1}.Data{i, 2};
                    app.MarkerSet.VirtualMarkerDef(i).(strcat("source", app.attribute)) = in{1}.Data{i, 3};
                end

                delete(in{2});
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Methods for virtual markers methods reconstruction exported.']};
                app.LogTextArea.Value = app.LogRecord;
            end

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: UpdateSegmentsButton
        function UpdateSegmentsButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            RightLLDropDownValueChanged(app)
            LeftLLDropDownValueChanged(app)

            app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", app.attribute);
            app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, strcat(app.ModelDropDown.Value, ".kinematics")), "FileType", "xml", "AttributeSuffix", app.attribute);
            app.CSdef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, strcat(app.ModelDropDown.Value, ".coord")), "FileType", "xml", "AttributeSuffix", app.attribute);
            
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - General model imported.']};
            app.LogTextArea.Value = app.LogRecord;

            % DEPRECATED in version 2.6 (20-Jan-2026): model files will be
            % XML format only.
            % if app.xmlCheckBox.Value
            %     app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", app.attribute);
            %     app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".kinematics")), "FileType", "xml", "AttributeSuffix", app.attribute);
            %     app.CSdef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".coord")), "FileType", "xml", "AttributeSuffix", app.attribute);
            % else
            %     app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "json");
            %     app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".kinematics")), "FileType", "json");
            %     app.KinDef = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".coord")), "FileType", "json");
            % end
            
            %%% --- DO EVERTYTHING AS IF FULL BODY IS ASKED --- %%%
            app.GeneralSeg = unique([app.CSdef.General.Segment.(strcat("label", app.attribute))], "stable");
            app.GeneralJoints = unique([app.KinDef.General.Joint.(strcat("label", app.attribute))], "stable");
            app.UpperLimbSeg = unique([app.CSdef.Unaffected_upper.Segment.(strcat("label", app.attribute))], "stable");%unique([app.markertemplate.Unaffected_upper.Marker.segment], "stable");
            app.UpperLimbJoints = unique([app.KinDef.Unaffected_upper.Joint.(strcat("label", app.attribute))], "stable");
            %%%%%%%%%%%%
            switch app.RightLLDropDown.Value
                case "none"
                    app.RightLLseg = unique([app.CSdef.Unaffected_lower.Segment(and(matches([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Unaffected_lower.Segment.(strcat("opt", app.attribute))] == 0)).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Unaffected_lower.Joint(and(matches([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Right"), [app.KinDef.Unaffected_lower.Joint.(strcat("opt", app.attribute))] == 0)).(strcat("label", app.attribute))], "stable");
                    % app.RightLLseg = unique([app.CSdef.Unaffected_lower.Segment(strcmp([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))], "Right")).(strcat("label", app.attribute))], "stable");
                    % app.RightLLJoints = unique([app.KinDef.Unaffected_lower.Joint(strcmp([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Right")).(strcat("label", app.attribute))], "stable");
                case "TF"
                    app.RightLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Right"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    % Auxiliary
                    app.RightLLseg = [app.RightLLseg, unique([app.CSdef.Auxiliary.Segment(and(matches([app.CSdef.Auxiliary.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Auxiliary.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable")];
                case "TT"
                    app.RightLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    app.RightLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Right"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    % Auxiliary
                    app.RightLLseg = [app.RightLLseg, unique([app.CSdef.Auxiliary.Segment(and(matches([app.CSdef.Auxiliary.Segment.(strcat("group", app.attribute))] , "Right"), [app.CSdef.Auxiliary.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable")];
            end
            %%%%%%%%%%%%
            switch app.LeftLLDropDown.Value
                case "none"
                    app.LeftLLseg = unique([app.CSdef.Unaffected_lower.Segment(and(matches([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Unaffected_lower.Segment.(strcat("opt", app.attribute))] == 0)).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Unaffected_lower.Joint(and(matches([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Left"), [app.KinDef.Unaffected_lower.Joint.(strcat("opt", app.attribute))] == 0)).(strcat("label", app.attribute))], "stable");
                    % app.LeftLLseg = unique([app.CSdef.Unaffected_lower.Segment(strcmp([app.CSdef.Unaffected_lower.Segment.(strcat("group", app.attribute))], "Left")).(strcat("label", app.attribute))], "stable");
                    % app.LeftLLJoints = unique([app.KinDef.Unaffected_lower.Joint(strcmp([app.KinDef.Unaffected_lower.Joint.(strcat("group", app.attribute))] , "Left")).(strcat("label", app.attribute))], "stable");
                case "TF"
                    app.LeftLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Left"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable");
                    % Auxiliary
                    app.LeftLLseg = [app.LeftLLseg, unique([app.CSdef.Auxiliary.Segment(and(matches([app.CSdef.Auxiliary.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Auxiliary.Segment.(strcat("opt", app.attribute))] == 2)).(strcat("label", app.attribute))], "stable")];
                case "TT"
                    app.LeftLLseg = unique([app.CSdef.Affected_lower.Segment(and(matches([app.CSdef.Affected_lower.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Affected_lower.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    app.LeftLLJoints = unique([app.KinDef.Affected_lower.Joint(and(matches([app.KinDef.Affected_lower.Joint.(strcat("group", app.attribute))] , "Left"), [app.KinDef.Affected_lower.Joint.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable");
                    % Auxiliary
                    app.LeftLLseg = [app.LeftLLseg, unique([app.CSdef.Auxiliary.Segment(and(matches([app.CSdef.Auxiliary.Segment.(strcat("group", app.attribute))] , "Left"), [app.CSdef.Auxiliary.Segment.(strcat("opt", app.attribute))] == 3)).(strcat("label", app.attribute))], "stable")];
            end
            %%% --- DELETE UPPER BODY IF LOWER BODY IS SELECTED --- %
            if strcmp(app.Switch.Value, "Lower body")
                app.GeneralSeg(1:2) = [];
                app.GeneralJoints = [];
                app.UpperLimbSeg = [];
                app.UpperLimbJoints = [];
            end            
        
            app.segments = [app.GeneralSeg, app.UpperLimbSeg, app.RightLLseg, app.LeftLLseg];
            app.joints = [app.GeneralJoints, app.UpperLimbJoints, app.RightLLJoints, app.LeftLLJoints];

            %%% --- CREATE MARKER LIST FROM TEMPLATE AND SETTINGS --- %%%
            app.MarkerSet.Marker = app.markertemplate.Marker(matches([app.markertemplate.Marker.(strcat("segment", app.attribute))], app.segments));
            %%% --- SELECT TF/TT SPECIFIC MARKERS --- %%%
            if matches(app.RightLLDropDown.Value, "TF")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Right"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 3)) = [];
            elseif matches(app.RightLLDropDown.Value, "TT")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Right"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 2)) = [];
            end
            if matches(app.LeftLLDropDown.Value, "TF")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Left"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 3)) = [];
            elseif matches(app.LeftLLDropDown.Value, "TT")
                app.MarkerSet.Marker(and(matches([app.MarkerSet.Marker.(strcat("group", app.attribute))], "Left"), [app.MarkerSet.Marker.(strcat("opt", app.attribute))] == 2)) = [];
            end

            if app.RightCFootwstarclampButton.Value
                app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("label", app.attribute))], ["RCLAL", "RCLPL", "RCLAM", "RCLPM"])) = [];
            end
            if app.LeftCFootwstarclampButton.Value
                app.MarkerSet.Marker(matches([app.MarkerSet.Marker.(strcat("label", app.attribute))], ["LCLAL", "LCLPL", "LCLAM", "LCLPM"])) = [];
            end

            %%% CREATE VIRTUAL MARKER LIST FROM TEMPLATE AND SETTINGS %%%
            app.MarkerSet.VirtualMarkerDef = app.markertemplate.VirtualMarkerDef(matches([app.markertemplate.VirtualMarkerDef.(strcat("label", app.attribute))], [app.MarkerSet.Marker.(strcat("label", app.attribute))]));
            if and(app.RightCFootwstarclampButton.Value, strcmp(app.RightLLDropDown.Value, 'TF'))
                app.MarkerSet.VirtualMarkerDef(matches([app.MarkerSet.VirtualMarkerDef.(strcat("label", app.attribute))], ["RCLP", "RCLA", "RCLL", "RCLM"])) = [];
            end
            if and(app.LeftCFootwstarclampButton.Value, strcmp(app.LeftLLDropDown.Value, 'TF'))
                app.MarkerSet.VirtualMarkerDef(matches([app.MarkerSet.VirtualMarkerDef.(strcat("label", app.attribute))], ["LCLP", "LCLA", "LCLL", "LCLM"])) = [];
            end

            %%% -- CREATE KINEMATIC MODEL FROM TEMPLATE AND SETTINGS -- %%%
            % ------- SELECT COORD SYS FROM TEMPLATE AND SETTINGS ------- %
            tempstruct = app.CSdef;
            fields = fieldnames(tempstruct);
            tempstruct.Segment = tempstruct.(fields{1}).Segment;
            for i = 2:numel(fields)
                tempstruct.Segment = [tempstruct.Segment tempstruct.(fields{i}).Segment];
            end
            app.KinematicModel.Segment = tempstruct.Segment;
            %%% --- SELECT TF/TT SPECIFIC JOINTS --- %%%
            switch app.RightLLDropDown.Value
                case "none"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg) - ...% delete those with same name for TF or TT
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectr = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2));
                    % selectr = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.RightLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbSeg)
                selectr = selectr + and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.UpperLimbSeg)); % correct to not delete those pertaining to the upper body (if considered)
            end
            
            switch app.LeftLLDropDown.Value
                case "none"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg) - ...% delete those with same name for TF or TT
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectl = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2));
                    % selectl = and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.LeftLLseg), ...
                    %              or([app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Segment.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbSeg)
                selectl = selectl + and(matches([app.KinematicModel.Segment.(strcat("group", app.attribute))], "Left"), matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.UpperLimbSeg)); % correct to not delete those pertaining to the upper body (if considered)
            end
            
            %
            if strcmp(app.Switch.Value, "Full body")
                select = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], app.GeneralSeg) + ...
                        + selectr + selectl;
            else
                select = matches([app.KinematicModel.Segment.(strcat("label", app.attribute))], "pelvis", "IgnoreCase", true) + selectr + selectl;
            end
            app.KinematicModel.Segment(~logical(select)) = [];

            % -------- SELECT JOINTS FROM TEMPLATE AND SETTINGS -------- %
            tempstruct = app.KinDef;
            fields = fieldnames(tempstruct);
            tempstruct.Joint = tempstruct.(fields{1}).Joint;
            for i = 2:numel(fields)
                if matches(fields{i}, "KinematicChain")
                    continue;
                else
                    tempstruct.Joint = [tempstruct.Joint tempstruct.(fields{i}).Joint];
                end
            end
            app.KinematicModel.Joint = tempstruct.Joint;
            %%% --- SELECT TF/TT SPECIFIC JOINTS --- %%%
            switch app.RightLLDropDown.Value
                case "none"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectr = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2));
                    % selectr = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.RightLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbJoints)
                selectr = selectr + and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Right"), matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.UpperLimbJoints)); % correct to not delete those pertaining to the upper body (if considered)
            end
                             
            
            switch app.LeftLLDropDown.Value
                case "none"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TF"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 3)));
                case "TT"
                    selectl = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints) - ...% delete those on the side not matching the required joint name
                              and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2));
                    % selectl = and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), ~matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints)) + ...% delete those on the side not matching the required joint name
                    %          and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), and(matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.LeftLLJoints), ...
                    %              or([app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 0, [app.KinematicModel.Joint.(strcat("opt", app.attribute))] == 2)));
            end
            if ~isempty(app.UpperLimbJoints)
                selectl = selectl + and(matches([app.KinematicModel.Joint.(strcat("group", app.attribute))], "Left"), matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.UpperLimbJoints)); % correct to not delete those pertaining to the upper body (if considered)
            end
            if strcmp(app.Switch.Value, "Full body")
                select = matches([app.KinematicModel.Joint.(strcat("label", app.attribute))], app.GeneralJoints) + ...
                        + selectr + selectl;
            else
                select = selectr+selectl;
            end
            app.KinematicModel.Joint(~logical(select)) = [];
            
            % ---- SELECT KINEMATIC CHAIN FROM TEMPLATE AND SETTINGS ---- %
            switch app.RightLLDropDown.Value
                case "none"
                    selectr = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Right"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 0);
                case "TF"
                    selectr = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Right"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 2);
                case "TT"
                    selectr = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Right"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 3);
            end
            switch app.LeftLLDropDown.Value
                case "none"
                    selectl = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Left"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 0);
                case "TF"
                    selectl = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Left"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 2);
                case "TT"
                    selectl = and(matches([app.KinDef.KinematicChain.chain.(strcat("group", app.attribute))], "Left"), [app.KinDef.KinematicChain.chain.(strcat("opt", app.attribute))] == 3);
            end
            select = selectr+selectl;
            app.KinematicModel.KinematicChain = app.KinDef.KinematicChain.chain(logical(select));
            %app.KinematicModel.KinematicChain(2) = app.KinDef.KinematicChain.(temp)(matches([app.KinDef.KinematicChain.(temp).(strcat("group", app.attribute))], "Left"));

            % DEPRECATED IN VERSION 1.1:
            % app.landmarks_to_reconstruct = [];
            % for i = 1:length(app.joints)
            %     jointcenters = ["RKJC", "RAJC", "LKJC", "LAJC"];
            %     points4centers = [{"RFLE", "RFME"}; {"RLM", "RMM"}; {"LFLE", "LFME"}; {"LLM", "LMM"}];
            %     if ismember(app.joints(i), ["rk", "ra", "lk", "la"])
            %         if isempty(app.landmarks_to_create)
            %             app.landmarks_to_create = struct('labels', '', 'method', '', 'points', {});
            %             app.landmarks_to_create.labels = jointcenters(["rk", "ra", "lk", "la"] == app.joints);
            %             app.landmarks_to_create.method = "midpoint";
            %             app.landmarks_to_create.points = string(points4centers(["rk", "ra", "lk", "la"] == app.joints, :));
            %         else
            %             keys = fieldnames(app.landmarks_to_create);
            %             values = {jointcenters(["rk", "ra", "lk", "la"] == app.joints(i)), "midpoint", string(points4centers(["rk", "ra", "lk", "la"] == app.joints(i), :))};
            %             temp = cell2struct(values, keys, 2);
            %             app.landmarks_to_create = [app.landmarks_to_create, temp];
            %         end
            %     end
            % end
            
            app.VirtualmarkersreconstructionmethodButton.Enable = "on";
            app.CalibratedmarkersButton.Enable = "on";
            app.Path2saveEditField.Enable = "on";
            app.SelectpathtostoretheconfigfileButton.Enable = "on";
            app.Path2saveEditField.Value = app.sbjroot;
            app.CloseTool.Enable = "on";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segments created as configurated!']};
            app.LogTextArea.Value = app.LogRecord;

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;

            % msg = msgbox("Segments created as configurated!", "modal");
            % uiwait(msg);
        end

        % Button pushed function: ConfirmModelButton
        function ConfirmModelButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", "");
            % DEPRECATED in version 2.6 (20-Jan-2026): model and config
            % file will be XML format only.
            % if app.xmlCheckBox.Value
            %     app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "XML", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "xml", "AttributeSuffix", "");
            % else
            %     app.markertemplate = readstruct(fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), "Models", app.ModelDropDown.Value, "JSON", strcat(app.ModelDropDown.Value, ".markerlist")), "FileType", "json");
            % end
            app.ConfirmModelButton.Enable = "off";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', app.ModelDropDown.Value, ' model selected.']};
            app.LogTextArea.Value = app.LogRecord;

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: ConfirmCalObjButton
        function ConfirmCalObjButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            if strcmp(app.CalobjDropDown.Value, "None")
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No calibration object selected!']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - To select a calibration object, use the DropDown menu and "Confirm" the selection.']};
                app.LogTextArea.Value = app.LogRecord;
               %  msg = msgbox(["To select a calibration object, use the", "DropDown menu and 'Confirm' the selection."], "No calibration object selected!", "warn", "modal");
               % uiwait(msg);
            else
                app.CalObject = app.CalObjectList.(app.CalobjDropDown.Value);
            end
            app.ConfirmCalObjButton.Enable = "off";

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', app.CalobjDropDown.Value, ' chosen as calibration object.']};
            app.LogTextArea.Value = app.LogRecord;

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.ConfirmModelButton.Enable = "on";
        end

        % Value changed function: CalobjDropDown
        function CalobjDropDownValueChanged(app, event)
            app.ConfirmCalObjButton.Enable = "on";
        end

        % Button pushed function: SelectpathtostoretheconfigfileButton
        function SelectpathtostoretheconfigfileButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            app.Path2saveEditField.Value = uigetdir();
            %app.SAVEButton.Enable = "on";
            app.SaveTool.Enable = "on";

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: SbjBrowseButton
        function SbjBrowseButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            selectedfolder = uigetdir();
            try
                [path, folder] = fileparts(selectedfolder);
            catch
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Subject folder not selected.']};
                app.LogTextArea.Value = app.LogRecord;
                % err = warndlg("No folder selected", "Subject folder not selected");
                % uiwait(err)
                return
            end
            app.SbjPathEditField.Value = fullfile(path, folder);
            app.sbjroot = app.SbjPathEditField.Value;
            app.Path2saveEditField.Value = app.SbjPathEditField.Value;

            % app.LeftLLDropDown.Value = "none";
            % app.LeftULDropDown.Value = "none";
            % app.RightLLDropDown.Value = "none";
            % app.RightULDropDown.Value = "none";
            % 
            % app.LeftProsthAlignmentPanel.Visible = "off";
            % app.LeftProsthKneeSet.Visible = "off";
            % app.LeftRPFset.Visible = "off";
            % 
            % app.RightProsthAlignmentPanel.Visible = "off";
            % app.RightProsthKneeSet.Visible = "off";
            % app.RightRPFset.Visible = "off";

            %app.markertemplate = readstruct(fullfile(app.sbjroot, "SourceXML/Olympia.MarkerSet"), "FileType", "xml", "AttributeSuffix", "");
            RightLLDropDownValueChanged(app)
            LeftLLDropDownValueChanged(app)

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Callback function: CloseTool
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            app.LogRecord = cell(0);
            app.LogTextArea.Value = cell(0,1);

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Button pushed function: ExportlogButton
        function ExportlogButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            if isempty(app.SbjPathEditField.Value)
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No subject folder selected!']};
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - The log file is exported by default in the subject folder.']};
                app.LogTextArea.Value = app.LogRecord;
            else
                fid = fopen(fullfile(app.SbjPathEditField.Value, ['log_export_', char(datetime('now','Format','yyyy_MM_dd_HH_mm_ss')), '.txt']), 'w');
                for i = 1:length(app.LogTextArea.Value)
                    fprintf(fid, '%s\n', app.LogTextArea.Value{i});
                end
                fclose(fid);
            end

            app.IdleLampLabel.Text = "Idle";
            app.IdleLamp.Color = app.lampoff;
        end

        % Selection changed function: TaskSelectionButtonGroup
        function TaskSelectionButtonGroupSelectionChanged(app, event)
            switch lower(app.TaskSelectionButtonGroup.SelectedObject.Text)
                case "walking"
                    app.LeftRPFset.Visible = "off";
                    app.RightRPFset.Visible = "off";
                    app.LeftProsthKneeSet.Visible = "off";
                    app.RightProsthKneeSet.Visible = "off";
                    app.LeftProsthAlignmentPanel.Visible = "off";
                    app.RightProsthAlignmentPanel.Visible = "off";
                case "running"
                    app.LeftRPFset.Visible = "on";
                    app.RightRPFset.Visible = "on";
                    app.LeftProsthKneeSet.Visible = "on";
                    app.RightProsthKneeSet.Visible = "on";
                    app.LeftProsthAlignmentPanel.Visible = "on";
                    app.RightProsthAlignmentPanel.Visible = "on";
            end
        end

        % Button pushed function: AddModelButton
        function AddModelButtonPushed(app, event)
            model = ModelCreator(app.MainPathEditField.Value);
            %model = ModelCreator(app.MainPathEditField.Value, app.xmlCheckBox.Value, app.jsonCheckBox.Value); % updated in version 2.6
        end

        % Callback function: InfoTool
        function InfoToolClicked(app, event)
            msg = sprintf(['Name: %s\n' ...
                'Version: %s\n' ...
                'Summary: %s\n' ...
                'Author: %s\n' ...
                'Description: %s'], ...
                app.Name, app.Version, app.Summary, app.Author, app.Description);

            % Mostra finestra di dialogo con icona info
            uialert(app.UIFigure, msg, 'App Info', 'Icon','info');
        end

        % Value changed function: DiagnosisDropDown
        function DiagnosisDropDownValueChanged(app, event)
            switch app.DiagnosisDropDown.Value
                case "Other"
                    app.DiagnosisEditField.Enable = true;
                    % Upper body
                    app.RightULAmpLabel.Enable = true;
                    app.RightULDropDown.Enable = true;
                    app.LeftULAmpLabel.Enable = true;
                    app.LeftULDropDown.Enable = true;
                    % Lower body
                    app.RightLLAmpLabel.Enable = true;
                    app.RightLLDropDown.Enable = true;
                    app.LeftLLAmpLabel.Enable = true;
                    app.LeftLLDropDown.Enable = true;
                case "None"
                    app.DiagnosisEditField.Enable = false;
                    % Upper body
                    app.RightULAmpLabel.Enable = false;
                    app.RightULDropDown.Enable = false;
                    app.LeftULAmpLabel.Enable = false;
                    app.LeftULDropDown.Enable = false;
                    % Lower body
                    app.RightLLAmpLabel.Enable = false;
                    app.RightLLDropDown.Enable = false;
                    app.LeftLLAmpLabel.Enable = false;
                    app.LeftLLDropDown.Enable = false;
                case "TransFemoral amputation"
                    app.DiagnosisEditField.Enable = false;
                    % Upper body
                    app.RightULAmpLabel.Enable = false;
                    app.RightULDropDown.Enable = false;
                    app.LeftULAmpLabel.Enable = false;
                    app.LeftULDropDown.Enable = false;
                    % Lower body
                    app.RightLLAmpLabel.Enable = true;
                    app.RightLLDropDown.Enable = true;
                    app.LeftLLAmpLabel.Enable = true;
                    app.LeftLLDropDown.Enable = true;
                case "TransTibial amputation"
                    app.DiagnosisEditField.Enable = false;
                    % Upper body
                    app.RightULAmpLabel.Enable = false;
                    app.RightULDropDown.Enable = false;
                    app.LeftULAmpLabel.Enable = false;
                    app.LeftULDropDown.Enable = false;
                    % Lower body
                    app.RightLLAmpLabel.Enable = true;
                    app.RightLLDropDown.Enable = true;
                    app.LeftLLAmpLabel.Enable = true;
                    app.LeftLLDropDown.Enable = true;
                case "TransHumeral amputation"
                    app.DiagnosisEditField.Enable = false;
                    % Upper body
                    app.RightULAmpLabel.Enable = true;
                    app.RightULDropDown.Enable = true;
                    app.LeftULAmpLabel.Enable = true;
                    app.LeftULDropDown.Enable = true;
                    % Lower body
                    app.RightLLAmpLabel.Enable = false;
                    app.RightLLDropDown.Enable = false;
                    app.LeftLLAmpLabel.Enable = false;
                    app.LeftLLDropDown.Enable = false;
                case "TransRadial amputation"
                    app.DiagnosisEditField.Enable = false;
                    % Upper body
                    app.RightULAmpLabel.Enable = true;
                    app.RightULDropDown.Enable = true;
                    app.LeftULAmpLabel.Enable = true;
                    app.LeftULDropDown.Enable = true;
                    % Lower body
                    app.RightLLAmpLabel.Enable = false;
                    app.RightLLDropDown.Enable = false;
                    app.LeftLLAmpLabel.Enable = false;
                    app.LeftLLDropDown.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SaveTool = uipushtool(app.Toolbar);
            app.SaveTool.ClickedCallback = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SaveTool.Enable = 'off';
            app.SaveTool.Icon = 'file_save.png';
            app.InfoTool = uipushtool(app.Toolbar);
            app.InfoTool.Tooltip = {'Import configuration file'};
            app.InfoTool.ClickedCallback = createCallbackFcn(app, @InfoToolClicked, true);
            app.InfoTool.Icon = 'help_ug.png';
            app.CloseTool = uipushtool(app.Toolbar);
            app.CloseTool.ClickedCallback = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseTool.Icon = 'vote-no.png';
            app.ParticipantsettingPanel = uipanel(app.UIFigure);
            app.ParticipantsettingPanel.Title = 'Participant setting';
            app.UpperBodyImg = uiimage(app.ParticipantsettingPanel);
            app.UpperBodyImg.ImageSource = 'upper_body.png';
            app.LowerBodyImg = uiimage(app.ParticipantsettingPanel);
            app.LowerBodyImg.ImageSource = 'lower_body.png';
            app.MasskgSpinnerLabel = uilabel(app.ParticipantsettingPanel);
            app.MasskgSpinnerLabel.Text = 'Mass (kg)';
            app.MasskgSpinner = uispinner(app.ParticipantsettingPanel);
            app.MasskgSpinner.Step = 0.1;
            app.MasskgSpinner.Limits = [0 300];
            app.Switch = uiswitch(app.ParticipantsettingPanel, 'slider');
            app.Switch.Items = {'Full body', 'Lower body'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Full body';
            app.ParticipantIDEditFieldLabel = uilabel(app.ParticipantsettingPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.SessionIDEditFieldLabel = uilabel(app.ParticipantsettingPanel);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.ConfigurationEditFieldLabel = uilabel(app.ParticipantsettingPanel);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationEditField = uieditfield(app.ParticipantsettingPanel, 'text');
            app.SessionIDEditField = uieditfield(app.ParticipantsettingPanel, 'text');
            app.SessionDatePicker = uidatepicker(app.ParticipantsettingPanel);
            app.DiagnosisEditField = uieditfield(app.ParticipantsettingPanel, 'text');
            app.DiagnosisEditField.Enable = 'off';
            app.DiagnosisDropDownLabel = uilabel(app.ParticipantsettingPanel);
            app.DiagnosisDropDownLabel.Text = 'Diagnosis';
            app.DiagnosisDropDown = uidropdown(app.ParticipantsettingPanel);
            app.DiagnosisDropDown.Items = {'None', 'Transfemoral amputation', 'Transtibial amputation', 'Transhumeral amputation', 'Transradial amputation', 'Other'};
            app.DiagnosisDropDown.ValueChangedFcn = createCallbackFcn(app, @DiagnosisDropDownValueChanged, true);
            app.DiagnosisDropDown.Value = 'None';
            app.SexatbirthDropDownLabel = uilabel(app.ParticipantsettingPanel);
            app.SexatbirthDropDownLabel.Text = 'Sex at birth';
            app.SexatbirthDropDown = uidropdown(app.ParticipantsettingPanel);
            app.SexatbirthDropDown.Items = {'Male', 'Female'};
            app.SexatbirthDropDown.Value = 'Male';
            app.ParticipantIDEditField = uieditfield(app.ParticipantsettingPanel, 'text');
            app.SessionDateLabel = uilabel(app.ParticipantsettingPanel);
            app.SessionDateLabel.Text = 'Session - Date';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.ModelConfiguratorLabel = uilabel(app.UIFigure);
            app.ModelConfiguratorLabel.Text = 'Model Configurator';
            app.LeftRPFset = uibuttongroup(app.UIFigure);
            app.LeftRPFset.Title = 'Left Prosthetic Foot';
            app.LeftCATSpinnerLabel = uilabel(app.LeftRPFset);
            app.LeftCATSpinnerLabel.Text = 'CAT:';
            app.LeftManufacturerDropDownLabel = uilabel(app.LeftRPFset);
            app.LeftManufacturerDropDownLabel.Text = 'Manufacturer';
            app.LeftModelDropDownLabel = uilabel(app.LeftRPFset);
            app.LeftModelDropDownLabel.Text = 'Model';
            app.LeftInternalRefEditField = uieditfield(app.LeftRPFset, 'text');
            app.LeftSerialNEditFieldLabel = uilabel(app.LeftRPFset);
            app.LeftSerialNEditFieldLabel.Text = 'Serial N.';
            app.LeftSerialNEditField = uieditfield(app.LeftRPFset, 'text');
            app.LeftCATSpinner = uispinner(app.LeftRPFset);
            app.LeftCATSpinner.Step = 0.1;
            app.LeftCATSpinner.Limits = [0 10];
            app.LeftModelDropDown = uidropdown(app.LeftRPFset);
            app.LeftModelDropDown.Items = {'Cheetah Xpanse', 'Cheetah Xcel', 'Cheetah Xceed', 'Cheetah Xtend', 'Cheetah Xtreme', 'Flex-Foot Cheetah', 'Cheetah Xplore', 'Flex-Run', '1E91 - Runner Standard', '1E91 - Runner Curved', '1E91 - Runner Straight Even', '1E91 - Long Jump', '1E90 - Sprinter', '1E96 - Sprinter Custom', 'Obsedian'};
            app.LeftModelDropDown.Placeholder = 'Select...';
            app.LeftModelDropDown.Value = 'Cheetah Xpanse';
            app.LeftManufacturerDropDown = uidropdown(app.LeftRPFset);
            app.LeftManufacturerDropDown.Items = {'Fillauer', 'Ossur', 'Ottobock', 'Other'};
            app.LeftManufacturerDropDown.Placeholder = 'Select...';
            app.LeftManufacturerDropDown.Value = 'Ottobock';
            app.CSfoot_starClamp = uiimage(app.LeftRPFset);
            app.CSfoot_starClamp.ImageSource = 'J-foot.png';
            app.LeftJFootButton = uiradiobutton(app.LeftRPFset);
            app.LeftJFootButton.Text = 'J-Foot';
            app.CCfoot_srdClamp = uiimage(app.LeftRPFset);
            app.CCfoot_srdClamp.ImageSource = 'CC-foot_clamp.png';
            app.LeftCFootwsquareclampButton = uiradiobutton(app.LeftRPFset);
            app.LeftCFootwsquareclampButton.Text = 'C-Foot w/ square clamp';
            app.Image2 = uiimage(app.LeftRPFset);
            app.Image2.ImageSource = 'CS-foot_clamp.png';
            app.LeftCFootwstarclampButton = uiradiobutton(app.LeftRPFset);
            app.LeftCFootwstarclampButton.Text = 'C-Foot w/ star clamp';
            app.LeftCFootwstarclampButton.Value = true;
            app.LeftInternalRefEditFieldLabel = uilabel(app.LeftRPFset);
            app.LeftInternalRefEditFieldLabel.Text = 'Internal Ref.';
            app.PrototypeLabel_2 = uilabel(app.LeftRPFset);
            app.PrototypeLabel_2.Text = 'Prototype';
            app.LeftPrototypeCheckBox = uicheckbox(app.LeftRPFset);
            app.LeftPrototypeCheckBox.Text = '';
            app.LeftLLDropDown = uidropdown(app.UIFigure);
            app.LeftLLDropDown.Items = {'none', 'TF', 'TT'};
            app.LeftLLDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftLLDropDownValueChanged, true);
            app.LeftLLDropDown.Value = 'none';
            app.RightLLDropDown = uidropdown(app.UIFigure);
            app.RightLLDropDown.Items = {'none', 'TF', 'TT'};
            app.RightLLDropDown.ValueChangedFcn = createCallbackFcn(app, @RightLLDropDownValueChanged, true);
            app.RightLLDropDown.Value = 'none';
            app.LeftULDropDown = uidropdown(app.UIFigure);
            app.LeftULDropDown.Items = {'none', 'TH', 'TR'};
            app.LeftULDropDown.Value = 'none';
            app.RightULDropDown = uidropdown(app.UIFigure);
            app.RightULDropDown.Items = {'none', 'TH', 'TR'};
            app.RightULDropDown.Value = 'none';
            app.RightULAmpLabel = uilabel(app.UIFigure);
            app.RightULAmpLabel.Text = 'Right upper limb amputation';
            app.LeftULAmpLabel = uilabel(app.UIFigure);
            app.LeftULAmpLabel.Tag = 'LeftULlabel';
            app.LeftULAmpLabel.Text = 'Left upper limb amputation';
            app.RightLLAmpLabel = uilabel(app.UIFigure);
            app.RightLLAmpLabel.Text = 'Right lower limb amputation';
            app.LeftLLAmpLabel = uilabel(app.UIFigure);
            app.LeftLLAmpLabel.Text = 'Left lower limb amputation';
            app.RightRPFset = uibuttongroup(app.UIFigure);
            app.RightRPFset.Title = 'Right Prosthetic Foot';
            app.RightInternalRefEditField = uieditfield(app.RightRPFset, 'text');
            app.RightSerialNEditField = uieditfield(app.RightRPFset, 'text');
            app.RightCATSpinner = uispinner(app.RightRPFset);
            app.RightCATSpinner.Step = 0.1;
            app.RightCATSpinner.Limits = [0 10];
            app.RightModelDropDown = uidropdown(app.RightRPFset);
            app.RightModelDropDown.Items = {'Cheetah Xpanse', 'Cheetah Xcel', 'Cheetah Xceed', 'Cheetah Xtend', 'Cheetah Xtreme', 'Flex-Foot Cheetah', 'Cheetah Xplore', 'Flex-Run', '1E91 - Runner Standard', '1E91 - Runner Curved', '1E91 - Runner Straight Even', '1E91 - Long Jump', '1E90 - Sprinter', '1E96 - Sprinter Custom', 'Obsedian'};
            app.RightModelDropDown.Placeholder = 'Select...';
            app.RightModelDropDown.Value = 'Cheetah Xpanse';
            app.RightManufacturerDropDown = uidropdown(app.RightRPFset);
            app.RightManufacturerDropDown.Items = {'Fillauer', 'Ossur', 'Ottobock', 'Other'};
            app.RightManufacturerDropDown.Placeholder = 'Select...';
            app.RightManufacturerDropDown.Value = 'Ottobock';
            app.RightJFootButton = uiradiobutton(app.RightRPFset);
            app.RightJFootButton.Text = 'J-Foot';
            app.RightCFootwsquareclampButton = uiradiobutton(app.RightRPFset);
            app.RightCFootwsquareclampButton.Text = 'C-Foot w/ square clamp';
            app.RightCFootwstarclampButton = uiradiobutton(app.RightRPFset);
            app.RightCFootwstarclampButton.Text = 'C-Foot w/ star clamp';
            app.RightCFootwstarclampButton.Value = true;
            app.Image2_2 = uiimage(app.RightRPFset);
            app.Image2_2.ImageSource = 'CS-foot_clamp.png';
            app.CCfoot_srdClamp_2 = uiimage(app.RightRPFset);
            app.CCfoot_srdClamp_2.ImageSource = 'CC-foot_clamp.png';
            app.CSfoot_starClamp_2 = uiimage(app.RightRPFset);
            app.CSfoot_starClamp_2.ImageSource = 'J-foot.png';
            app.RightCATSpinnerLabel = uilabel(app.RightRPFset);
            app.RightCATSpinnerLabel.Text = 'CAT:';
            app.RightManufacturerDropDownLabel = uilabel(app.RightRPFset);
            app.RightManufacturerDropDownLabel.Text = 'Manufacturer';
            app.RightModelDropDownLabel = uilabel(app.RightRPFset);
            app.RightModelDropDownLabel.Text = 'Model';
            app.RightSerialNEditFieldLabel = uilabel(app.RightRPFset);
            app.RightSerialNEditFieldLabel.Text = 'Serial N.';
            app.RightInternalRefEditFieldLabel = uilabel(app.RightRPFset);
            app.RightInternalRefEditFieldLabel.Text = 'Internal Ref.';
            app.RightPrototypeCheckBox = uicheckbox(app.RightRPFset);
            app.RightPrototypeCheckBox.Text = '';
            app.PrototypeLabel = uilabel(app.RightRPFset);
            app.PrototypeLabel.Text = 'Prototype';
            app.LeftProsthKneeSet = uibuttongroup(app.UIFigure);
            app.LeftProsthKneeSet.Title = 'Left Prosthetic Knee';
            app.LeftOttobock3S80Button = uiradiobutton(app.LeftProsthKneeSet);
            app.LeftOttobock3S80Button.Text = 'Ottobock 3S80';
            app.LeftOttobock3S80Button.Value = true;
            app.Image3_2 = uiimage(app.LeftProsthKneeSet);
            app.Image3_2.ImageSource = 'knee.png';
            app.RightProsthKneeSet = uibuttongroup(app.UIFigure);
            app.RightProsthKneeSet.Title = 'Right Prosthetic Knee';
            app.RightOttobock3S80Button = uiradiobutton(app.RightProsthKneeSet);
            app.RightOttobock3S80Button.Text = 'Ottobock 3S80';
            app.RightOttobock3S80Button.Value = true;
            app.Image3 = uiimage(app.RightProsthKneeSet);
            app.Image3.ImageSource = 'knee.png';
            app.RightProsthAlignmentPanel = uipanel(app.UIFigure);
            app.RightProsthAlignmentPanel.Title = 'Right Prosthesis Alignment';
            app.IDEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.PreflexionLabel = uilabel(app.RightProsthAlignmentPanel);
            app.PreflexionLabel.Text = 'Preflexion';
            app.TAPEditFieldLabel = uilabel(app.RightProsthAlignmentPanel);
            app.TAPEditFieldLabel.Text = 'TAP';
            app.SAPLabel = uilabel(app.RightProsthAlignmentPanel);
            app.SAPLabel.Text = 'SAP';
            app.Label_2 = uilabel(app.RightProsthAlignmentPanel);
            app.Label_2.Text = '°';
            app.SAPEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.cmLabel = uilabel(app.RightProsthAlignmentPanel);
            app.cmLabel.Text = 'cm';
            app.TAPEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.Label = uilabel(app.RightProsthAlignmentPanel);
            app.Label.Text = '°';
            app.PreflexionEditField = uieditfield(app.RightProsthAlignmentPanel, 'numeric');
            app.IDEditField = uieditfield(app.RightProsthAlignmentPanel, 'text');
            app.LeftProsthAlignmentPanel = uipanel(app.UIFigure);
            app.LeftProsthAlignmentPanel.Title = 'Left Prosthesis Alignment';
            app.IDEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.IDEditField_2Label.Text = 'ID';
            app.IDEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'text');
            app.PreflexionLabel_2 = uilabel(app.LeftProsthAlignmentPanel);
            app.PreflexionLabel_2.Text = 'Preflexion';
            app.PreflexionEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.TAPEditField_2Label = uilabel(app.LeftProsthAlignmentPanel);
            app.TAPEditField_2Label.Text = 'TAP';
            app.TAPEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.SAPLabel_2 = uilabel(app.LeftProsthAlignmentPanel);
            app.SAPLabel_2.Text = 'SAP';
            app.SAPEditField_2 = uieditfield(app.LeftProsthAlignmentPanel, 'numeric');
            app.Label_3 = uilabel(app.LeftProsthAlignmentPanel);
            app.Label_3.Text = '°';
            app.cmLabel_2 = uilabel(app.LeftProsthAlignmentPanel);
            app.cmLabel_2.Text = 'cm';
            app.Label_4 = uilabel(app.LeftProsthAlignmentPanel);
            app.Label_4.Text = '°';
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.png';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UpdateSegmentsButton = uibutton(app.Panel, 'push');
            app.UpdateSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSegmentsButtonPushed, true);
            app.UpdateSegmentsButton.Tooltip = {'After having set up the subject configuration using the Body segments panel and possible Right and Left Prosthetic parts panels, click on "Update segments" to let the software pick the relevant needed parts from the model and calibration files to start setting up the *.config file.'};
            app.UpdateSegmentsButton.Text = 'Update segments';
            app.VirtualmarkersreconstructionmethodButton = uibutton(app.Panel, 'push');
            app.VirtualmarkersreconstructionmethodButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualmarkersreconstructionmethodButtonPushed, true);
            app.VirtualmarkersreconstructionmethodButton.Enable = 'off';
            app.VirtualmarkersreconstructionmethodButton.Tooltip = {'Click here to check which virtual markers will be reconstructed and which method is going to be used. You will be able to modify anything you need.'};
            app.VirtualmarkersreconstructionmethodButton.Text = 'Virtual markers reconstruction method';
            app.CalibratedmarkersButton = uibutton(app.Panel, 'push');
            app.CalibratedmarkersButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratedmarkersButtonPushed, true);
            app.CalibratedmarkersButton.Enable = 'off';
            app.CalibratedmarkersButton.Tooltip = {'Click here after having updated the segments to check which markers are going to be calibrated using the calibration object selected in the "calibration object drop-down menu".'};
            app.CalibratedmarkersButton.Text = 'Calibrated markers';
            app.ConfignameLabel = uilabel(app.Panel);
            app.ConfignameLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.Panel, 'text');
            app.FilenameEditField.Placeholder = 'Type the *.xml file name';
            app.Path2saveEditField = uieditfield(app.Panel, 'text');
            app.Path2saveEditField.Placeholder = 'No folder selected.';
            app.SelectpathtostoretheconfigfileButton = uibutton(app.Panel, 'push');
            app.SelectpathtostoretheconfigfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathtostoretheconfigfileButtonPushed, true);
            app.SelectpathtostoretheconfigfileButton.Text = 'Select path to store the config file';
            app.SelectthebiomechanicalmodeltobeappliedLabel = uilabel(app.Panel);
            app.SelectthebiomechanicalmodeltobeappliedLabel.Text = 'Biomechanical model to be applied:';
            app.ModelDropDown = uidropdown(app.Panel);
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ConfirmModelButton = uibutton(app.Panel, 'push');
            app.ConfirmModelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmModelButtonPushed, true);
            app.ConfirmModelButton.Text = 'Confirm';
            app.AddModelButton = uibutton(app.Panel, 'push');
            app.AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @AddModelButtonPushed, true);
            app.AddModelButton.Enable = 'off';
            app.AddModelButton.Tooltip = {'In next version of the app, this button will activate a new window to  create a new model to be listed in the "select model" drop-down menu.'};
            app.AddModelButton.Text = 'Add';
            app.SelectthebiomechanicalmodeltobeappliedLabel_2 = uilabel(app.Panel);
            app.SelectthebiomechanicalmodeltobeappliedLabel_2.Text = 'Calibration object (if any):';
            app.CalobjDropDown = uidropdown(app.Panel);
            app.CalobjDropDown.ValueChangedFcn = createCallbackFcn(app, @CalobjDropDownValueChanged, true);
            app.AddCalObjButton = uibutton(app.Panel, 'push');
            app.AddCalObjButton.Enable = 'off';
            app.AddCalObjButton.Tooltip = {'In next version of the app, this button will activate a new window to  create a new calibration object to be listed in the "select calibration object" drop-down menu.'};
            app.AddCalObjButton.Text = 'Add';
            app.ConfirmCalObjButton = uibutton(app.Panel, 'push');
            app.ConfirmCalObjButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCalObjButtonPushed, true);
            app.ConfirmCalObjButton.Text = 'Confirm';
            app.SbjPathEditField = uieditfield(app.Panel, 'text');
            app.SbjPathEditField.Tooltip = {'You do not necessarily need to select a subject folder. If you do so and the folder contains any information on the subject configuration (whether he/she has any amputations or not), the software is using those info to pre-set the "Body segment panel" and it automatically suggests to save the final configuration in that folder. If you do not, you will be asked to select the saving folder later.'};
            app.SbjPathEditField.Placeholder = 'No pre-selected subject folder...';
            app.SbjBrowseButton = uibutton(app.Panel, 'push');
            app.SbjBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SbjBrowseButtonPushed, true);
            app.SbjBrowseButton.Tooltip = {'You do not necessarily need to select a subject folder. If you do so and the folder contains any information on the subject configuration (whether he/she has any amputations or not), the software is using those info to pre-set the "Body segment panel" and it automatically suggests to save the final configuration in that folder. If you do not, you will be asked to select the saving folder later.'};
            app.SbjBrowseButton.Text = 'Select subject folder';
            app.TaskSelectionButtonGroup = uibuttongroup(app.Panel);
            app.TaskSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TaskSelectionButtonGroupSelectionChanged, true);
            app.TaskSelectionButtonGroup.Title = 'Task Selection';
            app.WalkingButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.WalkingButton.Text = 'Walking';
            app.RunningButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.RunningButton.Text = 'Running';
            app.RunningButton.Value = true;
            app.LongjumpingButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.LongjumpingButton.Text = 'Long jumping';
            app.GeneralButton = uitogglebutton(app.TaskSelectionButtonGroup);
            app.GeneralButton.Text = 'General';
            app.IncludeconfigIDCheckBox = uicheckbox(app.Panel);
            app.IncludeconfigIDCheckBox.Text = '  Include config ID';
            app.IncludeconfigIDCheckBox.Value = true;
            app.MainsettingsLabel = uilabel(app.UIFigure);
            app.MainsettingsLabel.Text = '   Main settings';
            app.OperationsLabel = uilabel(app.UIFigure);
            app.OperationsLabel.Text = '   Operations';
            app.SavingLabel = uilabel(app.UIFigure);
            app.SavingLabel.Text = '   Saving';
            app.LogpanelPanel = uipanel(app.UIFigure);
            app.LogpanelPanel.Title = 'Log panel';
            app.LogTextArea = uitextarea(app.LogpanelPanel);
            app.LogTextArea.Editable = 'off';
            app.ClearlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ExportlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ExportlogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlogButtonPushed, true);
            app.ExportlogButton.Icon = 'pageicon.gif';
            app.ExportlogButton.Text = 'Export log';
            app.IdleLampLabel = uilabel(app.LogpanelPanel);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.LogpanelPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OlympiaConfigurator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end