classdef OlympiaMainSW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DanalysisButton               matlab.ui.control.Button
        FullReportButton              matlab.ui.control.Button
        QuickTableButton              matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        INAIL_2                       matlab.ui.control.Image
        INAIL                         matlab.ui.control.Image
        UNIPD_2                       matlab.ui.control.Image
        MainsettingsPanel             matlab.ui.container.Panel
        ConfigurationfileformatLabel  matlab.ui.control.Label
        jsonCheckBox                  matlab.ui.control.CheckBox
        xmlCheckBox                   matlab.ui.control.CheckBox
        MainPathEditField             matlab.ui.control.EditField
        MainBrowseButton              matlab.ui.control.Button
        MainsettingsLabel             matlab.ui.control.Label
        ConfiguratorButton            matlab.ui.control.Button
        StatictrialprocessorButton    matlab.ui.control.Button
        DynamictrialprocessorButton   matlab.ui.control.Button
        ProcessingAppLabel            matlab.ui.control.Label
        OLYMPIA                       matlab.ui.control.Image
        UNIPD                         matlab.ui.control.Image
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %it does nothing
        end

        % Button pushed function: DynamictrialprocessorButton
        function DynamictrialprocessorButtonPushed(app, event)
            dynamic = TrialAnalyzer(app.MainPathEditField.Value, app.xmlCheckBox.Value, app.jsonCheckBox.Value);
        end

        % Button pushed function: StatictrialprocessorButton
        function StatictrialprocessorButtonPushed(app, event)
            static = StaticTrialConstructor();%app.MainPathEditField.Value);
        end

        % Button pushed function: ConfiguratorButton
        function ConfiguratorButtonPushed(app, event)
            configApp = OlympiaConfigurator(app.MainPathEditField.Value, app.xmlCheckBox.Value, app.jsonCheckBox.Value);
        end

        % Button pushed function: MainBrowseButton
        function MainBrowseButtonPushed(app, event)
            selectedfolder = uigetdir();
            try
                [path, folder] = fileparts(selectedfolder);
            catch
                wrn = warndlg(["You may not select a main folder now,", "but will be needed in the following steps!"], "Subject folder not selected");
                uiwait(wrn);
                return
            end
            app.MainPathEditField.Value = fullfile(path, folder);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: FullReportButton
        function FullReportButtonPushed(app, event)
            repmaker = ReportMaker(app.MainPathEditField.Value);
        end

        % Button pushed function: QuickTableButton
        function QuickTableButtonPushed(app, event)
            QuickReportTables()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.ProcessingAppLabel = uilabel(app.UIFigure);
            app.ProcessingAppLabel.Text = 'Processing App';
            app.DynamictrialprocessorButton = uibutton(app.UIFigure, 'push');
            app.DynamictrialprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @DynamictrialprocessorButtonPushed, true);
            app.DynamictrialprocessorButton.Text = 'Dynamic trial processor';
            app.StatictrialprocessorButton = uibutton(app.UIFigure, 'push');
            app.StatictrialprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @StatictrialprocessorButtonPushed, true);
            app.StatictrialprocessorButton.Text = 'Static trial processor';
            app.ConfiguratorButton = uibutton(app.UIFigure, 'push');
            app.ConfiguratorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfiguratorButtonPushed, true);
            app.ConfiguratorButton.Tooltip = {''};
            app.ConfiguratorButton.Text = 'Subject Configurator';
            app.MainsettingsPanel = uipanel(app.UIFigure);
            app.MainsettingsPanel.Title = 'Main settings';
            app.MainsettingsLabel = uilabel(app.MainsettingsPanel);
            app.MainsettingsLabel.Text = '   Main settings';
            app.MainBrowseButton = uibutton(app.MainsettingsPanel, 'push');
            app.MainBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MainBrowseButtonPushed, true);
            app.MainBrowseButton.Text = 'Browse subject folder';
            app.MainPathEditField = uieditfield(app.MainsettingsPanel, 'text');
            app.MainPathEditField.Placeholder = 'Subject folder...';
            app.xmlCheckBox = uicheckbox(app.MainsettingsPanel);
            app.xmlCheckBox.Text = '*.xml';
            app.xmlCheckBox.Value = true;
            app.jsonCheckBox = uicheckbox(app.MainsettingsPanel);
            app.jsonCheckBox.Text = '*.json';
            app.jsonCheckBox.Value = true;
            app.ConfigurationfileformatLabel = uilabel(app.MainsettingsPanel);
            app.ConfigurationfileformatLabel.Text = 'Configuration file format:';
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.jpg';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.QuickTableButton = uibutton(app.UIFigure, 'push');
            app.QuickTableButton.ButtonPushedFcn = createCallbackFcn(app, @QuickTableButtonPushed, true);
            app.QuickTableButton.Text = 'Quick Table';
            app.FullReportButton = uibutton(app.UIFigure, 'push');
            app.FullReportButton.ButtonPushedFcn = createCallbackFcn(app, @FullReportButtonPushed, true);
            app.FullReportButton.Text = 'Full Report';
            app.DanalysisButton = uibutton(app.UIFigure, 'push');
            app.DanalysisButton.Text = '2D analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OlympiaMainSW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end