classdef QuickReportTables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CreateButton                   matlab.ui.control.Button
        OpenAfterProcessingCheckBox    matlab.ui.control.CheckBox
        ExistingdatamodeDropDown       matlab.ui.control.DropDown
        ExistingdatamodeDropDownLabel  matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        SavetoEditField                matlab.ui.control.EditField
        SavetoEditFieldLabel           matlab.ui.control.Label
        TypeSwitch                     matlab.ui.control.Switch
        TypeSwitchLabel                matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        AddTrialtoWorkspaceButton      matlab.ui.control.Button
        RunAnalysisButton              matlab.ui.control.Button
        INAIL_2                        matlab.ui.control.Image
        UNIPD_2                        matlab.ui.control.Image
        INAIL                          matlab.ui.control.Image
        QuickReportLabel               matlab.ui.control.Label
        OLYMPIA                        matlab.ui.control.Image
        UNIPD                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        filedir % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            vartypes=["string","string","double","string","string","string","string","double","string","double"];
            t=table('Size',[0,numel(app.UITable.ColumnName)],'VariableNames',app.UITable.ColumnName,'VariableTypes',vartypes);
            app.UITable.Data=t;
        end

        % Button pushed function: AddTrialtoWorkspaceButton
        function AddTrialtoWorkspaceButtonPushed(app, event)
            [f,p]=uigetfile(('.c3d'),'Select one or more trials to be analyzed',"MultiSelect",'on');
            f=string(f);
            if isempty(app.filedir)
                app.filedir=p;
            elseif not(isequal(p,app.filedir))
                errordlg('All files in the workspace must be in the same directory!','Import Error','modal');
                return
            end
            
            h0=height(app.UITable.Data);
            for i=1:length(f)
                app.UITable.Data.Trial(h0+i)=f(i);
                t=Trial(fullfile(p,f(i)));
                code=regexp(f(i),"(\w+)(\d\d)_S(\d\d)_T(\d\d)_",'tokens');
                code=cat(1,code{:});
                app.UITable.Data.Classification(h0+i)=code(1);
                app.UITable.Data.Subject(h0+i)=code(1)+code(2);
                ampside="Undefined";
                try
                md=t.Metadata.PROCESSING;
                mass=md.mass;
                app.UITable.Data.Mass(h0+i)=mass;
                L=md.LAmputation;
                R=md.RAmputation;
                if L&&not(R)
                ampside="Left";
                elseif not(L)&&R
                    ampside="Right";
                elseif L&&R
                    ampside="Both";
                else
                    ampside="None";
                end
                
                catch
                end
                app.UITable.Data.AmpSide(h0+i)=ampside;
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            indx=listdlg("ListString",app.UITable.Data.Trial,"PromptString","Select trials to remove","SelectionMode","multiple");
            app.UITable.Data(indx,:)=[];
            if height(app.UITable.Data)==0
               app.filedir=[];
            end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            ht=height(app.UITable.Data);
            f=app.UITable.Data.Trial;
            for i=1:ht
            try
            exp='(?<subject>\w+)_(?<session>\w+)_T(?<tID>\d+)_(?<trial>\w+)'; %expression to match trial name
            info=regexp(f(i),exp,'names'); %information retrieved from string
            while app.UITable.Data.Mass(i)<=0
                answer= inputdlg(sprintf("%s mass is non-positive, please enter a valid value! \nmass (kg):",f(i)),"error");
                if isempty(answer)
                   return
                end
                app.UITable.Data.Mass(i)=str2double(answer);
            end
            
            Info.Athlete.Mass=app.UITable.Data.Mass(i);
            Info.Athlete.ID=app.UITable.Data.Subject(i);
            Info.Athlete.AmputationSide=app.UITable.Data.AmpSide(i);
            Info.Session.Date='n/a';
            Info.Trial.ID=strrep(f(i),'.c3d','');
            Info.Trial.RunID=info.trial;
            
            if app.TypeSwitch.Value=="Long Jump"
               %ans=inputdlg(["TakeOff Leg";"Amputation Level"],"Enter additional information");
               Info.Athlete.AmpLevel=regexprep(info.subject,'\d+','');
               Info.Trial.TakeOffLeg=Info.Athlete.AmputationSide;
               Info.Trial.JumpID=Info.Trial.RunID;
               [Tfile]=tableCoreProcessorLJ(fullfile(app.filedir,f(i)),Info,app.SavetoEditField.Value,app.ExistingdatamodeDropDown.Value);
            else
                [Tfile]=tableCoreProcessor(fullfile(app.filedir,f(i)),Info,app.SavetoEditField.Value,app.ExistingdatamodeDropDown.Value);
            end
            app.UITable.Data.Status(i)="DONE!";
            catch ME
            app.UITable.Data.Status(i)="ERROR: "+ME.message;   
            end
            end
            if app.OpenAfterProcessingCheckBox.Value
               winopen(Tfile);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [f,p]=uigetfile('.xlsx','Select an existing Quick Report');
            app.SavetoEditField.Value=fullfile(p,f);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            [f,p]=uiputfile('.xlsx','Create a destination file for the Quick Report');
            app.SavetoEditField.Value=fullfile(p,f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.QuickReportLabel = uilabel(app.UIFigure);
            app.QuickReportLabel.Text = 'Quick Report';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.jpg';
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Tooltip = {'Run the analysis for the workspace using the selected options and save to the Excel'};
            app.RunAnalysisButton.Text = 'RunAnalysis';
            app.AddTrialtoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.AddTrialtoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialtoWorkspaceButtonPushed, true);
            app.AddTrialtoWorkspaceButton.Tooltip = {'Import dynamic trials into the workspace'};
            app.AddTrialtoWorkspaceButton.Text = 'Add Trial to Workspace';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Status'; 'Trial'; 'Mass'; 'AmpSide'; 'Classification'; 'Subject'; 'FootModel'; 'FootCat'; 'SocketName'; 'SocketAlignment'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.Tooltip = {'Import dynamic trials into the workspace'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'Remove trials from the workspace'};
            app.Button.Text = '-';
            app.TypeSwitchLabel = uilabel(app.UIFigure);
            app.TypeSwitchLabel.Text = 'Type';
            app.TypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.TypeSwitch.Items = {'Sprint', 'Long Jump'};
            app.TypeSwitch.Tooltip = {'Select the type of trial'};
            app.TypeSwitch.Value = 'Sprint';
            app.SavetoEditFieldLabel = uilabel(app.UIFigure);
            app.SavetoEditFieldLabel.Text = 'Save to:';
            app.SavetoEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Select the Excel file in which save the report'};
            app.BrowseButton.Text = 'Browse';
            app.ExistingdatamodeDropDownLabel = uilabel(app.UIFigure);
            app.ExistingdatamodeDropDownLabel.Tooltip = {'Select the behavior for if a corresponding trial is found in the Excel.'; 'Ignore: if data exist, do nothing'; 'Replace: replace existing data'; 'Append: if data exist, ignore it and put the new data to the bottom of the excel'};
            app.ExistingdatamodeDropDownLabel.Text = 'Existing data mode';
            app.ExistingdatamodeDropDown = uidropdown(app.UIFigure);
            app.ExistingdatamodeDropDown.Items = {'Overwrite', 'Ignore', 'Append'};
            app.ExistingdatamodeDropDown.Value = 'Ignore';
            app.OpenAfterProcessingCheckBox = uicheckbox(app.UIFigure);
            app.OpenAfterProcessingCheckBox.Tooltip = {'Open the Excel after the analysis is completed'};
            app.OpenAfterProcessingCheckBox.Text = 'Open After Processing';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Tooltip = {'Create an Excel file to save the analysis'};
            app.CreateButton.Text = 'Create';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuickReportTables

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end