classdef ReportMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UNIVR                          matlab.ui.control.Image
        UNIPD                          matlab.ui.control.Image
        INAIL                          matlab.ui.control.Image
        ReportMakerLabel               matlab.ui.control.Label
        OLYMPIA                        matlab.ui.control.Image
        ParaRun                        matlab.ui.control.Image
        MainsettingsPanel              matlab.ui.container.Panel
        pdfCheckBox                    matlab.ui.control.CheckBox
        ProcessedFilesRegistryEditField  matlab.ui.control.EditField
        ProcessedfilesregistryEditFieldLabel  matlab.ui.control.Label
        PickRegistryButton             matlab.ui.control.Button
        MainsettingsLabel              matlab.ui.control.Label
        MainPathEditField              matlab.ui.control.EditField
        MainsubjectfolderLabel         matlab.ui.control.Label
        MainBrowseButton               matlab.ui.control.Button
        ExportfileformatLabel          matlab.ui.control.Label
        pngCheckBox                    matlab.ui.control.CheckBox
        figCheckBox                    matlab.ui.control.CheckBox
        DatabasefilteringandfileselectionPanel  matlab.ui.container.Panel
        FilterButton                   matlab.ui.control.Button
        SocketAlignmentDropDown        matlab.ui.control.DropDown
        SocketAlignmentDropDownLabel   matlab.ui.control.Label
        FootCATDropDown                matlab.ui.control.DropDown
        FootCATDropDownLabel           matlab.ui.control.Label
        FootModelDropDown              matlab.ui.control.DropDown
        FootModelDropDownLabel         matlab.ui.control.Label
        FiltersLabel                   matlab.ui.control.Label
        ClassificationDropDown         matlab.ui.control.DropDown
        ClassificationDropDownLabel    matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        LoadButton                     matlab.ui.control.Button
        ReportsettingPanel             matlab.ui.container.Panel
        FilenameprefixEditField        matlab.ui.control.EditField
        FilenameprefixEditFieldLabel   matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        MainsubjectfolderLabel_2       matlab.ui.control.Label
        SaveBrowseButton               matlab.ui.control.Button
        LogTextArea                    matlab.ui.control.TextArea
        LogTextAreaLabel               matlab.ui.control.Label
        FontssizePanel                 matlab.ui.container.Panel
        TitlesSpinner                  matlab.ui.control.Spinner
        TitlesSpinner_3Label           matlab.ui.control.Label
        LegendsSpinner                 matlab.ui.control.Spinner
        LegendsSpinnerLabel            matlab.ui.control.Label
        MultiplestepIDintervalSpinner  matlab.ui.control.Spinner
        MultiplestepIDintervalLabel    matlab.ui.control.Label
        AxislabelsSpinner              matlab.ui.control.Spinner
        AxislabelsSpinnerLabel         matlab.ui.control.Label
        TimenormalizePanel             matlab.ui.container.Panel
        StanceCheckBox                 matlab.ui.control.CheckBox
        StrideCheckBox                 matlab.ui.control.CheckBox
        FO_MS_Panel                    matlab.ui.container.Panel
        MSCheckBox                     matlab.ui.control.CheckBox
        FOCheckBox                     matlab.ui.control.CheckBox
        OutputvariablesPanel           matlab.ui.container.Panel
        AllVarCheckBox                 matlab.ui.control.CheckBox
        LumpedCheckBox                 matlab.ui.control.CheckBox
        ROSCheckBox                    matlab.ui.control.CheckBox
        STparCheckBox                  matlab.ui.control.CheckBox
        KineticsCheckBox               matlab.ui.control.CheckBox
        KinematicsCheckBox             matlab.ui.control.CheckBox
        BodySidePanel                  matlab.ui.container.Panel
        GeneralsegmentsColorPickerLabel  matlab.ui.control.Label
        AllSideCheckBox                matlab.ui.control.CheckBox
        RightCheckBox                  matlab.ui.control.CheckBox
        LeftCheckBox                   matlab.ui.control.CheckBox
        GenerateReportPreviewButton    matlab.ui.control.Button
        CloseFiguresButton             matlab.ui.control.Button
        ExportfiguresButton            matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainroot;
        registryData;
        statFileList;
        dynFileList;
        trial;
        SavePath;  % after browsing and selecting the main folder where the DB is stored, the path is saved in here
        LogRecord = cell(0);
        cycle = 0:100;  % for x-axis plot
        %LeftLowerLimb;  % property either AL (affected limb) or UL (unaffected limb)
        %RightLowerLimb; % property either AL (affected limb) or UL (unaffected limb)
        %LLAmpLevel;     % lower limb amputation level: either TF (transfemoral) or TT (transtibial)
        TrialType;      % trial type (TSSR, LJ)
        SegJoints;      % property to select segmentale or joint variables
        StanceStride;   % property to select variables time normalization
        %UpperLower;     % property to select variables to plot
        normtime;       % property for plot time axis selection
        color = struct("General", [0.466666666666667, 0.674509803921569, 0.188235294117647],...
                       "Left", [0.850980392156863, 0.325490196078431, 0.0980392156862745],...
                       "Right", [0	0.447058823529412	0.741176470588235]);          % property for plot color line selection (given in HEX)
        plane = {"Sagittal", "Frontal", "Transverse"};
        context = {"General", "Left", "Right"};       % property for plot (body side)
        %
        %lineWidth;
        titleFontSize = 12;
        labelsFontSize = 12;
        multipleStepIDInterval = 15;
        legendFontSize = 10;
    end
    
    methods (Access = private)
        
        function h = plotROS(app)
            segment = {"Shank", "FootClamp"};

            h = nan(length(app.context)-1, 1);

            R = app.trial.dyn.ForcePlatform.align2ISB();
            GRF = (R*(app.trial.dyn.ForcePlatform.GRF)')';

            for lr = 2:length(app.context) % start from 2 to skip 'General'
                [~, stance.analog, ~] = buildCycle(app.trial.dyn.Events, "analog");
                [~, stance.point, ~] = buildCycle(app.trial.dyn.Events, "point");
                for istance = 1:size(stance.analog.(app.context{lr}), 1)
                    GRFstance.(app.context{lr})(:,:,istance) = time2cycle([], GRF(stance.analog.(app.context{lr})(istance, 1):stance.analog.(app.context{lr})(istance, 2), :), 101); 
                end
            end

            for lr = 2:length(app.context) % start from 2 to skip 'General'
                for k = 1:length(segment)
                    fields = fieldnames(app.trial.dyn.Segments.(app.context{lr}));
                    idx = findStringMatch(fields, app.context{lr}, segment{k});
                    if isempty(idx)
                        continue
                    end
                    
                    h(lr-1) = figure("Name", app.context{lr} + "_ROS_parameters");

                    tiledlayout(3, 2);

                    % figure 1: GRF AP e V in CLAMP CS
                    nexttile
                    [~, ~, Fc, COPc] = getROS(app.trial.dyn, app.context{lr}, fields{contains(fields, segment{k})});
                    dim = min(size(Fc, 3), size(stance.point.(app.context{lr}), 1));
                    for l = 1:dim
                        hold on;
                        px = plot(app.cycle, Fc(:,1,l),'--','Color',app.color.(app.context{lr}));
                        py = plot(app.cycle, Fc(:,2,l),'Color',app.color.(app.context{lr}));
                        plot_with_text(app.cycle', Fc(:,1,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{lr}));
                        plot_with_text(app.cycle', Fc(:,2,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{lr}));
                        if l>1
                            px.HandleVisibility='off';
                            py.HandleVisibility='off';
                        end
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center');
                    %     end
                    % end
                    yline(0);
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('GRF (N)', 'FontSize', app.labelsFontSize);
                    title("AP and V GRFs in " + app.context{lr} + segment{k}, 'FontSize', app.titleFontSize);
                    legend({'GRF_{AP}','GRF_{V}'}, 'FontSize', app.legendFontSize);

                    % figure 2: COP progression
                    % theta FootClamp (TF)/ theta socket(new: -->FootClamp) (TT)
                    % vs x-coordinate COP in FootClamp CS
                    nexttile
                    angles = app.trial.dyn.Segments.(app.context{lr}).(app.context{lr} + segment{k}).EulerAngles;
                    for istance = 1:size(stance.point.(app.context{lr}), 1)
                        anglesStance.(app.context{lr})(:,:,istance) = time2cycle([], angles(stance.point.(app.context{lr})(istance, 1):stance.point.(app.context{lr})(istance, 2), :), 101);
                    end

                    for l = 1:dim
                        plot(COPc(:,1,l), anglesStance.(app.context{lr})(:, 3, l), 'Color', app.color.(app.context{lr}));
                        plot_with_text(COPc(:,1,l), anglesStance.(app.context{lr})(:, 3, l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{lr}));
                    end
                    xlabel("X_{COP} in " + app.context{lr} + segment{k} + "(mm)", 'FontSize', app.labelsFontSize);
                    ylabel("\theta_{"+ app.context{lr}{1}(1) + segment{k} + "} (deg)", 'FontSize', app.labelsFontSize);
                    title('COP progression', 'FontSize', app.titleFontSize);

                    % figure 3: left/right ROS
                    nexttile
                    showROS(Fc, COPc, 'default', app.color.(app.context{lr}), segment{k});
                    title('ROS', 'FontSize', app.titleFontSize);

                    %figure 4: left/right deltaROS
                    nexttile
                    showROS(Fc, COPc, 'deltaROS', app.color.(app.context{lr}), segment{k});
                    title('deltaROS', 'FontSize', app.titleFontSize);

                    % figure 5: deltaLx vs Fx in clamp CS
                    nexttile
                    hjc = app.trial.dyn.Points.PointStruct.(app.context{lr}{1}(1) + "HJC");
                    cop = app.trial.dyn.ForcePlatform.resample(app.trial.dyn.Metadata.POINT.RATE).COP;
                    deltaL = abs(cop-hjc);
                    for istance = 1:size(stance.point.(app.context{lr}), 1)
                        deltaLStance.(app.context{lr})(:,:,istance) = time2cycle([], deltaL(stance.point.(app.context{lr})(istance, 1):stance.point.(app.context{lr})(istance, 2), :), 101);
                    end
                    
                    for l = 1:dim
                        hold on;
                        plot(deltaLStance.(app.context{lr})(:,1,l),...
                            GRFstance.(app.context{lr})(:,1,l), 'Color', app.color.(app.context{lr}));
                        plot_with_text(deltaLStance.(app.context{lr})(:,1,l),...
                            GRFstance.(app.context{lr})(:,1,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{lr}))
                    end
                    xlabel('deltaL_{X} (mm)', 'FontSize', app.labelsFontSize);
                    ylabel('GRF_{X} (N)', 'FontSize', app.labelsFontSize);
                    title('AP GRF vs deltaL in clamp CS', 'FontSize', app.titleFontSize);

                    % figure 6: deltaLy vs Fy in clamp CS
                    nexttile
                    for l = 1:dim
                        hold on;
                        plot(deltaLStance.(app.context{lr})(:,2,l),...
                            GRFstance.(app.context{lr})(:,2,l), 'Color', app.color.(app.context{lr}));
                        plot_with_text(deltaLStance.(app.context{lr})(:,2,l),...
                            GRFstance.(app.context{lr})(:,2,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{lr}))
                    end
                    xlabel('deltaL_{Y} (mm)', 'FontSize', app.labelsFontSize);
                    ylabel('GRF_{Y} (N)', 'FontSize', app.labelsFontSize);
                    title('Vertical GRF vs deltaL in clamp CS', 'FontSize', app.titleFontSize);
                end
                sgtitle(app.context{lr} + "ROS parameters");
            end
        end
        
        function h = plotLumpedModels(app)
            % [ind, ~] = listdlg('PromptString', {['Select the proximal point', 'for the Lumped Models analysis:']}, 'ListString', {'Great Trochanter', 'Hip Joint Center', 'Clamp Origin'}, 'ListSize', [160,120]);
            % h=nan(length(ind),1);
                        
            for lr = 2:length(app.context) % start from 2 to skip 'General'
                % [~, stance.analog, ~] = buildCycle(app.trial.dyn.Events, "analog");
                [~, stance.point, ~] = buildCycle(app.trial.dyn.Events, "point");
            end
            
            points=[];
            for s = [app.trial.dyn.Scalars]
                tmp = regexp(s.Label, "\w+_(\w)(\w+)-COP", "tokens");
                tmp = tmp{:};
                if tmp{1} == "L"
                    lumped.Left.(strrep(s.Label,"-","_")) = s.Data(:, 1);
                else
                    lumped.Right.(strrep(s.Label,"-","_")) = s.Data(:, 1);
                end
                points = [points, tmp(2)];
            end
            points = unique(points);
            
            for lr = 2:length(app.context)
                fields = fieldnames(lumped.(app.context{lr}));
                for ff = fields'
                    for istance = 1:size(stance.point.(app.context{lr}), 1)
                        temp(:, istance) = time2cycle([], lumped.(app.context{lr}).(ff{:})(stance.point.(app.context{lr})(istance, 1):stance.point.(app.context{lr})(istance, 2), 1), 101);
                    end
                    lumped.(app.context{lr}).(ff{:}) = temp;
                end
            end
            
            % dim=size(app.trial.kinetics.filtered.(app.context).COP.cycle,3);
            
            %%% FIGURE
            % figure con PP: HJC e GT
            ii=1;
            for lr = [app.context{2:end}]
                for i=points
                    h(ii) = figure("Name", lr + i);
                    tiledlayout(3,3);
                    nexttile
                    % figure 1: theta LEG
                    plot(app.cycle, lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 'Color', app.color.(lr));
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                            plot_with_text(app.cycle', lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP")(:,j), num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('\theta_{' + i + '}' + '(deg)', 'FontSize', app.labelsFontSize);
                    title('\theta ' + lr + i, 'FontSize', app.titleFontSize);

                    % figure 2: deltaL in stance
                    lumped.(lr).("deltaL_" + lr{:}(1) + i + "_COP") = lumped.(lr).("L_" + lr{:}(1) + i + "_COP") - lumped.(lr).("L_" + lr{:}(1) + i + "_COP")(1, :);
                    nexttile
                    plot(app.cycle', lumped.(lr).("deltaL_" + lr{:}(1) + i + "_COP"), 'Color', app.color.(lr));
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot_with_text(app.cycle', lumped.(lr).("deltaL_" + lr{:}(1) + i + "_COP")(:,j), num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    yline(0,'HandleVisibility','off')
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('\DeltaL (mm)', 'FontSize', app.labelsFontSize);
                    title(lr + ' Leg Shortening (mm)', 'FontSize', app.titleFontSize)

                    %figure 3: vertical component PP
                    pp = app.trial.dyn.Points.PointStruct.(lr{:}(1) + i);
                    for istance = 1:size(stance.point.(lr), 1)
                        ppStance(:, istance) = time2cycle([], pp(stance.point.(lr)(istance, 1):stance.point.(lr)(istance, 2), 2), 101);
                    end
                    
                    nexttile
                    plot(app.cycle', ppStance, 'Color', app.color.(lr))
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot_with_text(app.cycle', ppStance(:,j), num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    yline(0,'HandleVisibility','off')
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('\deltaV_{' + i + '} (mm)', 'FontSize', app.labelsFontSize);
                    title('delta Vertical Component of ' + lr + " " + i, 'FontSize', app.titleFontSize)

                    % figure 4: magnitude of GRF projected
                    nexttile
                    plot(app.cycle', lumped.(lr).("GRFmod_" + lr{:}(1) + i + "_COP"),'Color', app.color.(lr));
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot_with_text(app.cycle', lumped.(lr).("GRFmod_" + lr{:}(1) + i + "_COP")(:,j), num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('||GRFp|| (N)', 'FontSize', app.labelsFontSize);
                    title('Magnitude of projected GRF (N)', 'FontSize', app.titleFontSize)

                    % figure 5: GRFp vs deltaL
                    nexttile
                    hold on
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot(lumped.(lr).("deltaL_" + lr{:}(1) + i + "_COP")(:,j), lumped.(lr).("GRFmod_" + lr{:}(1) + i + "_COP")(:,j), 'Color', app.color.(lr));
                        plot_with_text(lumped.(lr).("deltaL_" + lr{:}(1) + i + "_COP")(:,j), lumped.(lr).("GRFmod_" + lr{:}(1) + i + "_COP")(:,j), num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    xlabel('\DeltaL (mm)', 'FontSize', app.labelsFontSize);
                    ylabel('||GRFp|| (N)', 'FontSize', app.labelsFontSize);
                    title('\DeltaL vs Magnitude of projected GRF ('+ i +'_COP)', 'FontSize', app.titleFontSize)

                    % figure 6: L in % stance
                    nexttile
                    plot(app.cycle, lumped.(lr).("L_" + lr{:}(1) + i + "_COP"),'Color',app.color.(lr));
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot_with_text(app.cycle', lumped.(lr).("L_" + lr{:}(1) + i + "_COP")(:,j),num2str(j), app.multipleStepIDInterval, app.color.(lr))
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('||L|| (mm)', 'FontSize', app.labelsFontSize);
                    title('Leg length (' + i +'-COP)', 'FontSize', app.titleFontSize)

                    % figure 7: Kist
                    nexttile(8)
                    plot(app.cycle, lumped.(lr).("K_" + lr{:}(1) + i + "_COP"), 'Color', app.color.(lr));
                    for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                        plot_with_text(app.cycle', lumped.(lr).("K_" + lr{:}(1) + i + "_COP"), num2str(j), app.multipleStepIDInterval, app.color.(lr));
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                    %     if app.MSCheckBox.Value
                    %         xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
                    %     end
                    % end
                    xlabel('% Stance', 'FontSize', app.labelsFontSize);
                    ylabel('Stiffness (N/mm)', 'FontSize', app.labelsFontSize);
                    title('Leg Stiffness  (' + i + '_COP)', 'FontSize', app.titleFontSize)

                    %figure 8: COP progression in Ground CS
                    nexttile(9)
                    % for j=1:size(lumped.(lr).("Theta_" + lr{:}(1) + i + "_COP"), 2)
                    % 
                    %     hold on
                    %     plot(app.trial.kinetics.filtered.(app.context).COP.cycle(:,2,j),app.trial.kinetics.filtered.(app.context).COP.cycle(:,1,j),'Color',app.color);
                    %     %         if dim>1
                    %     %             for k=1:dim
                    %     %                 plot_with_text(db{1,1}.session{si_n}.trial{ti_e}.kinetics.filtered.(app.context).COP.cycle(:,2,k),db{1,1}.session{si_n}.trial{ti_e}.kinetics.filtered.(app.context).COP.cycle(:,1,k),num2str(k),app.multipleStepIDInterval,color)
                    %     %             end
                    %     %         end
                    % end
                    xlabel('x-coordinate (mm)', 'FontSize', app.labelsFontSize);
                    ylabel('y-coordinate (mm)', 'FontSize', app.labelsFontSize);
                    title('COP progression in Ground CS', 'FontSize', app.titleFontSize);
                end
            end

            %%% figure con PP: clamp
            % if sum(ind==3)
            %     PP='CLAMP';
            %     h(3)=figure("Name", PP);
            %     tiledlayout(3,3);
            %     nexttile
            %     % figure 1: theta LEG
            % 
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Theta.(app.context).(PP).cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Theta.(app.context).(PP).cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color)
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel(['\theta_{' PP '}' '(deg)'], 'FontSize', app.labelsFontSize);
            %     title(['\theta ' app.context PP], 'FontSize', app.titleFontSize);
            % 
            %     % figure 2: deltaL in stance
            %     nexttile
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaL.cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaL.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color)
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     yline(0,'HandleVisibility','off')
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel('\DeltaL (mm)', 'FontSize', app.labelsFontSize);
            %     title(['Leg/Foot Shortening (' app.context,' app.context)'], 'FontSize', app.titleFontSize)
            % 
            %     %figure 3: vertical component PP
            %     nexttile
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaV.cycle,'Color',app.color)
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaV.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color)
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     yline(0,'HandleVisibility','off')
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel(['\deltaV ' PP ' (mm)' ], 'FontSize', app.labelsFontSize);
            %     title(['delta Vertical Component of ' app.context PP], 'FontSize', app.titleFontSize)
            % 
            %     % figure 4: magnitude of GRF projected
            %     nexttile
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).modGRF.cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Stiffness.(app.context).modGRF.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color);
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel('||GRFp|| (N)', 'FontSize', app.labelsFontSize);
            %     title(['Magnitude of projected GRF (' app.context,' app.context)'], 'FontSize', app.titleFontSize)
            % 
            %     % figure 5: GRFp vs deltaL
            %     nexttile
            %     plot(app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaL.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).modGRF.cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.trial.kinetics.lumped_models(2).Stiffness.(app.context).deltaL.cycle(:,j), app.trial.kinetics.lumped_models(2).Stiffness.(app.context).modGRF.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color);
            %         end
            %     end
            %     xlabel('\DeltaL (mm)', 'FontSize', app.labelsFontSize);
            %     ylabel('||GRFp|| (N)', 'FontSize', app.labelsFontSize);
            %     title(['\DeltaL vs Magnitude of projected GRF (' app.context,' app.context)'], 'FontSize', app.titleFontSize)
            % 
            %     % figure 6: L in % stance
            %     nexttile
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).L.cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Stiffness.(app.context).L.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color);
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel('||L|| (mm)', 'FontSize', app.labelsFontSize);
            %     title(['Leg length (' app.context,' app.context)'], 'FontSize', app.titleFontSize)
            % 
            %     % figure 7: Kist
            %     nexttile(8)
            %     plot(app.cycle, app.trial.kinetics.lumped_models(2).Stiffness.(app.context).Kist.cycle,'Color',app.color);
            %     if dim>1
            %         for j=1:dim
            %             plot_with_text(app.cycle', app.trial.kinetics.lumped_models(2).Stiffness.(app.context).Kist.cycle(:,j),num2str(j), app.multipleStepIDInterval, app.color);
            %         end
            %     end
            %     if ~strcmp(app.TrialType,'longJumping')
            %         if app.MSCheckBox.Value
            %             xline(app.trial.spacetimeParam.events.(app.context).kin.cycle_stance_MS,'-.','Color', app.color,'label','MS','LabelHorizontalAlignment','center')
            %         end
            %     end
            %     xlabel('% Stance', 'FontSize', app.labelsFontSize);
            %     ylabel('Stiffness (N/mm)', 'FontSize', app.labelsFontSize);
            %     title(['Leg Stiffness  (' app.context,' app.context)'], 'FontSize', app.titleFontSize)
            % 
            %     %figure 8: COP progression in Ground CS
            %     dim = size(app.trial.kinetics.filtered.(app.context).COP.cycle,3);
            %     nexttile(9)
            %     for j=1:dim
            %         hold on
            %         plot(app.trial.kinetics.filtered.(app.context).COP.cycle(:,2,j), app.trial.kinetics.filtered.(app.context).COP.cycle(:,1,j),'Color',app.color);
            %     end
            %     xlabel('x-coordinate (mm)', 'FontSize', app.labelsFontSize);
            %     ylabel('y-coordinate (mm)', 'FontSize', app.labelsFontSize);
            %     title('COP progression in Ground CS', 'FontSize', app.titleFontSize);
            % 
            % 
            % end
            sgtitle('Lumped Models');
        end
        
        function h = plotKinetics(app, h, normspec)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%RICORDA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%da sistemare perchè nel caso del salto la gamba di stacco
            %%%%%non c e in DB
            % if strcmp(app.TrialType,'longJumping')
            %     if strcmp(app.RightLowerLimb,'AL')
            %         app.context={'R'};
            %     elseif strcmp(app.LeftLowerLimb,'AL')
            %         side={'L'};
            %     end
            % end
            commonSegment = {};
            segment = {'Hip', 'Knee', 'Ankle'};

            tag=0;
            ii=1;
            rows = 4;
            cols = length(segment)+length(commonSegment);
            handles = nan(cols*rows);

            figure(h);
            if strcmp(normspec, 'normStance')
                codetimenorm = 1; % stance
            else
                codetimenorm = 2; % stride
            end

            out = app.trial.dyn.Events.exportEvents("perc",false);

            for i=2:length(app.context)
                fields = fieldnames(app.trial.dyn.Joints.(app.context{i}));
                realsegment = fields(contains(fields, segment));
                for j = 1 %sagittal plane only
                    for k = 1:length(realsegment)
                        if ~isempty(app.trial.dyn.Joints.(app.context{i}).(realsegment{k}).CardanAngles)
                            [cycles, ~, ~] = app.trial.dyn.Joints.(app.context{i}).(realsegment{k}).timeNorm(101, lower(strrep(normspec, "norm", "")));
                            dim=size(cycles.angle, 3);
                            % ================= Angle ====================
                            handles(ii) = subplot(rows, cols, ii);
                            for l = 1:dim
                                hold on;
                                p = plot(app.cycle, cycles.angle(:, j, l), 'Color', app.color.(app.context{i}));
                                if dim>1
                                    plot_with_text(app.cycle', cycles.angle(:, j, l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}))
                                end
                                if k>1
                                    p.HandleVisibility='off';
                                end
                            end
                            % if ~strcmp(app.TrialType,'longJumping')
                            %     if app.MSCheckBox.Value && codetimenorm == 1
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     elseif app.MSCheckBox.Value && codetimenorm == 2
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     end
                            % end
                            if app.FOCheckBox.Value && codetimenorm == 2
                                xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}), 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                            end
                            xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                            ylabel('Angle (°)', 'FontSize', app.labelsFontSize);
                            if isequal(tag,1)
                                legend(app.context{i},'FontSize', app.legendFontSize);
                            else
                                legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                            end
                            title(segment{k}, 'FontSize', app.titleFontSize);

                            % ============ Angular Velocity ===============
                            handles(ii+3) = subplot(rows, cols, ii+3);
                            for l = 1:dim
                                hold on;
                                p = plot(app.cycle, cycles.omega(:, j, l), 'Color', app.color.(app.context{i}));
                                if dim>1
                                    plot_with_text(app.cycle', cycles.omega(:, j, l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}))
                                end
                                if k>1
                                    p.HandleVisibility='off';
                                end
                            end
                            % if ~strcmp(app.TrialType,'longJumping')
                            %     if app.MSCheckBox.Value && codetimenorm == 1
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     elseif app.MSCheckBox.Value && codetimenorm == 2
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     end
                            % end
                            if app.FOCheckBox.Value && codetimenorm == 2
                                xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}), 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                            end
                            xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                            ylabel('Angular Velocity (°/s)', 'FontSize', app.labelsFontSize);
                            if isequal(tag,1)
                                legend(app.context{i},'FontSize', app.legendFontSize);
                            else
                                legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                            end
                            xlim([min(app.cycle), max(app.cycle)])

                            % ============ Joint Moment ===============
                            handles(ii+6) = subplot(rows, cols, ii+6);
                            try
                                for l = 1:dim
                                    hold on;
                                    p = plot(app.cycle, cycles.moment(:, j, l), 'Color', app.color.(app.context{i}));
                                    if dim>1
                                        plot_with_text(app.cycle', cycles.moment(:, j, l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}))
                                    end
                                    if k>1
                                        p.HandleVisibility='off';
                                    end
                                end
                                % if ~strcmp(app.TrialType,'longJumping')
                                %     if app.MSCheckBox.Value && codetimenorm == 1
                                %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %     elseif app.MSCheckBox.Value && codetimenorm == 2
                                %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %     end
                                % end
                                if app.FOCheckBox.Value && codetimenorm == 2
                                    xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}), 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                                end
                                xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                                ylabel('Moment (Nmm)', 'FontSize', app.labelsFontSize);
                                if isequal(tag,1)
                                    legend(app.context{i},'FontSize', app.legendFontSize);
                                else
                                    legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                                end
                                xlim([min(app.cycle), max(app.cycle)])
                            catch
                            end

                            % ============ Joint Power ===============
                            handles(ii+9) = subplot(rows, cols, ii+9);
                            try
                                for l = 1:dim
                                    hold on;
                                    p = plot(app.cycle, cycles.power(:, j, l), 'Color', app.color.(app.context{i}));
                                    if dim>1
                                        plot_with_text(app.cycle', cycles.power(:, j, l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}))
                                    end
                                    if k>1
                                        p.HandleVisibility='off';
                                    end
                                end
                                % if ~strcmp(app.TrialType,'longJumping')
                                %     if app.MSCheckBox.Value && codetimenorm == 1
                                %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %     elseif app.MSCheckBox.Value && codetimenorm == 2
                                %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',app.color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %     end
                                % end
                                if app.FOCheckBox.Value && codetimenorm == 2
                                    xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}), 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                                end
                                xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                                ylabel('Power (W)', 'FontSize', app.labelsFontSize);
                                if isequal(tag,1)
                                    legend(app.context{i},'FontSize', app.legendFontSize);
                                else
                                    legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                                end
                                xlim([min(app.cycle), max(app.cycle)])
                            catch
                            end
                        elseif i<length(app.context)
                            %i=i+1;
                            tag=1;
                        end
                        ii = ii+1;
                    end
                end
                ii = 1;
            end
            sgtitle("Lower limb kinetics - " + app.normtime);
        end

        function h = plotKinematicsUpper(app, h, normspec)
            commonSegments = {};
            segments = {"Shoulder", "Elbow", "Hip"};

            if strcmp(normspec, 'normStance')
                codetimenorm = 1; % stance
            else
                codetimenorm = 2; % stride
            end

            %h = nan(length(normtime), 1);

            %tiledlayout(3,3);
            tag=0;
            ii=1;
            rows = length(segments)+length(commonSegments);
            cols = length(app.plane);
            handles = nan(cols*rows);

            %figure: shoulder/elbow/hip angles
            figure(h)
            for k=1:length(segments)
                for j=1:length(app.plane)
                    handles(ii) = subplot(rows, cols, ii);
                    for i=2:length(app.context) %index starts from 2 to avoid context = "General"
                        if ~isempty(app.trial.dyn.Joints.(app.context{i}).(app.context{i}+segments{k}).CardanAngles)
                            [cycles, ~, ~] = app.trial.dyn.Joints.(app.context{i}).(app.context{i}+segments{k}).timeNorm(101,lower(strrep(normspec, 'norm', '')));
                            dim=size(cycles.angle, 3);
                            for l = 1:dim
                                hold on;
                                p = plot(app.cycle, cycles.angle(:,j,l), 'Color', app.color.(app.context{i}));
                                if dim>1
                                    plot_with_text(app.cycle', cycles.angle(:,j,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}));
                                end
                                if l>1
                                    p.HandleVisibility='off';
                                end
                            end
                            % if ~strcmp(app.TrialType,'longJumping')
                            %     if app.MSCheckBox.Value && codetimenorm == 1
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     elseif app.MSCheckBox.Value && codetimenorm == 2
                            %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     end
                            % end
                            if app.FOCheckBox.Value && codetimenorm == 2
                                out = app.trial.dyn.Events.exportEvents("perc",false);
                                xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}),'Label',{'FO'},'LabelHorizontalAlignment','center')
                            end

                            xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                            ylabel([segments{k} ' (deg)'], 'FontSize', app.labelsFontSize);
                            if isequal(tag,1)
                                legend(app.context{i},'FontSize', app.legendFontSize);
                            else
                                legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                            end
                            title(app.plane{j}, 'FontSize', app.titleFontSize);
                        elseif i<length(app.context)
                            %i=i+1;
                            tag=1;
                            continue;
                        end
                    end
                    xlim([min(app.cycle), max(app.cycle)])
                    ii = ii+1;
                end
                tag=0;
            end
            delete(handles(5));
            delete(handles(6));
            %sgtitle('Upper-limb joints')
            sgtitle("Upper limb kinematics - " + app.normtime);
        end
        
        function h = plotKinematicsLower(app, h, normspec)
            commonSegments = {"Back"};
            segments = {"Hip","Knee","Ankle"};

            if strcmp(normspec, "normStance")
                codetimenorm = 1; % stance
            else
                codetimenorm = 2; % stride
            end

            tag=0;
            ii=1;
            rows = length(segments)+length(commonSegments);
            cols = length(app.plane);
            handles = nan(cols*rows);

            figure(h);
            for j=1:length(app.plane)
                handles(ii) = subplot(rows, cols, ii);
                if ~isempty(app.trial.dyn.Joints.General.(commonSegments{1}).CardanAngles)
                    [cycles, ~, ~] = app.trial.dyn.Joints.General.Back.timeNorm(101,lower(strrep(normspec, 'norm', '')));
                    dim=size(cycles.angle, 3);
                    for k=1:dim
                        hold on;
                        p = plot(app.cycle, cycles.angle(:,j,k), 'Color', app.color.General);
                        if dim>1
                            plot_with_text(app.cycle', cycles.angle(:,j,k), num2str(k), app.multipleStepIDInterval, app.color.General)
                        end
                        if k>1
                            p.HandleVisibility = "off";
                        end
                    end
                    % if ~strcmp(app.TrialType,'longJumping')
                        %     if app.MSCheckBox.Value && codetimenorm == 1
                        %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                        %     elseif app.MSCheckBox.Value && codetimenorm == 2
                        %         if isfield(app.trial.spacetimeParam.events.(side{i}).kin,'cycle_MS')
                        %             xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                        %         end
                        %     end
                        % end
                        if app.FOCheckBox.Value && codetimenorm == 2
                            out = app.trial.dyn.Events.exportEvents("perc", false);
                            xline(out.Left.Foot_Off, '-.', 'HandleVisibility', 'off', 'Color', app.color.Left, 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                        end

                    xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                    ylabel('Trunk-pelvis angle (deg)', 'FontSize', app.labelsFontSize);
                    title(app.plane{j}, 'FontSize', app.titleFontSize);
                end
                xlim([min(app.cycle), max(app.cycle)])
                ii = ii+1;
            end

            for k=1:length(segments)
                for j=1:length(app.plane)
                    handles(ii) = subplot(4,3,ii);
                    for i=2:length(app.context) %index starts from 2 to avoid context = "General"
                        fields = fieldnames(app.trial.dyn.Joints.(app.context{i}));
                        idx = findStringMatch(fields, app.context{i}, segments{k});
                        if ~isempty(app.trial.dyn.Joints.(app.context{i}).(fields{idx}).CardanAngles)
                            [cycles, ~, ~] = app.trial.dyn.Joints.(app.context{i}).(fields{idx}).timeNorm(101,lower(strrep(normspec, 'norm', '')));
                            dim=size(cycles.angle, 3);
                            for l=1:dim
                                hold on;
                                plot(app.cycle, cycles.angle(:,j,l), 'Color', app.color.(app.context{i}));
                                if dim>1
                                    plot_with_text(app.cycle', cycles.angle(:,j,l), num2str(l), app.multipleStepIDInterval, app.color.(app.context{i}));
                                end
                                if l>1
                                    p.HandleVisibility='off';
                                end
                            end
                                % if ~strcmp(app.TrialType,'longJumping')
                                %     if app.MSCheckBox.Value && codetimenorm == 1
                                %         xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %     elseif app.MSCheckBox.Value && codetimenorm == 2
                                %         if isfield(app.trial.spacetimeParam.events.(side{i}).kin,'cycle_MS')
                                %             xline(app.trial.spacetimeParam.events.(side{i}).kin.cycle_MS,'-.','HandleVisibility','off','Color',color{i},'Label',{'MS'},'LabelHorizontalAlignment','center')
                                %         end
                                %     end
                                % end
                            if app.FOCheckBox.Value && codetimenorm == 2
                                out = app.trial.dyn.Events.exportEvents("perc",false);
                                xline(out.(app.context{i}).Foot_Off,'-.','HandleVisibility','off','Color',app.color.(app.context{i}),'Label',{'FO'},'LabelHorizontalAlignment','center')
                            end

                            xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                            ylabel([segments{k} ' (deg)'], 'FontSize', app.labelsFontSize);
                            if isequal(tag,1)
                                legend(app.context{i},'FontSize', app.legendFontSize);
                            else
                                legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                            end
                            title(app.plane{j}, 'FontSize', app.titleFontSize);
                        elseif i<length(app.context)
                            %i=i+1;
                            tag=1;
                            continue;
                        end
                    end
                    xlim([min(app.cycle), max(app.cycle)])
                    ii = ii+1;
                end
                tag=0;  
                %sgtitle('Lower-limb joints');
            end
            sgtitle("Lower limb kinematics - " + app.normtime);
        end
        
        function h = plotKinematicsSegments(app, h, normspec)
            commonSegment = {"Trunk", "Pelvis"};
            segments = {"Thigh", "Shank", "Socket", "FootClamp"};

            for nt = 1:length(normspec)
                if strcmp(normspec, "normStance")
                    codetimenorm = 1; % stance
                else
                    codetimenorm = 2; % stride
                end

                %tag=0;
                ii=1;
                rows = length(segments)+length(commonSegment)-2;
                cols = length(app.plane);
                handles = nan(cols*rows);

                figure(h);
                % figure: trunk-pelvis angle in sagittal/frontal/transversal plane
                for i=1:length(commonSegment)
                    [cycles, ~, ~] = app.trial.dyn.Segments.General.(commonSegment{i}).timeNorm(101,lower(strrep(normspec, "norm", "")));
                    dim=size(cycles.angle, 3);
                    for j=[3 1 2]
                        handles(ii) = subplot(rows, cols, ii);
                        ii = ii+1;
                        if ~isempty(app.trial.dyn.Segments.General.(commonSegment{i}).EulerAngles)

                            for k=1:dim
                                hold on;
                                p = plot(app.cycle, cycles.angle(:,j,k), 'Color', app.color.General);
                                if dim>1
                                    plot_with_text(app.cycle', cycles.angle(:,j,k), num2str(k), app.multipleStepIDInterval, app.color.General)
                                end
                                if k > 1
                                    p.HandleVisibility = "off";
                                end
                            end
                            
                            % if ~strcmp(app.TrialType,'longJumping')
                            %     if app.MSCheckBox.Value && codetimenorm == 1
                            %         xline(app.trial.spacetimeParam.events.(control_fig_side).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',control_fig_color,'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     elseif app.MSCheckBox.Value && codetimenorm == 2
                            %         xline(app.trial.spacetimeParam.events.(control_fig_side).kin.cycle_MS,'-.','HandleVisibility','off','Color',control_fig_color,'Label',{'MS'},'LabelHorizontalAlignment','center')
                            %     end
                            % end
                            if app.FOCheckBox.Value && codetimenorm == 2
                                out = app.trial.dyn.Events.exportEvents("perc",false);
                                xline(out.Left.Foot_Off, '-.', 'HandleVisibility','off', 'Color', app.color.General, 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                            end
                            xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                            ylabel([segments{k} ' (deg)'], 'FontSize', app.labelsFontSize);
                            legend(app.context{1}, 'FontSize', app.legendFontSize);
                            title(app.plane{j}, 'FontSize', app.titleFontSize);
                        elseif i<length(commonSegment)
                            %tag = 1;
                          
                            break;
                        end
                        xlim([min(app.cycle), max(app.cycle)])
                        
                    end
                    %tag = 0;
                end

                %figure: thigh/shank - socket/clamp - thigh/socket
                for i=2:length(app.context) %index starts from 2 to avoid context = "General"
                    ii=7;
                    lim=1;
                    for k=1:length(segments)
                        if lim < 3 % limita caso transtibiale a 2 segmenti per non uscire dai limiti del plot
                            idx = findStringMatch(fieldnames(app.trial.dyn.Segments.(app.context{i})), app.context{i}, segments{k});
                            if isempty(idx)
                                continue;
                            else
                                lim=lim+1;
                                [cycles, ~, ~] = app.trial.dyn.Segments.(app.context{i}).(app.context{i}+segments{k}).timeNorm(101,lower(strrep(normspec, 'norm', '')));
                                %xyz2sft=char(app.trial.dyn.Segments.(app.context{i}).(app.context{i}+segments{k}).AngleSequence)-'X';
                                dim=size(cycles.angle, 3);
                                for j=[3 1 2]
                                    handles(ii) = subplot(rows, cols, ii);
                                    ii = ii+1;  
                                    for l=1:dim
                                        hold on;
                                        p = plot(app.cycle, cycles.angle(:,j,l), 'Color', app.color.(app.context{i}));
                                        if dim>1
                                            plot_with_text(app.cycle', cycles.angle(:,j,l),num2str(k), app.multipleStepIDInterval, app.color.(app.context{i}))
                                        end
                                        if l > 1
                                            p.HandleVisibility = "off";
                                        end
                                    end
                                    % if ~strcmp(app.TrialType,'longJumping')
                                    %     if app.MSCheckBox.Value && codetimenorm == 1
                                    %         xline(app.trial.spacetimeParam.events.(control_fig_side).kin.cycle_stance_MS,'-.','HandleVisibility','off','Color',control_fig_color,'Label',{'MS'},'LabelHorizontalAlignment','center')
                                    %     elseif app.MSCheckBox.Value && codetimenorm == 2
                                    %         xline(app.trial.spacetimeParam.events.(control_fig_side).kin.cycle_MS,'-.','HandleVisibility','off','Color',control_fig_color,'Label',{'MS'},'LabelHorizontalAlignment','center')
                                  %     end
                                    % end
                                    if app.FOCheckBox.Value && codetimenorm == 2
                                        out = app.trial.dyn.Events.exportEvents("perc",false);
                                        xline(out.(app.context{i}).Foot_Off, '-.', 'HandleVisibility', 'off', 'Color', app.color.(app.context{i}), 'Label', {'FO'}, 'LabelHorizontalAlignment', 'center')
                                    end
                                    xlabel(['% ', strrep(normspec, "norm", "")], 'FontSize', app.labelsFontSize);
                                    ylabel([segments{k} ' (deg)'], 'FontSize', app.labelsFontSize);
                                    legend(app.context{2:end}, 'FontSize', app.legendFontSize);
                                    title(app.plane{j}, 'FontSize', app.titleFontSize);
                                   
                                end
                            end
                        else
                            break;
                        end
                        % elseif i<length(segments)
                        %     %i=i+1;
                        %     continue;
                        % end
                        %sgtitle('segment kinematics');
                    end
                end
            end
            sgtitle("Segment kinematics - " + app.normtime);
        end
        
        function h = plotKineticsImpulses(app)
            %%%%%da sistemare perchè nel caso del salto la gamba di stacco
            %%%%%non c e in DB
            % if strcmp(app.TrialType,'longJumping')
            %     if strcmp(app.RightLowerLimb,'AL')
            %         side={'R'};
            %     elseif strcmp(app.LeftLowerLimb,'AL')
            %         side={'L'};
            %     end
            % end
            segment={"FootClamp", "Shank"};

            R = app.trial.dyn.ForcePlatform.align2ISB();
            GRF = (R*(app.trial.dyn.ForcePlatform.GRF)')';

            for lr = 2:length(app.context) % start from 2 to skip 'General'
                [~, stance.analog, ~] = buildCycle(app.trial.dyn.Events, "analog");
                [~, stance.point, ~] = buildCycle(app.trial.dyn.Events, "point");
                %GRF = nan(101,3, size(stance.(app.context{lr}), 1));
                for istance = 1:size(stance.analog.(app.context{lr}), 1)
                    GRFstance.(app.context{lr})(:,:,istance) = time2cycle([], GRF(stance.analog.(app.context{lr})(istance, 1):stance.analog.(app.context{lr})(istance, 2), :), 101);
                    
                end
            end

            %interval=15;
            h = nan(length(app.context)-1, 1);

            for lr = 2:length(app.context) % start from 2 to skip 'General'
                for k = segment
                    fields =fieldnames(app.trial.dyn.Segments.(app.context{lr}));
                    idx = findStringMatch(fields, app.context{lr}, k);
                    if isempty(idx)
                        continue
                    end
                    
                    h(lr-1) = figure("Name", app.context{lr} + "_GRF_impulses");

                    tiledlayout(2,2);

                    % fig 1.1
                    nexttile
                    dim = size(GRFstance.(app.context{lr}), 3);
                    rho = nan(max(app.cycle)+1, dim);
                    for st = 1:dim
                        rho(:, st) = GRFstance.(app.context{lr})(:, 1, st) ./ GRFstance.(app.context{lr})(:, 2, st);
                        ts=abs(GRFstance.(app.context{lr})(:, 2, st));
                        ts=ts/max(ts) <0.1;
                        rho(ts,:)=nan;
                    end

                    theta = app.trial.dyn.Segments.(app.context{lr}).(fields{idx}).EulerAngles(:,3);
                    for istance = 1:size(stance.point.(app.context{lr}), 1)
                        thetaStance.(app.context{lr})(:,istance) = time2cycle([], theta(stance.point.(app.context{lr})(istance, 1):stance.point.(app.context{lr})(istance, 2), :), 101);
                    end

                    yyaxis left

                    for st = 1:dim
                        plot(app.cycle, GRFstance.(app.context{lr})(:, 1, st), 'Color', app.color.(app.context{lr}));
                        hold on
                        if dim>1
                            plot_with_text(app.cycle', GRFstance.(app.context{lr})(:, 1, st), num2str(st), app.multipleStepIDInterval, app.color.(app.context{lr}))
                        end
                        plot(app.cycle, GRFstance.(app.context{lr})(:, 2, st), 'Color', app.color.(app.context{lr}));
                        if dim>1
                            plot_with_text(app.cycle', GRFstance.(app.context{lr})(:, 2, st), num2str(st), app.multipleStepIDInterval, app.color.(app.context{lr}))
                        end
                    end
                    ylabel("GRF_{gnd}", 'Color', app.color.(app.context{lr}), 'FontSize', app.labelsFontSize)
                    xlabel("% Stance", 'FontSize', app.labelsFontSize)

                    yyaxis right
                    for i=1:dim
                        plot(app.cycle', rho(:, i)*10,  'Color', [119, 172, 48]/255)
                        hold on
                        if dim>1
                            plot_with_text(app.cycle', rho(:, i)*10, num2str(i), app.multipleStepIDInterval,  [119, 172, 48]/255)
                        end
                        plot(app.cycle', thetaStance.(app.context{lr})(:,i),  "Color", [237, 177, 32]/255)
                        if dim>1
                            plot_with_text(app.cycle', thetaStance.(app.context{lr})(:,i), num2str(i), app.multipleStepIDInterval,  [237, 177, 32]/255)
                        end

                    end

                    align_yyaxis_zero(gca)
                    rhoLabelText = sprintf('\\color[rgb]{%.2f, %.2f, %.2f}\\rho (au*10)',  [119, 172, 48]/255);
                    thetaLabelText = sprintf('\\color[rgb]{%.2f, %.2f, %.2f}\\Theta_{%s} (°)',  [237, 177, 32]/255, fields{idx});

                    %ylabelText = {rhoLabelText; thetaLabelText};
                    ylabel({rhoLabelText; thetaLabelText}, 'Interpreter', 'tex', 'FontSize', app.labelsFontSize);


                    % --------------------------------------------------------
                    % fig 1.2 - impulsi
                    % app.trial.dyn.Metadata.GRF_IMPULSES
                    nexttile
                    % xlab_AP={'braking', 'propulsive', 'net'};
                    AP_impulses = [app.trial.dyn.Metadata.GRF_IMPULSES.(app.context{lr} + "AnteroPosteriorBraking"),...
                        app.trial.dyn.Metadata.GRF_IMPULSES.(app.context{lr} + "AnteroPosteriorPropulsive"), ...
                        app.trial.dyn.Metadata.GRF_IMPULSES.(app.context{lr} + "AnteroPosteriorNet")]';

                    xstep_AP = cell(size(AP_impulses, 2), 1);
                    for xi=1:length(xstep_AP)
                        xstep_AP{xi} = "step " + num2str(xi);
                    end

                    %for xi = 1:size(xlab_AP)

                    barcolor = {[1 0 0], [0 1 0], [0 0.4471 0.7412]};

                    APbar = bar(AP_impulses' * 1000, 'BarWidth', 0.4); % Trasponi AP_impulses per le colonne
                    hold on;

                    % Applicazione dei colori
                    for i = 1:length(APbar)
                        APbar(i).FaceColor = 'flat';
                        APbar(i).CData = barcolor{i};%repmat(barcolor(i, :), size(AP_impulses, 2), 1); % Colori per ogni gruppo
                    end
                    hold off;


                    legend('braking','propulsion','net','FontSize',app.legendFontSize,'Location','northwest');
                    set(gca, 'XTickLabels', xstep_AP, 'FontSize', app.labelsFontSize);
                    xtips = [APbar.XEndPoints];
                    ytips = [APbar.YEndPoints];
                    labels = num2str([APbar.YData]','%0.3f');
                    text(xtips,ytips,labels,'HorizontalAlignment','Center',...
                        'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel('I_{AP} [10^{-3} N\cdots/BW]','FontSize',app.labelsFontSize);
                    title(' AP Impulses','FontSize', app.titleFontSize, interpreter='none');
                    % --------------------------------------------------------

                    % fig 2.1
                    nexttile

                    R = app.trial.dyn.Segments.(app.context{lr}).(fields{idx}).Orientation;
                    GRFloc = app.trial.dyn.ForcePlatform.GRF(1:5:end, :);
                    % rotate the GRF in the segment CS
                    for ii = 1:size(R, 3)
                        GRFloc(ii, :) = (R(:, :, ii)*(GRFloc(ii, :))')';
                    end
                    % time normalize the GRF over the stance phase
                    for istance = 1:size(stance.point.(app.context{lr}), 1)
                        GRFlocstance.(app.context{lr})(:,:,istance) = time2cycle([], GRFloc(stance.point.(app.context{lr})(istance, 1):stance.point.(app.context{lr})(istance, 2), :), 101);
                    end

                    dim = size(GRFlocstance.(app.context{lr}), 3);
                    rho = nan(max(app.cycle)+1, dim);

                    for st = 1:dim
                        rho(:, st) = GRFlocstance.(app.context{lr})(:, 1, st) ./ GRFlocstance.(app.context{lr})(:, 2, st);
                        ts=abs(GRFlocstance.(app.context{lr})(:, 2, st));
                        ts=ts/max(ts) <0.1;
                        rho(ts,:)=nan;
                    end
                        rho()
                    yyaxis left

                    for st = 1:dim
                        plot(app.cycle, GRFlocstance.(app.context{lr})(:, 1, st), 'Color', app.color.(app.context{lr}));
                        hold on
                        if dim>1
                            plot_with_text(app.cycle', GRFlocstance.(app.context{lr})(:, 1, st), num2str(st), app.multipleStepIDInterval, app.color.(app.context{lr}))
                        end
                        plot(app.cycle, GRFlocstance.(app.context{lr})(:, 2, st), 'Color', app.color.(app.context{lr}));
                        if dim>1
                            plot_with_text(app.cycle', GRFlocstance.(app.context{lr})(:, 2, st), num2str(st), app.multipleStepIDInterval, app.color.(app.context{lr}))
                        end
                    end
                    ylabel("GRF_{" + strrep(fields{idx}, app.context{lr}, "") + "}", 'Color', app.color.(app.context{lr}), 'FontSize', app.labelsFontSize)
                    xlabel("% Stance", 'FontSize', app.labelsFontSize)

                    yyaxis right
                    for i=1:dim
                        plot(app.cycle', rho(:, i)*10,  'Color', [119, 172, 48]/255)
                        hold on
                        if dim>1
                            plot_with_text(app.cycle', rho(:, i)*10, num2str(i), app.multipleStepIDInterval,  [119, 172, 48]/255)
                        end

                        plot(app.cycle', thetaStance.(app.context{lr})(:,i),  "Color", [237, 177, 32]/255)
                        if dim>1
                            plot_with_text(app.cycle', thetaStance.(app.context{lr})(:,i), num2str(i), app.multipleStepIDInterval,  [237, 177, 32]/255)
                        end

                    end

                    align_yyaxis_zero(gca)

                    rhoLabelText = sprintf('\\color[rgb]{%.2f, %.2f, %.2f}\\rho (au*10)',  [119, 172, 48]/255);
                    thetaLabelText = sprintf('\\color[rgb]{%.2f, %.2f, %.2f}\\Theta_{%s} (°)',  [237, 177, 32]/255, fields{idx});

                    %ylabelText = {rhoLabelText; thetaLabelText};
                    ylabel({rhoLabelText; thetaLabelText}, 'Interpreter', 'tex', 'FontSize', app.labelsFontSize);


                    % % --------------------------------------------------------
                    % fig 2.2 - impulsi V

                    nexttile
                    V_impulses = app.trial.dyn.Metadata.GRF_IMPULSES.(app.context{lr} + "Vertical");
                    barcolor = [0 0.4471 0.7412];
                    xstep_V = cell(size(V_impulses, 2), 1);
                    for jj = 1:size(V_impulses, 2)
                        xstep_V{jj} = [num2str(jj), ' step'];
                    end

                    Vbar = bar(V_impulses' * 1000, 'BarWidth', 0.4);
                    hold on;
                    for jj = 1:numel(Vbar)
                        Vbar(jj).FaceColor = 'flat'; % Abilita i colori personalizzati
                        Vbar(jj).CData = repmat(barcolor, size(V_impulses, 2), 1); % Assegna il colore unico
                    end
                    hold off;

                    legend('vertical', 'FontSize', app.legendFontSize,'Location', 'northwest');
                    set(gca, 'XTickLabels', xstep_V, 'FontSize', app.labelsFontSize);

                    xtips = [Vbar.XEndPoints];
                    ytips = [Vbar.YEndPoints];
                    labels = num2str([Vbar.YData]','%0.3f');
                    text(xtips,ytips,labels,'HorizontalAlignment','Center',...
                        'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel('I_V [N\cdots/BW]', 'FontSize', app.labelsFontSize);
                    title('V Impulses','FontSize', app.titleFontSize, interpreter='none');

                    % --------------------------------------------------------
                    sgtitle(app.context{lr} + 'GRF and Impulses');
                end
            end
        end
        
        
        function cumulativePlot(app, saving)
            errorStatus = 0;
            app.titleFontSize = app.TitlesSpinner.Value;
            app.legendFontSize = app.LegendsSpinner.Value;
            app.multipleStepIDInterval = app.MultiplestepIDintervalSpinner.Value;
            app.labelsFontSize = app.AxislabelsSpinner.Value;

            if app.StanceCheckBox.Value && app.StrideCheckBox.Value
                app.normtime = {'normStance', 'normStride'};
            elseif app.StanceCheckBox.Value && ~app.StrideCheckBox.Value
                app.normtime = {'normStance'};
            elseif ~app.StanceCheckBox.Value && app.StrideCheckBox.Value
                app.normtime = {'normStride'};
            else
                err = errordlg('Either stance or stride (or both) time normalization must be selected.', 'Error!', 'modal');
                uiwait(err);
            end
            
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Report generation started...']};
            app.LogTextArea.Value = app.LogRecord;
            close all

            %CHECK WHETHER SAVING OR JUST SHOWING
            if saving
                % CHECK E CREAZIONE DIRECTORY PER REPORT
                if exist(fullfile(app.SavePathEditField.Value, "Report"), 'dir') == 7
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - The report main directory exists.']};
                else
                    mkdir(fullfile(app.SavePathEditField.Value, "Report"));
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - The report main directory has been created.']};
                end
                app.LogTextArea.Value = app.LogRecord;

                %checking file type for export and create sub-folders
                if app.pngCheckBox.Value && exist(fullfile(app.SavePathEditField.Value, "Report", "PNG"), "dir") ~= 7 
                    mkdir(fullfile(app.SavePathEditField.Value, "Report", "PNG"));
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - PNG file folder has been created']};
                end
                if app.figCheckBox.Value && exist(fullfile(app.SavePathEditField.Value, "Report", "FIG"), "dir") ~= 7 
                    mkdir(fullfile(app.SavePathEditField.Value, "Report", "FIG"));
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - FIG file folder has been created']};
                end
                app.LogTextArea.Value = app.LogRecord;
            end
            
            h_upper = nan(length(app.normtime), 1);
            h_lower = nan(length(app.normtime), 1);
            h_seg = nan(length(app.normtime), 1);
            h_kin = nan(length(app.normtime), 1);
            hkinimp = cell(length(app.dynFileList), 1);
            hros = cell(length(app.dynFileList), 1);
            hlumped = cell(length(app.dynFileList), 1);

            for nt = 1:length(app.normtime)
                % if strcmp(app.normtime{nt}, 'normStance')
                %     codetimenorm = 1; % stance
                % else
                %     codetimenorm = 2; % stride
                % end

                h_upper(nt) = figure("Name", "Upper limb kinematics - " + app.normtime{nt});
                h_lower(nt) = figure("Name", "Lower limb kinematics - " + app.normtime{nt});
                h_seg(nt) = figure("Name", "Segment kinematics - " + app.normtime{nt});
                h_kin(nt) = figure("Name", "Lower limb kinetics - " + app.normtime{nt});
                %h_impulses(nt) = figure("Name", "Impulses - " + app.normtime{nt});
                %h_ros(nt) = figure("Name", "ROS - " + app.normtime{nt});
                %h_lumped(nt) = figure("Name", "Lumped parameters model output - " + app.normtime{nt});
            
            
                %%%% ------------------------- %%%%
                %%%% - QUI FUNZIONI DEI PLOT - %%%%
                %%%% ------------------------- %%%%
                trialName = cell(length(app.dynFileList), 1);

                for i = 1:length(app.dynFileList)
                    % import the i-th trial
                    app.trial = load(fullfile(app.MainPathEditField.Value, app.dynFileList{i}));
                    T=app.trial.dyn.ForcePlatform.align2ISB;
                    T(4,4)=1;
                    app.trial.dyn.changeCoordinates(T);
                    [~, trialName{i}, ~] = fileparts(app.dynFileList{i});
                    
                    % ================= KINEMATICS =================== %
                    if app.KinematicsCheckBox.Value
                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Joint and Segment Kinematics plot started...']};
                        app.LogTextArea.Value = app.LogRecord;
                        
                        % upper limb kinematics
                        try
                            plotKinematicsUpper(app, h_upper, app.normtime{nt})
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Upper limb kinematics plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Upper limb kinematics plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
    
                        % lower limb kinematics
                        try
                            plotKinematicsLower(app, h_lower, app.normtime{nt})
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Lower limb kinematics plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Lower limb kinematics plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
    
                        % segmental kinematics
                        try
                            plotKinematicsSegments(app, h_seg, app.normtime{nt})
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Segment kinematics plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Segment kinematics plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end
    
                    % ===================== KINETICS ======================= %
                    if app.KineticsCheckBox.Value
                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Joint Kinetics plot started...']};
                        app.LogTextArea.Value = app.LogRecord;
                        try
                            plotKinetics(app, h_kin, app.normtime{nt});
                            hkinimp{i} = plotKineticsImpulses(app);
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Joint kinetics, GRF and impulses plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Joint kinetics, GRF and impulses plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end
    
                    if app.ROSCheckBox.Value
                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Plot ROS started...']};
                        app.LogTextArea.Value = app.LogRecord;
                        try
                            hros{i}=plotROS(app);
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' ROS plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' ROS plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end
    
    
                    % ================= LUMPED PAR MODELS =================== %
                    if app.LumpedCheckBox.Value
                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Lumped parameters model outputs started...']};
                        app.LogTextArea.Value = app.LogRecord;
                        try
                            hlumped{i} = plotLumpedModels(app);
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Lumped parameters model outputs plot completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial ', trialName{i}, ' Lumped parameters model outputs plot could not be completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end
                end

                % ================= SAVING ================== %
                if saving
                    if length(trialName) > 1
                        tagName = "";
                    else
                        tagName = trialName{1};
                    end
                    if app.pngCheckBox.Value
                        try
                            saveas(h_upper, ...
                                fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Lower.png"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Upper limb kinematics: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Upper limb kinematics: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_lower, ...
                                fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Upper.png"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lower limb kinematics: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lower limb kinematics: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_seg, ...
                                fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Segments.png"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segment kinematics: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segment kinematics: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_kin, ...
                                fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinetics.png"))
                            for i = 1:numel(hkinimp)
                                for j = hkinimp{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".png"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Joint kinetics, GRF and Impulses: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Joint kinetics, GRF and Impulses: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            for i = 1:numel(hros)
                                for j = hros{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".png"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ROS: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ROS: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            for i = 1:numel(hlumped)
                                for j = hlumped{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "PNG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".png"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lumped parameters model output: PNG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lumped parameters model output: PNG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end

                    if app.figCheckBox.Value
                        try
                            saveas(h_upper, ...
                                fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Lower.fig"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Upper limb kinematics: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Upper limb kinematics: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_lower, ...
                                fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Upper.fig"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lower limb kinematics: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lower limb kinematics: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_seg, ...
                                fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinematics_Segments.fig"))
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segment kinematics: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Segment kinematics: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            saveas(h_kin, ...
                                fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                app.FilenameprefixEditField.Value + tagName + "_Kinetics.fig"))
                            for i = 1:numel(hkinimp)
                                for j = hkinimp{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".fig"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Joint kinetics, GRF and Impulses: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Joint kinetics, GRF and Impulses: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            for i = 1:numel(hros)
                                for j = hros{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".fig"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ROS: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ROS: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                        % ------------------------------------
                        try
                            for i = 1:numel(hlumped)
                                for j = hlumped{i}'
                                    saveas(j, ...
                                        fullfile(app.SavePathEditField.Value, "Report", "FIG", ...
                                        app.FilenameprefixEditField.Value + "_" + string(trialName{i}) + get(j, 'Name') + ".fig"))
                                end
                            end
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lumped parameters model output: FIG export completed.']};
                        catch
                            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Lumped parameters model output: FIG export not completed.']};
                            errorStatus = 1;
                        end
                        app.LogTextArea.Value = app.LogRecord;
                    end
                end
            end


            if errorStatus
                errline = 'with errors.';
            else
                errline = 'without errors.';
            end
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - EXPORT CONCLUDED ' errline newline '==============================']};
            app.LogTextArea.Value = app.LogRecord;
            if saving
            choice = questdlg('Do you want to close all the figures?', ...
                'EXPORT CONCLUDED', ...
                'Yes', 'No', 'Yes'); % Default: 'Yes'
            if strcmp(choice, 'Yes')
                close all;
            end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainroot)
            arguments
                app;
                mainroot (1,1) string = "";
            end

            try
                app.mainroot = mainroot;
                app.MainPathEditField.Value = mainroot;
                app.SavePathEditField.Value = mainroot;
            catch
                msg = msgbox("Please select or create a Subject folder to save the config file!", "No subject configuration file was found", "warn", "modal");
                uiwait(msg);
            end
        end

        % Button pushed function: MainBrowseButton
        function MainBrowseButtonPushed(app, event)
            selectedfolder = uigetdir();
            try
                [path, folder] = fileparts(selectedfolder);
            catch
                err = errordlg(["You have to select the main folder where the data are stored.", "Cannot make any report without data folder!"], "Database main folder not selected");
                uiwait(err);
            end
            app.MainPathEditField.Value = fullfile(path, folder);
        end

        % Button pushed function: PickRegistryButton
        function PickRegistryButtonPushed(app, event)
            [selectedfile, selectedfolder] = uigetfile(fullfile(app.MainPathEditField.Value, '*.xls;*.xlsx'), 'Select an Excel file');
            try
                registry = fullfile(selectedfolder, selectedfile);
            catch
                err = errordlg(["Please select a valid Excel file.", "Cannot proceed without the file!"], "File Not Selected");
                uiwait(err);
            end
            app.ProcessedFilesRegistryEditField.Value = registry;
            app.registryData = convertString(readtable(registry));
            app.UITable.Data = app.registryData;
            app.ClassificationDropDown.Items = ['--'; unique(string(app.registryData.("Classification")))];
            app.FootModelDropDown.Items = ['--'; unique(string(app.registryData.("FootModel")))];
            app.FootCATDropDown.Items = ['--'; unique(string(app.registryData.("FootCat")))];
            app.SocketAlignmentDropDown.Items = ['--'; unique(string(app.registryData.("SocketAlignment")))];
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if ~strcmp(app.ClassificationDropDown.Value, '--')
                filter1 = strcmp(app.registryData.("Classification"), app.ClassificationDropDown.Value);
            else
                filter1 = true(size(app.registryData, 1), 1);
            end
            if ~strcmp(app.FootModelDropDown.Value, '--')
                filter2 = strcmp(app.registryData.("FootModel"), app.FootModelDropDown.Value);
            else
                filter2 = true(size(app.registryData, 1), 1);
            end
            if ~strcmp(app.FootCATDropDown.Value, '--')
                filter3 = strcmp(app.registryData.("FootCat"), app.FootCATDropDown.Value);
            else
                filter3 = true(size(app.registryData, 1), 1);
            end
            if ~strcmp(app.SocketAlignmentDropDown.Value, '--')
                filter4 = strcmp(app.registryData.("SocketAlignment"), app.SocketAlignmentDropDown.Value);
            else
                filter4 = true(size(app.registryData, 1), 1);
            end
            combinedfilter = filter1 & filter2 & filter3 & filter4;
            
            %Apply the combined filter to the table
            app.UITable.Data = app.registryData(combinedfilter, :);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if size(app.UITable, 1) ~= 0
                app.statFileList = app.UITable.Data.("Static");
                app.dynFileList = app.UITable.Data.("MATfile");

                app.GenerateReportPreviewButton.Enable = "on";
                app.CloseFiguresButton.Enable = "on";
                app.ExportfiguresButton.Enable = "on";
            else
                msgfig1 = warndlg(["The filtered registry is empty!", "Please adjust filter parameters or check the source registry file."],'Cannot load any files','modal');
                uiwait(msgfig1)
            end
        end

        % Value changed function: AllVarCheckBox
        function AllVarCheckBoxValueChanged(app, event)
            value = app.AllVarCheckBox.Value;
            if value
                %disable selection for other options
                app.LumpedCheckBox.Value = 1;
                app.ROSCheckBox.Value = 1;
                app.STparCheckBox.Value = 1;
                app.KineticsCheckBox.Value = 1;
                app.KinematicsCheckBox.Value = 1;
                app.LumpedCheckBox.Enable = "off";
                app.ROSCheckBox.Enable = "off";
                app.STparCheckBox.Enable = "off";
                app.KineticsCheckBox.Enable = "off";
                app.KinematicsCheckBox.Enable = "off";
            else
                %enable selection for other options
                app.LumpedCheckBox.Enable = "on";
                app.ROSCheckBox.Enable = "on";
                app.STparCheckBox.Enable = "on";
                app.KineticsCheckBox.Enable = "on";
                app.KinematicsCheckBox.Enable = "on";
            end
        end

        % Value changed function: AllSideCheckBox
        function AllSideCheckBoxValueChanged(app, event)
            value = app.AllSideCheckBox.Value;
            if value
                %disable selection for other options
                app.RightCheckBox.Value = 1;
                app.LeftCheckBox.Value = 1;
                app.RightCheckBox.Enable = "off";
                app.LeftCheckBox.Enable = "off";
                app.RightColorPicker.Enable = "off";
                app.LeftColorPicker.Enable = "off";
            else
                %enable selection for other options
                app.RightCheckBox.Enable = "on";
                app.LeftCheckBox.Enable = "on";
                app.RightColorPicker.Enable = "on";
                app.LeftColorPicker.Enable = "on";
            end
        end

        % Button pushed function: GenerateReportPreviewButton
        function GenerateReportPreviewButtonPushed(app, event)
            saving = 0;
            cumulativePlot(app, saving);
        end

        % Button pushed function: ExportfiguresButton
        function ExportButtonPushed(app, event)
            saving = 1;
            cumulativePlot(app, saving);
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveBrowseButton
        function SaveBrowseButtonPushed(app, event)
            selectedfolder = uigetdir();
            try
                [path, folder] = fileparts(selectedfolder);
                app.SavePathEditField.Value = fullfile(path, folder);
            catch
                err = errordlg(["You have to select the folder where the report should be stored!", "If no folder is selected, the report will be saved in the Main folder selected."], "Report saving folder not selected");
                uiwait(err);
            end
        end

        % Callback function
        function GeneralColorPickerValueChanged(app, event)
            app.color.General = app.GeneralColorPicker.Value;
        end

        % Callback function
        function LeftColorPickerValueChanged(app, event)
            app.color.Left = app.LeftColorPicker.Value;
        end

        % Callback function
        function RightColorPickerValueChanged(app, event)
            app.color.Right = app.RightColorPicker.Value;
        end

        % Value changed function: RightCheckBox
        function RightCheckBoxValueChanged(app, event)
            if app.RightCheckBox.Value
                app.RightColorPicker.Enable = "on";
            else
                app.RightColorPicker.Enable = "off";
            end
        end

        % Value changed function: LeftCheckBox
        function LeftCheckBoxValueChanged(app, event)
            if app.LeftCheckBox.Value
                app.LeftColorPicker.Enable = "on";
            else
                app.LeftColorPicker.Enable = "off";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Click here to close the Report Maker app'};
            app.CloseButton.Text = 'Close';
            app.ExportfiguresButton = uibutton(app.UIFigure, 'push');
            app.ExportfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportfiguresButton.Enable = 'off';
            app.ExportfiguresButton.Tooltip = {'By clicking here the figures will be genereted and stored in the ''Saving directory > Report'' folder. Figures will be saved as *.fig and/or *.png based on your selection on the ''Main settings'' panel on top of the app window. '};
            app.ExportfiguresButton.Text = 'Export figures';
            app.CloseFiguresButton = uibutton(app.UIFigure, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Enable = 'off';
            app.CloseFiguresButton.Tooltip = {'Close all open figures'};
            app.CloseFiguresButton.Text = 'Close Figures';
            app.GenerateReportPreviewButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportPreviewButtonPushed, true);
            app.GenerateReportPreviewButton.Enable = 'off';
            app.GenerateReportPreviewButton.Tooltip = {'Click here if you want to see how the generated figures will look like. Use this as a check to see whether you selected everything you needed.'};
            app.GenerateReportPreviewButton.Text = 'Generate Report Preview';
            app.ReportsettingPanel = uipanel(app.UIFigure);
            app.ReportsettingPanel.Title = 'Report setting:';
            app.BodySidePanel = uipanel(app.ReportsettingPanel);
            app.BodySidePanel.Title = 'Body Side';
            app.LeftCheckBox = uicheckbox(app.BodySidePanel);
            app.LeftCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftCheckBoxValueChanged, true);
            app.LeftCheckBox.Enable = 'off';
            app.LeftCheckBox.Text = 'Left';
            app.LeftCheckBox.Value = true;
            app.RightCheckBox = uicheckbox(app.BodySidePanel);
            app.RightCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightCheckBoxValueChanged, true);
            app.RightCheckBox.Enable = 'off';
            app.RightCheckBox.Text = 'Right';
            app.RightCheckBox.Value = true;
            app.AllSideCheckBox = uicheckbox(app.BodySidePanel);
            app.AllSideCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSideCheckBoxValueChanged, true);
            app.AllSideCheckBox.Text = 'All';
            app.AllSideCheckBox.Value = true;
            app.GeneralsegmentsColorPickerLabel = uilabel(app.BodySidePanel);
            app.GeneralsegmentsColorPickerLabel.Text = 'General segments';
            app.OutputvariablesPanel = uipanel(app.ReportsettingPanel);
            app.OutputvariablesPanel.Title = 'Output variables';
            app.KinematicsCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.KinematicsCheckBox.Enable = 'off';
            app.KinematicsCheckBox.Text = 'Kinematics';
            app.KinematicsCheckBox.Value = true;
            app.KineticsCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.KineticsCheckBox.Enable = 'off';
            app.KineticsCheckBox.Text = 'Kinetics';
            app.KineticsCheckBox.Value = true;
            app.STparCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.STparCheckBox.Enable = 'off';
            app.STparCheckBox.Text = 'Spatio-temporal parameters';
            app.STparCheckBox.Value = true;
            app.ROSCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.ROSCheckBox.Enable = 'off';
            app.ROSCheckBox.Text = 'ROS';
            app.ROSCheckBox.Value = true;
            app.LumpedCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.LumpedCheckBox.Enable = 'off';
            app.LumpedCheckBox.Text = 'Lumped parameters models';
            app.LumpedCheckBox.Value = true;
            app.AllVarCheckBox = uicheckbox(app.OutputvariablesPanel);
            app.AllVarCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllVarCheckBoxValueChanged, true);
            app.AllVarCheckBox.Text = 'All';
            app.AllVarCheckBox.Value = true;
            app.FO_MS_Panel = uipanel(app.ReportsettingPanel);
            app.FO_MS_Panel.Title = 'Intermediate events';
            app.FOCheckBox = uicheckbox(app.FO_MS_Panel);
            app.FOCheckBox.Text = 'Foot Off (FO)';
            app.FOCheckBox.Value = true;
            app.MSCheckBox = uicheckbox(app.FO_MS_Panel);
            app.MSCheckBox.Text = 'Mid-Stance (MS)';
            app.TimenormalizePanel = uipanel(app.ReportsettingPanel);
            app.TimenormalizePanel.Title = 'Time normalize';
            app.StrideCheckBox = uicheckbox(app.TimenormalizePanel);
            app.StrideCheckBox.Text = 'Stride';
            app.StrideCheckBox.Value = true;
            app.StanceCheckBox = uicheckbox(app.TimenormalizePanel);
            app.StanceCheckBox.Text = 'Stance';
            app.FontssizePanel = uipanel(app.ReportsettingPanel);
            app.FontssizePanel.Title = 'Fonts size';
            app.AxislabelsSpinnerLabel = uilabel(app.FontssizePanel);
            app.AxislabelsSpinnerLabel.Text = 'Axis labels';
            app.AxislabelsSpinner = uispinner(app.FontssizePanel);
            app.AxislabelsSpinner.Limits = [6 40];
            app.AxislabelsSpinner.Value = 12;
            app.MultiplestepIDintervalLabel = uilabel(app.FontssizePanel);
            app.MultiplestepIDintervalLabel.Text = {'Multiple step'; 'ID interval'};
            app.MultiplestepIDintervalSpinner = uispinner(app.FontssizePanel);
            app.MultiplestepIDintervalSpinner.Limits = [6 40];
            app.MultiplestepIDintervalSpinner.Value = 15;
            app.LegendsSpinnerLabel = uilabel(app.FontssizePanel);
            app.LegendsSpinnerLabel.Text = 'Legends';
            app.LegendsSpinner = uispinner(app.FontssizePanel);
            app.LegendsSpinner.Limits = [6 40];
            app.LegendsSpinner.Value = 10;
            app.TitlesSpinner_3Label = uilabel(app.FontssizePanel);
            app.TitlesSpinner_3Label.Text = 'Titles';
            app.TitlesSpinner = uispinner(app.FontssizePanel);
            app.TitlesSpinner.Limits = [6 40];
            app.TitlesSpinner.Value = 12;
            app.LogTextAreaLabel = uilabel(app.ReportsettingPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.ReportsettingPanel);
            app.LogTextArea.Editable = 'off';
            app.SaveBrowseButton = uibutton(app.ReportsettingPanel, 'push');
            app.SaveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBrowseButtonPushed, true);
            app.SaveBrowseButton.Tooltip = {'Select the main folder you want to store your reports in. This is set by default equal to the Main working directory.'; 'Please note that the ''Report'' folder will be automatically created inside the selected ''Saving directory''.'};
            app.SaveBrowseButton.Text = 'Browse';
            app.SaveBrowseButton.Icon = fullfile(pathToMLAPP, 'Imgs', 'folder.png');
            app.MainsubjectfolderLabel_2 = uilabel(app.ReportsettingPanel);
            app.MainsubjectfolderLabel_2.Text = 'Saving directory:';
            app.SavePathEditField = uieditfield(app.ReportsettingPanel, 'text');
            app.FilenameprefixEditFieldLabel = uilabel(app.ReportsettingPanel);
            app.FilenameprefixEditFieldLabel.Text = 'File name prefix:';
            app.FilenameprefixEditField = uieditfield(app.ReportsettingPanel, 'text');
            app.FilenameprefixEditField.Tooltip = {'Write the file name prefix that is going to be added to all the generated figures'};
            app.DatabasefilteringandfileselectionPanel = uipanel(app.UIFigure);
            app.DatabasefilteringandfileselectionPanel.Title = 'Database filtering and file selection:';
            app.LoadButton = uibutton(app.DatabasefilteringandfileselectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Click here to allow the app collecting the files you selected by filtering the processed files database.'};
            app.LoadButton.Text = 'Load';
            app.UITable = uitable(app.DatabasefilteringandfileselectionPanel);
            app.UITable.ColumnName = {'Trial'; 'Static'; 'Configuration'; 'Classification'; 'SubjectID'; 'FootModel'; 'FootCat'; 'Socket'; 'SocketAlignment'; 'MATfile'};
            app.UITable.RowName = {};
            app.ClassificationDropDownLabel = uilabel(app.DatabasefilteringandfileselectionPanel);
            app.ClassificationDropDownLabel.Text = 'Classification';
            app.ClassificationDropDown = uidropdown(app.DatabasefilteringandfileselectionPanel);
            app.FiltersLabel = uilabel(app.DatabasefilteringandfileselectionPanel);
            app.FiltersLabel.Text = 'Filters:';
            app.FootModelDropDownLabel = uilabel(app.DatabasefilteringandfileselectionPanel);
            app.FootModelDropDownLabel.Text = 'FootModel';
            app.FootModelDropDown = uidropdown(app.DatabasefilteringandfileselectionPanel);
            app.FootCATDropDownLabel = uilabel(app.DatabasefilteringandfileselectionPanel);
            app.FootCATDropDownLabel.Text = 'Foot CAT';
            app.FootCATDropDown = uidropdown(app.DatabasefilteringandfileselectionPanel);
            app.SocketAlignmentDropDownLabel = uilabel(app.DatabasefilteringandfileselectionPanel);
            app.SocketAlignmentDropDownLabel.Text = 'Socket Alignment';
            app.SocketAlignmentDropDown = uidropdown(app.DatabasefilteringandfileselectionPanel);
            app.FilterButton = uibutton(app.DatabasefilteringandfileselectionPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.MainsettingsPanel = uipanel(app.UIFigure);
            app.MainsettingsPanel.Title = 'Main settings';
            app.figCheckBox = uicheckbox(app.MainsettingsPanel);
            app.figCheckBox.Text = '*.fig';
            app.figCheckBox.Value = true;
            app.pngCheckBox = uicheckbox(app.MainsettingsPanel);
            app.pngCheckBox.Text = '*.png';
            app.pngCheckBox.Value = true;
            app.ExportfileformatLabel = uilabel(app.MainsettingsPanel);
            app.ExportfileformatLabel.Text = 'Export file format:';
            app.MainBrowseButton = uibutton(app.MainsettingsPanel, 'push');
            app.MainBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MainBrowseButtonPushed, true);
            app.MainBrowseButton.Tooltip = {'This is not mandatory.'};
            app.MainBrowseButton.Text = 'Browse';
            app.MainBrowseButton.Icon = fullfile(pathToMLAPP, 'Imgs', 'folder.png');
            app.MainsubjectfolderLabel = uilabel(app.MainsettingsPanel);
            app.MainsubjectfolderLabel.Text = 'Main working directory:';
            app.MainPathEditField = uieditfield(app.MainsettingsPanel, 'text');
            app.MainsettingsLabel = uilabel(app.MainsettingsPanel);
            app.MainsettingsLabel.Text = '   Main settings';
            app.PickRegistryButton = uibutton(app.MainsettingsPanel, 'push');
            app.PickRegistryButton.ButtonPushedFcn = createCallbackFcn(app, @PickRegistryButtonPushed, true);
            app.PickRegistryButton.Tooltip = {'Select a processed data registry. It is an Excel file containing the info of all the processed data files.'};
            app.PickRegistryButton.Text = 'Pick registry';
            app.PickRegistryButton.Icon = fullfile(pathToMLAPP, 'Imgs', 'folder.png');
            app.ProcessedfilesregistryEditFieldLabel = uilabel(app.MainsettingsPanel);
            app.ProcessedfilesregistryEditFieldLabel.Text = 'Processed files registry:';
            app.ProcessedFilesRegistryEditField = uieditfield(app.MainsettingsPanel, 'text');
            app.pdfCheckBox = uicheckbox(app.MainsettingsPanel);
            app.pdfCheckBox.Enable = 'off';
            app.pdfCheckBox.Text = '*.pdf';
            app.ParaRun = uiimage(app.UIFigure);
            app.ParaRun.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.ReportMakerLabel = uilabel(app.UIFigure);
            app.ReportMakerLabel.Text = 'Report Maker';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.jpg';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'UNIPD.png';
            app.UNIVR = uiimage(app.UIFigure);
            app.UNIVR.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReportMaker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end