classdef StaticTrialConstructor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProOlympiaStaticFileCreatorUIFigure  matlab.ui.Figure
        CloseButton                    matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        OPTIONSTab                     matlab.ui.container.Tab
        SAVINGOPTIONSPanel             matlab.ui.container.Panel
        SaveConfigurationButton        matlab.ui.control.Button
        OpenafterexportCheckBox        matlab.ui.control.CheckBox
        matCheckBox                    matlab.ui.control.CheckBox
        C3DCheckBox                    matlab.ui.control.CheckBox
        SaveTrialButton                matlab.ui.control.Button
        PROCESSINGOPTIONSPanel         matlab.ui.container.Panel
        GenderSwitch                   matlab.ui.control.Switch
        GenderSwitchLabel              matlab.ui.control.Label
        UseinertialpropertiesCheckBox  matlab.ui.control.CheckBox
        ApplyChangesButton             matlab.ui.control.Button
        SubjectMasskgEditField         matlab.ui.control.NumericEditField
        SubjectMasskgEditFieldLabel    matlab.ui.control.Label
        FileGroundDropDown             matlab.ui.control.DropDown
        RotateGroundDropDownLabel      matlab.ui.control.Label
        CombineForcePlatesListBox      matlab.ui.control.ListBox
        CombineForcePlatesCheckBox     matlab.ui.control.CheckBox
        TrialUnitsSwitch               matlab.ui.control.Switch
        TrialUnitsSwitchLabel          matlab.ui.control.Label
        WarningLabel                   matlab.ui.control.Label
        rightarrowLabel                matlab.ui.control.Label
        CombineForcePlatesLabel        matlab.ui.control.Label
        TargetGroundDropDown           matlab.ui.control.DropDown
        EditField                      matlab.ui.control.EditField
        AverageTrialCheckBox           matlab.ui.control.CheckBox
        ClearUnlabeledTracksCheckBox   matlab.ui.control.CheckBox
        LogTab                         matlab.ui.container.Tab
        ExportlogButton                matlab.ui.control.Button
        ClearlogButton                 matlab.ui.control.Button
        LogTextArea                    matlab.ui.control.TextArea
        EditInertialPropertiesButton   matlab.ui.control.Button
        INAIL_2                        matlab.ui.control.Image
        UNIPD_2                        matlab.ui.control.Image
        INAIL                          matlab.ui.control.Image
        StaticFileConfiguratorLabel    matlab.ui.control.Label
        OLYMPIA                        matlab.ui.control.Image
        UNIPD                          matlab.ui.control.Image
        CONFIGURATIONFILEEXPLOREREDITORLabel  matlab.ui.control.Label
        AddRemoveItemDropDown          matlab.ui.control.DropDown
        AddRemoveItemDropDownLabel     matlab.ui.control.Label
        RemoveButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MarkerTab                      matlab.ui.container.Tab
        MarkerTable                    matlab.ui.control.Table
        VirtualMarkerDefTab            matlab.ui.container.Tab
        VirtualMarkerTable             matlab.ui.control.Table
        CalibratedMarkerDefTab         matlab.ui.container.Tab
        CalMarkerTable                 matlab.ui.control.Table
        SegmentTab                     matlab.ui.container.Tab
        SegmentTable                   matlab.ui.control.Table
        JointTab                       matlab.ui.container.Tab
        JointTable                     matlab.ui.control.Table
        KinematicChainTab              matlab.ui.container.Tab
        KinematicChainTable            matlab.ui.control.Table
        OpenStaticTrialButton          matlab.ui.control.Button
        OpenconfigurationfileButton    matlab.ui.control.Button
    end


    properties (Access = private)
        rootdir % Description
        configfile
        Configuration
        Trial
        TrialFile
        XMLatt="_att";
        LogRecord = cell(0);
        Gender="Male";
        AnalysisDone=false;
    end

    methods (Access = private)

        function refreshTables(app)
            cfg=app.Configuration;
            dat=struct2table(cfg.MarkerSet.Marker);
            app.MarkerTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
            app.MarkerTable.Data=dat;
            if isfield(cfg.MarkerSet,'VirtualMarkerDef')
                dat=struct2table(cfg.MarkerSet.VirtualMarkerDef);
                app.VirtualMarkerTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
                app.VirtualMarkerTable.Data=dat;
            end
            if isfield(cfg.MarkerSet,'CalibratedMarkerDef')
                dat=struct2table(cfg.MarkerSet.CalibratedMarkerDef);
                app.CalMarkerTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
                app.CalMarkerTable.Data=dat;
            end
            dat=struct2table(cfg.KinematicModel.Segment);
            app.SegmentTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
            app.SegmentTable.Data=dat;

            dat=struct2table(cfg.KinematicModel.Joint);
            app.JointTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
            app.JointTable.Data=dat;

            dat=struct2table(cfg.KinematicModel.KinematicChain);
            app.KinematicChainTable.ColumnName=strrep(dat.Properties.VariableNames,app.XMLatt,"");
            app.KinematicChainTable.Data=dat;

            app.refreshConfig;
        end

        function refreshConfig(app)
            %markerset
            if isempty(app.MarkerTable.Data)
                app.Configuration.MarkerSet.Marker=struct();
            else
                app.Configuration.MarkerSet.Marker=table2struct(app.MarkerTable.Data)';
            end
            if isempty(app.VirtualMarkerTable.Data)
                app.Configuration.MarkerSet.VirtualMarkerDef=struct();
            else
                app.Configuration.MarkerSet.VirtualMarkerDef=table2struct(app.VirtualMarkerTable.Data)';
            end
            if isempty(app.CalMarkerTable.Data)
                app.Configuration.MarkerSet.CalibratedMarkerDef=struct();
            else
                app.Configuration.MarkerSet.CalibratedMarkerDef=table2struct(app.CalMarkerTable.Data)';
            end
            %kinematicmodel
            for f=string(fieldnames(app.Configuration.KinematicModel))'
                app.Configuration.KinematicModel.(f)=table2struct(app.([f+"Table"]).Data)';
            end

            app.SaveConfigurationButton.Enable=false;


        end


        % function str = table2struct(tab)
        %     if isempty(tab)
        %        str=str();
        %     else
        %         str=table2struct(tab);
        %     end
        % end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, root)
            arguments
                app;
                root string = "";
            end

            app.rootdir = root;

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Static Trial Analyzer app ready.']};
            app.LogTextArea.Value = app.LogRecord;
        end

        % Button pushed function: OpenStaticTrialButton
        function OpenStaticTrialButtonPushed(app, event)
            [f, p]=uigetfile(fullfile(app.rootdir,'*.c3d'));
            if f==0
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Static file not selected.']};
                app.LogTextArea.Value = app.LogRecord;
                return
            end

            app.SaveConfigurationButton.Enable=false;

            app.rootdir=p;
            app.TrialFile=fullfile(p,f);
            app.TrialUnitsSwitch.Enable=true;
            app.SubjectMasskgEditField.Enable=true;
            app.AverageTrialCheckBox.Enable=true;
            app.ApplyChangesButton.Enable=true;
            app.ClearUnlabeledTracksCheckBox.Enable=true;
            app.CombineForcePlatesCheckBox.Enable=true;
            app.Trial=Trial(app.TrialFile);
            if ~isempty(app.Trial.ForcePlatform)
                app.CombineForcePlatesListBox.Enable=true;
                app.CombineForcePlatesListBox.Items=[app.Trial.ForcePlatform.Label];
                if numel(app.CombineForcePlatesListBox.Items)<2
                    app.CombineForcePlatesCheckBox.Enable=false;
                end
                mass=app.Trial.ForcePlatform.combineFP;
                mass=mass.mean;
                mass=vecnorm(mass.GRF)/9.81;
                app.SubjectMasskgEditField.Value=mass;
                app.CombineForcePlatesCheckBoxValueChanged;
            else
                mass=1;
                app.SubjectMasskgEditField.Value=mass;
            end

            if isfield(app.Configuration,"gender")
                g=app.Configuration.gender;
                if g=="Male"
                    app.GenderSwitch.Value="M";
                else
                    app.GenderSwitch.Value="F";
                end
            end


            pp=cat(3,app.Trial.Points.Coordinates);
            pp=vecnorm(pp,2,2);
            m=mean(pp,1,'omitnan');
            s=std(pp,1,1);
            mm=max(s./m,[],'all');
            mm=mm;
            if mm>0.1
                app.WarningLabel.Visible="on";
            else
                app.WarningLabel.Visible="off";
            end
            app.ApplyChangesButton.Enable = "on";
        end

        % Button pushed function: OpenconfigurationfileButton
        function OpenconfigurationfileButtonPushed(app, event)
            if strcmp(app.rootdir, "")
                [f, p]=uigetfile('*.xml');
                %[f, p]=uigetfile('*.configuration');
            else
                [f, p]=uigetfile(fullfile(app.rootdir, '*.xml'));
                %[f, p]=uigetfile(fullfile(app.rootdir, '*.configuration'));
            end
            app.configfile=fullfile(p,f);
            if f==0
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Configuration file not selected.']};
                app.LogTextArea.Value = app.LogRecord;
                return
            end
            cfg=readstruct(fullfile(p,f),"FileType","xml","AttributeSuffix",app.XMLatt);
            app.Configuration=cfg;
            app.rootdir=p;
            app.refreshTables;
            app.OpenStaticTrialButton.Enable=1;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)

            if app.AnalysisDone
                answer=questdlg("The loaded trial has been already processed, by continuing you will permanently delete previous analysis, prooceed?","Attention required","Cancel");
                if not(answer=="Yes")
                    return
                end

            end
            %app.Trial=Trial(app.TrialFile);
            %app.Trial.ConfigFile=app.Configuration;
            %app.Trial.applyConfiguration;
            app.Trial.XMLatt=app.XMLatt;

            if app.ClearUnlabeledTracksCheckBox.Value
                cl=app.EditField.Value;
            else
                cl="";
            end

            mass=app.SubjectMasskgEditField.Value;
            % if contains(app.TrialUnitsSwitch.Value,"kg")
            %    tu="m";
            %    massu="kg";
            % else
            %    tu="mm";
            %    massu="t";
            %    mass=mass/1000;
            % end
            massu="kg";
            tu="mm";


            if app.CombineForcePlatesCheckBox.Value
                index=app.CombineForcePlatesListBox.ValueIndex;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Force Plate data combined.']};
                app.LogTextArea.Value = app.LogRecord;
            else
                index=nan;
            end

            T2=dropDown2T(app.TargetGroundDropDown.Value);
            T1=dropDown2T(app.FileGroundDropDown.Value);
            T=T1/T2;

            trial=StaticTrialProcessor(app.TrialFile,app.Configuration,...
                "AverageTrial",app.AverageTrialCheckBox.Value,...
                "CombineFP",index,...
                "XMLatt",app.XMLatt,...
                "TrialUnits",tu,"ChangeCoordinates",T,"ClearUnlabeled",cl,"InertialProperties",app.UseinertialpropertiesCheckBox.Value,"Gender",app.Gender,"Mass_kg",mass);

            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Static Trial analyzed!']};
            app.LogTextArea.Value = app.LogRecord;


            app.Trial=trial;
            app.Configuration=app.Trial.ConfigFile;
            app.SaveConfigurationButton.Enable=true;
            app.refreshTables;
            app.SaveTrialButton.Enable=true;
            if ~isempty(app.Trial.ForcePlatform)
                app.CombineForcePlatesListBox.Items=[app.Trial.ForcePlatform.Label];
            end
            app.EditInertialPropertiesButton.Enable=1;
            app.AnalysisDone=true;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if contains(app.AddRemoveItemDropDown.Value,'Marker')

                props=string(fieldnames(app.Configuration.MarkerSet.(app.AddRemoveItemDropDown.Value)))';
                newatt=string(inputdlg(strrep(props,app.XMLatt,""),'Insert Desired Attributes'));

                if isempty(newatt) %no input
                    return
                end

                lab=newatt(props==("label"+app.XMLatt));

                item=[app.Configuration.MarkerSet.(app.AddRemoveItemDropDown.Value)];
                if sum(matches([item.("label"+app.XMLatt)],lab))>=1
                    error("%s labeled %s has already been defined!",(app.AddRemoveItemDropDown.Value),lab);
                end

                if not(matches(app.AddRemoveItemDropDown.Value,'Marker'))
                    mkr=[app.Configuration.MarkerSet.Marker];
                    if sum(matches([mkr.("label"+app.XMLatt)],lab))<1
                        error("All new Virtual/Calibrated Markers must be part of the MarkerSet prior to definition!")
                    end
                end
                % mkr=[app.Configuration.MarkerSet.Marker];
                % if sum(matches([mkr.("label"+app.XMLatt)],lab))>=1
                %     error("Marker labeled %s is already part of the Markerset!",lab);
                % end
                h=numel(app.Configuration.MarkerSet.(app.AddRemoveItemDropDown.Value));


                if not(matches(app.AddRemoveItemDropDown.Value,'Marker'))
                    if sum(matches([mkr.("label"+app.XMLatt)],lab))<1
                        error("All new Virtual/Calibrated Markers must be part of the MarkerSet prior to definition!")
                    elseif sum(matches([mkr.("label"+app.XMLatt)],lab))>1
                        error("Virtual/Calibrated Marker labeled %s has already been defined!",lab);
                    end
                end

                for i=1:numel(props)
                    app.Configuration.MarkerSet.(app.AddRemoveItemDropDown.Value)(h+1).(props(i))=newatt(i);
                end

            else %Kinematic model group
                props=string(fieldnames(app.Configuration.KinematicModel.(app.AddRemoveItemDropDown.Value)))';
                h=numel(app.Configuration.KinematicModel.(app.AddRemoveItemDropDown.Value));
                newatt=string(inputdlg(strrep(props,app.XMLatt,""),'Insert Desired Attributes'));

                if isempty(newatt)
                    return
                end
                lab=newatt(props==("label"+app.XMLatt));
                item=[app.Configuration.KinematicModel.(app.AddRemoveItemDropDown.Value)];
                if sum(matches([item.("label"+app.XMLatt)],lab))==1
                    error("%s labeled %s is already part of the Kinematic Model!",app.AddRemoveItemDropDown.Value,lab);
                end

                for i=1:numel(props)
                    app.Configuration.KinematicModel.(app.AddRemoveItemDropDown.Value)(h+1).(props(i))=newatt(i);
                end
            end

            app.refreshTables;
            %app.refreshConfig;

        end

        % Value changed function: AddRemoveItemDropDown
        function AddRemoveItemDropDownValueChanged(app, event)
            value = app.AddRemoveItemDropDown.Value;
            app.TabGroup.SelectedTab=app.(value+"Tab");
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            r=listdlg('ListString',app.TabGroup.SelectedTab.Children.Data.("label"+app.XMLatt),'SelectionMode','multiple','Name','Select Items to delete');

            if app.TabGroup.SelectedTab==app.MarkerTab
                ml=app.TabGroup.SelectedTab.Children.Data.("label"+app.XMLatt)(r);
                if not(isempty(app.VirtualMarkerTable.Data))
                    vml=app.VirtualMarkerTable.Data.("label"+app.XMLatt);
                    app.VirtualMarkerTable.Data(matches(vml,ml),:)=[];
                end
                if not(isempty(app.CalMarkerTable.Data))
                    cml=app.CalMarkerTable.Data.("label"+app.XMLatt);
                    app.CalMarkerTable.Data(matches(cml,ml),:)=[];
                end
            end
            app.TabGroup.SelectedTab.Children.Data(r,:)=[];saveConfiguration
            app.refreshConfig;
            %app.refreshTables;
        end

        % Button pushed function: SaveTrialButton
        function SaveTrialButtonPushed(app, event)
            %app.ApplyChangesButtonPushed;
            [p,f,e]=fileparts(app.TrialFile);
            [f,p]=uiputfile([app.TrialFile],'Save Trial');
            stat=app.Trial;

            try
                save(fullfile(p,f(1:end-4)),'stat');
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Analyzed file exported.']};
                app.LogTextArea.Value = app.LogRecord;
            catch ME
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', ME.message]};
                app.LogTextArea.Value = app.LogRecord;
            end

            app.Trial.writeC3D(fullfile(p,f),'newfile');
            if app.OpenafterexportCheckBox.Value
                winopen(fullfile(p,f));
            end
            app.SaveConfigurationButton.Enable=true;
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            try
                app.Trial.saveConfiguration;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Configuration file updated and saved.']};
                app.LogTextArea.Value = app.LogRecord;
            catch
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - It was not possible to save the updated Config file.']};
                app.LogTextArea.Value = app.LogRecord;
            end
        end

        % Value changed function: CombineForcePlatesCheckBox, 
        % ...and 1 other component
        function CombineForcePlatesCheckBoxValueChanged(app, event)

            if app.CombineForcePlatesCheckBox.Value
                app.CombineForcePlatesListBox.Value=app.CombineForcePlatesListBox.Items;
            else
                app.CombineForcePlatesListBox.Value={};
            end
        end

        % Cell edit callback: VirtualMarkerTable
        function VirtualMarkerTableCellEdit(app, event)
            app.refreshConfig;
        end

        % Cell edit callback: CalMarkerTable
        function CalMarkerTableCellEdit(app, event)
            app.refreshConfig;
        end

        % Cell edit callback: SegmentTable
        function SegmentTableCellEdit(app, event)
            app.refreshConfig;
        end

        % Cell edit callback: JointTable
        function JointTableCellEdit(app, event)
            app.refreshConfig;
        end

        % Cell edit callback: KinematicChainTable
        function KinematicChainTableCellEdit(app, event)
            app.refreshConfig;
        end

        % Button pushed function: EditInertialPropertiesButton
        function EditInertialPropertiesButtonPushed(app, event)
            seglist=app.SegmentTable.Data.("label"+app.XMLatt);
            seg=listdlg("ListString",seglist,"Name","Select segment to edit:");
            group=app.SegmentTable.Data.("group"+app.XMLatt)(seg);
            label=app.SegmentTable.Data.("comlabel"+app.XMLatt)(seg);
            seg=app.SegmentTable.Data.("label"+app.XMLatt)(seg);
            cm=app.Trial.Segments.(group).(seg).COM;
            try
                cm=point2local(cm,app.Trial.Segments.(group).(seg).TransformMat);
            catch
                cm=[0 0 0];
            end
            Icm=app.Trial.Segments.(group).(seg).Icm;
            m=app.Trial.Segments.(group).(seg).Mass;

            %app.ProOlympiaStaticFileCreatorUIFigure
            t = timer('StartDelay', 0.15, 'TimerFcn', @(~,~) resizeEditDialog());
            start(t);
            i=inputdlg(["mass (kg)";sprintf("local COM coordinates (%s)",app.Trial.Metadata.POINT.UNITS{:});"I_{CM} (kgm^2)"], ...
                "Edit:",[3 40],{num2str(m),num2str(cm,"%0.4f,%0.4f,%0.4f"),num2str(Icm,"%0.4f,%0.4f,%0.4f;%0.4f,%0.4f,%0.4f;%0.4f,%0.4f,%0.4f")});
            delete(t);
            function resizeEditDialog()
                h = findall(0,'Type','figure','Name','Edit:');
                if isempty(h)
                    return
                end
                scaleFactor = 2; % stesso fattore che usavi prima
                oldPos = h.Position;
                newW = oldPos(3) * scaleFactor;
                newH = oldPos(4) * scaleFactor;
                centerX = oldPos(1) + oldPos(3)/2;
                centerY = oldPos(2) + oldPos(4)/2;
                h.Position = [centerX-newW/2, centerY-newH/2, newW, newH];
                children = findall(h,'-property','Position');
                children = children(children ~= h);
                for k = 1:numel(children)
                    c = children(k);
                    p = c.Position;
                    c.Position = p .* scaleFactor;
                end
                allControls = findall(h,'-property','FontSize');
                set(allControls,'FontSize',12);
            end

            %i=inputdlg(["mass (kg)";sprintf("local COM coordinates (%s)",app.Trial.Metadata.POINT.UNITS{:});"I_{CM} (kgm^2)"],"Edit:",[3 40],{num2str(m),num2str(cm,"%0.4f,%0.4f,%0.4f"),num2str(Icm,"%0.4f,%0.4f,%0.4f;%0.4f,%0.4f,%0.4f;%0.4f,%0.4f,%0.4f")});

            m=str2double(i{1});
            mustBePositive(m);
            cm=str2num(i{2});
            Icm=str2num(i{3});

            app.Trial.Segments.(group).(seg).Icm=Icm;
            app.Trial.Segments.(group).(seg).Mass=m;
            cm=[cm 1];
            T=app.Trial.Segments.(group).(seg).TransformMat;
            CM=(T*cm')';
            CM=CM(:,1:3);
            tu=app.Trial.Points(1).Units;
            p=app.Trial.Points.appendPoint(label,CM,tu,seg,"V",0,group);
            app.Trial.Points(end+1)=p;
            app.Trial.Segments.(group).(seg).COM=p;
            app.Trial.Segments.(group).(seg).Points(end+1)=p;
            app.Trial.Segments.(group).(seg).Icm=Icm;
            app.Trial.Metadata.SEGMENTS_MASS.(seg)=m;
            app.Trial.Metadata.SEGMENTS_ICM.(seg)=Icm;
            app.Trial.setC3DMetaData;
            app.Configuration=app.Trial.ConfigFile;
            app.refreshTables;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogRecord = cell(0);
            app.LogTextArea.Value = cell(0,1);
        end

        % Button pushed function: ExportlogButton
        function ExportlogButtonPushed(app, event)
            if isempty(app.MainPathEditField)
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No folder selected!']};
                app.LogTextArea.Value = app.LogRecord;
            else
                fid = fopen(fullfile(app.MainPathEditField.Value, ['log_export_', char(datetime('now','Format','yyyy_MM_dd_HH_mm_ss')), '.txt']), 'w');
                for i = 1:length(app.LogTextArea.Value)
                    fprintf(fid, '%s\n', app.LogTextArea.Value{i});
                end
                fclose(fid);
            end
        end

        % Value changed function: GenderSwitch
        function GenderSwitchValueChanged(app, event)
            value = app.GenderSwitch.Value;
            if value=="M"
                app.Gender="Male";
            else
                app.Gender="Female";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ProOlympiaStaticFileCreatorUIFigure = uifigure('Visible', 'off');
            app.ProOlympiaStaticFileCreatorUIFigure.Name = 'ProOlympia Static File Creator';
            app.OpenconfigurationfileButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.OpenconfigurationfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenconfigurationfileButtonPushed, true);
            app.OpenconfigurationfileButton.Tooltip = {'Open an XML configuration file built with the Configurator'};
            app.OpenconfigurationfileButton.Text = 'Open configuration file';
            app.OpenStaticTrialButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.OpenStaticTrialButton.ButtonPushedFcn = createCallbackFcn(app, @OpenStaticTrialButtonPushed, true);
            app.OpenStaticTrialButton.Enable = 'off';
            app.OpenStaticTrialButton.Tooltip = {'Open the C3D file of the static trial which will be processed using the loaded configuration'};
            app.OpenStaticTrialButton.Text = 'Open Static Trial';
            app.TabGroup = uitabgroup(app.ProOlympiaStaticFileCreatorUIFigure);
            app.MarkerTab = uitab(app.TabGroup);
            app.MarkerTab.Title = 'Marker';
            app.MarkerTable = uitable(app.MarkerTab);
            app.MarkerTable.ColumnName = '';
            app.MarkerTable.ColumnRearrangeable = 'on';
            app.MarkerTable.RowName = {};
            app.MarkerTable.ColumnEditable = true;
            app.VirtualMarkerDefTab = uitab(app.TabGroup);
            app.VirtualMarkerDefTab.Title = 'VirtualMarkerDef';
            app.VirtualMarkerTable = uitable(app.VirtualMarkerDefTab);
            app.VirtualMarkerTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.VirtualMarkerTable.ColumnRearrangeable = 'on';
            app.VirtualMarkerTable.RowName = {};
            app.VirtualMarkerTable.ColumnEditable = true;
            app.VirtualMarkerTable.CellEditCallback = createCallbackFcn(app, @VirtualMarkerTableCellEdit, true);
            app.CalibratedMarkerDefTab = uitab(app.TabGroup);
            app.CalibratedMarkerDefTab.Title = 'CalibratedMarkerDef';
            app.CalMarkerTable = uitable(app.CalibratedMarkerDefTab);
            app.CalMarkerTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CalMarkerTable.ColumnRearrangeable = 'on';
            app.CalMarkerTable.RowName = {};
            app.CalMarkerTable.ColumnEditable = true;
            app.CalMarkerTable.CellEditCallback = createCallbackFcn(app, @CalMarkerTableCellEdit, true);
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.SegmentTable = uitable(app.SegmentTab);
            app.SegmentTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SegmentTable.ColumnRearrangeable = 'on';
            app.SegmentTable.RowName = {};
            app.SegmentTable.ColumnEditable = true;
            app.SegmentTable.CellEditCallback = createCallbackFcn(app, @SegmentTableCellEdit, true);
            app.JointTab = uitab(app.TabGroup);
            app.JointTab.Title = 'Joint';
            app.JointTable = uitable(app.JointTab);
            app.JointTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.JointTable.ColumnRearrangeable = 'on';
            app.JointTable.RowName = {};
            app.JointTable.ColumnEditable = true;
            app.JointTable.CellEditCallback = createCallbackFcn(app, @JointTableCellEdit, true);
            app.KinematicChainTab = uitab(app.TabGroup);
            app.KinematicChainTab.Title = 'KinematicChain';
            app.KinematicChainTable = uitable(app.KinematicChainTab);
            app.KinematicChainTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.KinematicChainTable.ColumnRearrangeable = 'on';
            app.KinematicChainTable.RowName = {};
            app.KinematicChainTable.ColumnEditable = true;
            app.KinematicChainTable.CellEditCallback = createCallbackFcn(app, @KinematicChainTableCellEdit, true);
            app.AddButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tooltip = {'Add an instance of the selected item to the configuration'};
            app.AddButton.Text = '+';
            app.RemoveButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tooltip = {'Remove one or more items of the selected type from the loaded configuration'};
            app.RemoveButton.Text = '-';
            app.AddRemoveItemDropDownLabel = uilabel(app.ProOlympiaStaticFileCreatorUIFigure);
            app.AddRemoveItemDropDownLabel.Text = 'Add/Remove Item';
            app.AddRemoveItemDropDown = uidropdown(app.ProOlympiaStaticFileCreatorUIFigure);
            app.AddRemoveItemDropDown.Items = {'Marker', 'CalibratedMarkerDef', 'VirtualMarkerDef', 'Segment', 'Joint', 'KinematicChain'};
            app.AddRemoveItemDropDown.ValueChangedFcn = createCallbackFcn(app, @AddRemoveItemDropDownValueChanged, true);
            app.AddRemoveItemDropDown.Value = 'Marker';
            app.CONFIGURATIONFILEEXPLOREREDITORLabel = uilabel(app.ProOlympiaStaticFileCreatorUIFigure);
            app.CONFIGURATIONFILEEXPLOREREDITORLabel.Tooltip = {'This window contains the informations present in the configuration file and provides a tool for editing the contents of the configuration file'};
            app.CONFIGURATIONFILEEXPLOREREDITORLabel.Text = 'CONFIGURATION FILE EXPLORER/EDITOR';
            app.UNIPD = uiimage(app.ProOlympiaStaticFileCreatorUIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.ProOlympiaStaticFileCreatorUIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.StaticFileConfiguratorLabel = uilabel(app.ProOlympiaStaticFileCreatorUIFigure);
            app.StaticFileConfiguratorLabel.Text = 'Static Trial Analyzer';
            app.INAIL = uiimage(app.ProOlympiaStaticFileCreatorUIFigure);
            app.INAIL.ImageSource = fullfile(pathToMLAPP, 'inail.png');
            app.UNIPD_2 = uiimage(app.ProOlympiaStaticFileCreatorUIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL_2 = uiimage(app.ProOlympiaStaticFileCreatorUIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.EditInertialPropertiesButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.EditInertialPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @EditInertialPropertiesButtonPushed, true);
            app.EditInertialPropertiesButton.Enable = 'off';
            app.EditInertialPropertiesButton.Tooltip = {'Allow to edit the Inertial properties of a specific segment.'; 'This must be done after "Apply Changes" button is pressed'};
            app.EditInertialPropertiesButton.Text = 'Edit Inertial Properties';
            app.TabGroup2 = uitabgroup(app.ProOlympiaStaticFileCreatorUIFigure);
            app.OPTIONSTab = uitab(app.TabGroup2);
            app.OPTIONSTab.Title = 'OPTIONS';
            app.PROCESSINGOPTIONSPanel = uipanel(app.OPTIONSTab);
            app.PROCESSINGOPTIONSPanel.Title = 'PROCESSING OPTIONS';
            app.ClearUnlabeledTracksCheckBox = uicheckbox(app.PROCESSINGOPTIONSPanel);
            app.ClearUnlabeledTracksCheckBox.Tooltip = {'Removes any unlabeled track from the trial, unlabeled track are usually identified by a C_ prefix'};
            app.ClearUnlabeledTracksCheckBox.Text = 'Clear Unlabeled Tracks';
            app.ClearUnlabeledTracksCheckBox.Value = true;
            app.AverageTrialCheckBox = uicheckbox(app.PROCESSINGOPTIONSPanel);
            app.AverageTrialCheckBox.Tooltip = {'Apply the mean function to the point and force platform data, making it suitable for SVD algorithm in the dynamic processing. If the C3D file can''t be averaged, please consider to trim to a single frame before importing to this application.'};
            app.AverageTrialCheckBox.Text = 'Average Trial';
            app.AverageTrialCheckBox.Value = true;
            app.EditField = uieditfield(app.PROCESSINGOPTIONSPanel, 'text');
            app.EditField.Value = 'C_';
            app.TargetGroundDropDown = uidropdown(app.PROCESSINGOPTIONSPanel);
            app.TargetGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.TargetGroundDropDown.Value = 'Z-UP, X-LAT';
            app.CombineForcePlatesLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.CombineForcePlatesLabel.Tooltip = {'Combine the selected forceplates in the trial to a single FP.  If you want to group similar plaforms into subgroups, please repeat the process for each subgroup'};
            app.CombineForcePlatesLabel.Text = 'Combine Force Plates';
            app.rightarrowLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.rightarrowLabel.Interpreter = 'tex';
            app.rightarrowLabel.Text = '\rightarrow';
            app.WarningLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Tooltip = {'Point STD is higher than 10%, consider to trim the trial prior to processing'};
            app.WarningLabel.Text = 'Warning!';
            app.TrialUnitsSwitchLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.TrialUnitsSwitchLabel.Enable = 'off';
            app.TrialUnitsSwitchLabel.Visible = 'off';
            app.TrialUnitsSwitchLabel.Text = 'Trial Units';
            app.TrialUnitsSwitch = uiswitch(app.PROCESSINGOPTIONSPanel, 'slider');
            app.TrialUnitsSwitch.Items = {'N, kg, m, s', 'N, kg, mm, s'};
            app.TrialUnitsSwitch.Enable = 'off';
            app.TrialUnitsSwitch.Visible = 'off';
            app.TrialUnitsSwitch.Tooltip = {'Switch the trial Units'};
            app.TrialUnitsSwitch.Value = 'N, kg, mm, s';
            app.CombineForcePlatesCheckBox = uicheckbox(app.PROCESSINGOPTIONSPanel);
            app.CombineForcePlatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CombineForcePlatesCheckBoxValueChanged, true);
            app.CombineForcePlatesCheckBox.Tooltip = {''};
            app.CombineForcePlatesCheckBox.Text = '';
            app.CombineForcePlatesListBox = uilistbox(app.PROCESSINGOPTIONSPanel);
            app.CombineForcePlatesListBox.Items = {};
            app.CombineForcePlatesListBox.Multiselect = 'on';
            app.CombineForcePlatesListBox.ValueChangedFcn = createCallbackFcn(app, @CombineForcePlatesCheckBoxValueChanged, true);
            app.CombineForcePlatesListBox.Tooltip = {''};
            app.CombineForcePlatesListBox.Value = {};
            app.RotateGroundDropDownLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.RotateGroundDropDownLabel.Text = 'Rotate Ground';
            app.FileGroundDropDown = uidropdown(app.PROCESSINGOPTIONSPanel);
            app.FileGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.FileGroundDropDown.Tooltip = {'Rotate the coordinates of the acquisition to a different orientation'};
            app.FileGroundDropDown.Value = 'Z-UP, X-LAT';
            app.SubjectMasskgEditFieldLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.SubjectMasskgEditFieldLabel.Tooltip = {'Subject mass that will be stored in the trial. The initial value is computed using data from force plates'};
            app.SubjectMasskgEditFieldLabel.Text = 'Subject Mass (kg)';
            app.SubjectMasskgEditField = uieditfield(app.PROCESSINGOPTIONSPanel, 'numeric');
            app.SubjectMasskgEditField.LowerLimitInclusive = 'off';
            app.SubjectMasskgEditField.UpperLimitInclusive = 'off';
            app.SubjectMasskgEditField.Limits = [0 Inf];
            app.SubjectMasskgEditField.Tooltip = {''};
            app.SubjectMasskgEditField.Value = 1;
            app.ApplyChangesButton = uibutton(app.PROCESSINGOPTIONSPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Enable = 'off';
            app.ApplyChangesButton.Tooltip = {'Apply the configuration and the processing options to the loaded Trial'};
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.UseinertialpropertiesCheckBox = uicheckbox(app.PROCESSINGOPTIONSPanel);
            app.UseinertialpropertiesCheckBox.Tooltip = {'Apply the mean function to the point and force platform data, making it suitable for SVD algorithm in the dynamic processing. If the C3D file can''t be averaged, please consider to trim to a single frame before importing to this application.'};
            app.UseinertialpropertiesCheckBox.Text = 'Use inertial properties';
            app.UseinertialpropertiesCheckBox.Value = true;
            app.GenderSwitchLabel = uilabel(app.PROCESSINGOPTIONSPanel);
            app.GenderSwitchLabel.Text = 'Gender';
            app.GenderSwitch = uiswitch(app.PROCESSINGOPTIONSPanel, 'slider');
            app.GenderSwitch.Items = {'M', 'F'};
            app.GenderSwitch.ValueChangedFcn = createCallbackFcn(app, @GenderSwitchValueChanged, true);
            app.GenderSwitch.Value = 'M';
            app.SAVINGOPTIONSPanel = uipanel(app.OPTIONSTab);
            app.SAVINGOPTIONSPanel.Title = 'SAVING OPTIONS';
            app.SaveTrialButton = uibutton(app.SAVINGOPTIONSPanel, 'push');
            app.SaveTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrialButtonPushed, true);
            app.SaveTrialButton.Enable = 'off';
            app.SaveTrialButton.Tooltip = {'Save the trial to the selected formats'};
            app.SaveTrialButton.Text = 'Save Trial';
            app.C3DCheckBox = uicheckbox(app.SAVINGOPTIONSPanel);
            app.C3DCheckBox.Text = '.C3D';
            app.C3DCheckBox.Value = true;
            app.matCheckBox = uicheckbox(app.SAVINGOPTIONSPanel);
            app.matCheckBox.Text = '.mat';
            app.matCheckBox.Value = true;
            app.OpenafterexportCheckBox = uicheckbox(app.SAVINGOPTIONSPanel);
            app.OpenafterexportCheckBox.Tooltip = {'Select to open the trial in the default window application after exporting'};
            app.OpenafterexportCheckBox.Text = 'Open after export';
            app.SaveConfigurationButton = uibutton(app.SAVINGOPTIONSPanel, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Enable = 'off';
            app.SaveConfigurationButton.Tooltip = {'Save the modified configuration'};
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.LogTab = uitab(app.TabGroup2);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            app.ClearlogButton = uibutton(app.LogTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ExportlogButton = uibutton(app.LogTab, 'push');
            app.ExportlogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlogButtonPushed, true);
            app.ExportlogButton.Text = 'Export log';
            app.CloseButton = uibutton(app.ProOlympiaStaticFileCreatorUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Save the workspace trials to the selected destinations'};
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ProOlympiaStaticFileCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StaticTrialConstructor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProOlympiaStaticFileCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProOlympiaStaticFileCreatorUIFigure)
        end
    end
end