classdef TrialAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        SaveTool                       matlab.ui.container.toolbar.PushTool
        CloseTool                      matlab.ui.container.toolbar.PushTool
        SaveButton                     matlab.ui.control.Button
        ExportWorkspace                matlab.ui.control.Button
        LoadWorkspace                  matlab.ui.control.Button
        LogpanelPanel                  matlab.ui.container.Panel
        IdleLamp                       matlab.ui.control.Lamp
        IdleLampLabel                  matlab.ui.control.Label
        ExportlogButton                matlab.ui.control.Button
        ClearlogButton                 matlab.ui.control.Button
        LogTextArea                    matlab.ui.control.TextArea
        SAVEOPTIONSPanel               matlab.ui.container.Panel
        SaveSettingsasdefaultButton    matlab.ui.control.Button
        KeepOriginalCheckBox           matlab.ui.control.CheckBox
        DBfileEditField                matlab.ui.control.EditField
        relativepathCheckBox           matlab.ui.control.CheckBox
        BrowseButton                   matlab.ui.control.Button
        addtoDBCheckBox                matlab.ui.control.CheckBox
        KeepOriginalEditField          matlab.ui.control.EditField
        matCheckBox                    matlab.ui.control.CheckBox
        OpenC3DafterexportCheckBox     matlab.ui.control.CheckBox
        combinedmatCheckBox            matlab.ui.control.CheckBox
        C3DCheckBox                    matlab.ui.control.CheckBox
        KINEMATICSPanel                matlab.ui.container.Panel
        ClearUnlabeledEditField        matlab.ui.control.EditField
        ClearUnlabeledTracksCheckBox   matlab.ui.control.CheckBox
        ApplySVDoptimizationCheckBox   matlab.ui.control.CheckBox
        KinematicCutOffHzEditField     matlab.ui.control.NumericEditField
        CutOffHzEditFieldLabel         matlab.ui.control.Label
        KinematicOrderEditField        matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        FilterKinematicsCheckBox       matlab.ui.control.CheckBox
        FORCEPLATEPanel                matlab.ui.container.Panel
        AdvancedoptionsCheckBox        matlab.ui.control.CheckBox
        CrossTalkRemovalCheckBox       matlab.ui.control.CheckBox
        ReflectSignalforfilteringCheckBox  matlab.ui.control.CheckBox
        CombineForcePlatesLabel        matlab.ui.control.Label
        CombineForcePlatesCheckBox     matlab.ui.control.CheckBox
        EventThresholdEditField        matlab.ui.control.NumericEditField
        ThresholdNEditField_2Label     matlab.ui.control.Label
        DetectEventsCheckBox           matlab.ui.control.CheckBox
        ValidThresholdNEditField       matlab.ui.control.NumericEditField
        ValidThresholdNLabel           matlab.ui.control.Label
        MaxContactsEditField           matlab.ui.control.NumericEditField
        MaxContactsEditFieldLabel      matlab.ui.control.Label
        MaxDurationmsEditField         matlab.ui.control.NumericEditField
        MaxDurationmsEditFieldLabel    matlab.ui.control.Label
        ForceCutOffHzEditField         matlab.ui.control.NumericEditField
        CutOffHzEditField_2Label       matlab.ui.control.Label
        ForceOrderEditField            matlab.ui.control.NumericEditField
        OrderEditField_2Label          matlab.ui.control.Label
        FilterForcePlatesCheckBox      matlab.ui.control.CheckBox
        TASKSELECTIONButtonGroup       matlab.ui.container.ButtonGroup
        GeneralButton                  matlab.ui.control.ToggleButton
        LongjumpingButton              matlab.ui.control.ToggleButton
        RunningButton                  matlab.ui.control.ToggleButton
        WalkingButton                  matlab.ui.control.ToggleButton
        GENERALPanel                   matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        UseInertialPropertiesCheckBox  matlab.ui.control.CheckBox
        rightarrowLabel                matlab.ui.control.Label
        FileGroundDropDown             matlab.ui.control.DropDown
        AbsoluteanglescorrectionLabel  matlab.ui.control.Label
        TargetGroundDropDown           matlab.ui.control.DropDown
        MainPathEditField              matlab.ui.control.EditField
        MainsubjectfolderLabel         matlab.ui.control.Label
        INAIL_2                        matlab.ui.control.Image
        UNIPD_2                        matlab.ui.control.Image
        INAIL                          matlab.ui.control.Image
        TrialAnalyzerLabel             matlab.ui.control.Label
        OLYMPIA                        matlab.ui.control.Image
        UNIPD                          matlab.ui.control.Image
        RemovefromWorkspaceButton      matlab.ui.control.Button
        RunAnalysisButton              matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        AddTrialtoWorkspaceButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        rootdir=cd; % Description
        filedir string;
        mainroot
        TrialGroup
        %DatabaseFile;
        LogRecord = cell(0);
        lampon = [0.29, 0.86, 0.25] % Green lamp light (on status)
        lampoff = [0.80, 0.80, 0.80]; % Gray lamp light (off)
        lamperr = [0.86, 0.01, 0.01]; % Red lamp light (error)
        lampwarn = [0.93,0.69,0.13]; % Orange lamp light (warning)
        report_config; % Description
        attribute = "_att"; % Description
    end
    % properties (Access = private, Dependent)
    %     Settings
    % end
    
    methods (Access = private)
        
        function SetReportConfig(app, path)
            src = fullfile(char(java.io.File(fullfile(fileparts(mfilename('fullpath')), '..')).getCanonicalPath()), 'Templates', 'report_configuration.xml');
            dst = fullfile(path,'report_configuration.xml');

            dst = app.dstPathUpdate(dst);

            if isfile(src) && ~isfile(dst)
                copyfile(src, dst);
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Report config template at: ', dst]};
                app.LogTextArea.Value = app.LogRecord;
            elseif and(isfile(src), isfile(dst))
                app.IdleLamp.Color = app.lampwarn;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: Report config file already exists in the selected folder!']};
                app.LogTextArea.Value = app.LogRecord;
                pause(0.3)
            elseif ~isfile(src)
                app.IdleLamp.Color = app.lampwarn;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: Source report config file does not exists!']};
                app.LogTextArea.Value = app.LogRecord;
                pause(0.3)
            else
                app.IdleLamp.Color = app.lamperr;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ERROR: Something went wrong in accessing the Report config file!']};
                app.LogTextArea.Value = app.LogRecord;
                pause(0.3)
            end

            app.DBfileEditField.Value = dst;
        end
        
        function dst_new = dstPathUpdate(app, dst)
            choice = questdlg( ...
                ['The Report configuration file will be stored at:' newline dst newline newline 'Is this correct?'], ...
                'Confirm path', ...
                'Yes','Modify','Cancel','Yes');

            switch choice
                case 'Yes'
                    dst_new = dst;
                case 'Modify'
                    newPath = uigetdir(dst,'Select the path to store the Report Config file.');
                    if newPath ~= 0
                        dst_new = fullfile(newPath, "report_configuration.xml");
                    end
                case 'Cancel'
                    dst_new = "";
                    app.IdleLamp.Color = app.lampwarn;
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No path has been selected. The Report Config file will not be saved.']};
                    app.LogTextArea.Value = app.LogRecord;                    
            end
        end
        
        function settingstruct=getSettings(app)
            settings=fieldnames(app);
            for s=string(settings)'
                app.(s)
                if isprop(app.(s),"Value")
                settingstruct.(s)=app.(s).Value;
                end
            end
        end
        
        function setSettings(app,settings)
                for s=string(fieldnames(settings))'
                    if isstring(settings.(s))
                        if settings.(s)=="true"
                            settings.(s)=true;
                        elseif settings.(s)=="false"
                            settings.(s)=false;
                        end
                    end
                    app.(s).Value=settings.(s);
                end
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainroot, task)
           arguments
                app;
                mainroot (1,1) string = cd;
                % DEPRECATED in version 2.1 (20-Jan-2026): only XML format
                % files will be handled.
                %xmlchoice (1,1) logical = 1;
                %jsonchoice (1,1) logical = 0;
                task (1,1) string = "walking";
            end

            try
                app.mainroot = mainroot;
                app.MainPathEditField.Value = mainroot;
                %app.DatabaseFile=fullfile(mainroot,'ProcessedTrialList.xlsx');
            catch
                msg = msgbox("Please select or create a Subject folder to save the config file!", "No subject configuration file was found", "warn", "modal");
                uiwait(msg);
            end 
            %app.XML = xmlchoice;
            %app.JSON = jsonchoice;
            for i = 1: length(app.TASKSELECTIONButtonGroup.Buttons)
                if strcmpi(app.TASKSELECTIONButtonGroup.Buttons(i).Text, task)
                    app.TASKSELECTIONButtonGroup.Buttons(i).Value = 1;
                else
                    app.TASKSELECTIONButtonGroup.Buttons(i).Value = 0;
                end
            end
            app.CombineForcePlatesCheckBox.Value = 0;
            app.MaxDurationmsEditField.Value = 500;
            app.ForceCutOffHzEditField.Value = 15;
            app.CombineForcePlatesCheckBox.Enable = "off";
            app.CombineForcePlatesLabel.Enable = "off";
            app.EventThresholdEditField.Enable = "off";
            app.DetectEventsCheckBox.Enable = "off";
            app.ThresholdNEditField_2Label.Enable = "off";
            app.EventThresholdEditField.Enable = "off";
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Trial Analyzer app ready.']};
            app.LogTextArea.Value = app.LogRecord; 

           
            mfilePath = fileparts(mfilename('fullpath'));
            file=fullfile(mfilePath,"default_settings.xml");
            if isfile(file)
                s=readstruct(file,"FileType","xml");
                app.setSettings(s.Settings);
            end

        end

        % Button pushed function: AddTrialtoWorkspaceButton
        function AddTrialtoWorkspaceButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)
            
            if isempty(app.filedir)
                [f,p]=uigetfile(fullfile(app.rootdir,'*.c3d;*.mat;*.xml'),'Select Static Trial and configuration file','MultiSelect','on');
                %[f,p]=uigetfile(fullfile(app.rootdir,'*.c3d;*.mat;*.xml'),'Select Static Trial and configuration file','MultiSelect','on');
            else
                [f,p]=uigetfile(fullfile(app.filedir(end),'*.c3d;*.mat;*.xml'),'Select Static Trial and configuration file','MultiSelect','on');
                %[f,p]=uigetfile(fullfile(app.filedir(end),'*.c3d;*.mat;*.xml'),'Select Static Trial and configuration file','MultiSelect','on');
            end
            f=string(f);
            if f==""
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No Trial and Configuration files selected.']};
                app.LogTextArea.Value = app.LogRecord;
                return
            end
            conf=f(contains(f,'.xml'));
            %conf=f(contains(f,'.xml'));
            stat=f(contains(f,'.c3d'));
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Selected Configuration: ', char(p + conf)]};
            app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Selected Static Trial: ', char(p + stat)]};
            app.LogTextArea.Value = app.LogRecord;
            % app.filedir(end+1)=string(p);
            [f]=uigetfile(fullfile(p,'*.c3d'),'Select Dynamic Trial(s)','MultiSelect','on');
            f=string(f);
            if f==""
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No dyamic trial selected.']};
                app.LogTextArea.Value = app.LogRecord;
                return
            end
            for i=1:length(f)
                app.filedir(end+1)=string(p);
                t{i,1}="";
                t{i,2}=strrep(f(i),".c3d","");
                t{i,3}=strrep(stat,".c3d","");
                t{i,4}=strrep(conf,".xml","");
                %t{i,4}=strrep(conf,".xml","");


                code=regexp(f(i),"(\w+)(\d\d)_S(\d\d)_T(\d\d)_",'tokens');
                code=cat(1,code{:});
                if isempty(code)
                    code(1)="";
                    code(2)="";
                end
                %code=cat(1,code{:});


                t{i,5}=code(1);
                t{i,6}=code(1)+code(2);
                t{i,7}="";
                t{i,8}=nan;
                t{i,9}="";
                t{i,10}=nan;
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Loaded dynamic trial: ', char(p + f(i))]};
                app.LogTextArea.Value = app.LogRecord; 
            end
            t=cell2table(t,"VariableNames",app.UITable.ColumnName);
            app.UITable.Data=[app.UITable.Data;t];
            app.SaveTool.Enable=false;
            app.SaveButton.Enable=false;
            app.addtoDBCheckBox.Enable=false;
            app.addtoDBCheckBox.Value=false;
            % end

            app.SetReportConfig(p);

            app.report_config = readstruct(app.DBfileEditField.Value, "FileType", "xml", "AttributeSuffix", app.attribute);

            app.IdleLamp.Color = app.lampoff;
            app.IdleLampLabel.Text = "Idle";
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)
            
            tr=app.UITable.Data;
            h=height(tr);
            for t=1:h
                app.UITable.Data.Status(t)="ANALYZING...";
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Analysis started for: ', char(app.UITable.Data.Trial(t))]};
                app.LogTextArea.Value = app.LogRecord;
                % try
                if contains(app.TargetGroundDropDown.Value,"Z-UP")
                    g=[0 0 -9.806];
                elseif contains(app.TargetGroundDropDown.Value,"Y-UP")
                    g=[0 -9.806 0];
                end
                % if contains(app.WorkingUnitsSwitch.Value,"kg")
                %     tu="m";
                % else
                %     tu="mm";
                % end
                
                if app.ClearUnlabeledTracksCheckBox.Value
                    cl=app.ClearUnlabeledEditField.Value;
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Clearing unlabel trajectories.']};
                    app.LogTextArea.Value = app.LogRecord;
                else
                    cl="";
                end
                if app.CombineForcePlatesCheckBox.Value
                    index='all';
                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Combining force plates.']};
                    app.LogTextArea.Value = app.LogRecord;
                else
                    index=[];
                end
                T2=dropDown2T(app.TargetGroundDropDown.Value);
                T1=dropDown2T(app.FileGroundDropDown.Value);
                T=T1/T2;

                switch app.Switch.Value
                    case "All Entities"
                        Tg=T;
                        Ta=eye(3);
                    case "Absolute Angles"
                        Tg=eye(4);
                        Ta=T(1:3,1:3);
                end


                if app.ApplySVDoptimizationCheckBox.Value
                    svdmode="replace";
                else
                    svdmode="ignore";
                end

                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Processing...']};
                app.LogTextArea.Value = app.LogRecord;

                config = readstruct(fullfile(app.filedir(t),tr.Configuration(t)+".xml"), "FileType", "xml", "AttributeSuffix", app.attribute);

                % Subject = config.subject;
                % 
                % Subject.Session(1) = config.session;
                % Subject.Session(1).("type" + app.attribute) = app.TASKSELECTIONButtonGroup.SelectedObject.Text; 
                % 
                % Subject.Session(1).Trial(t).("filename" + app.attribute) = tr.Trial(t);
                % Subject.Session(1).Trial(t).("staticreference" + app.attribute) = tr.Static(t);
                % Subject.Session(1).Trial(t).("error" + app.attribute) = "false";
                % 
                if isfield(config, "medical_device")
                    fields = fieldnames(config.medical_device);
                    for f=fields
                        Subject.Session(1).Trial(t).Parameter = extractParameters(config.medical_device, f{:});
                    end
                end

                clear config

                [dyn,stat]=DynamicTrialProcessor(fullfile(app.filedir(t),tr.Trial(t)+".c3d"),...
                    "StaticTrial",fullfile(app.filedir(t),tr.Static(t)+".c3d"),...
                    "Configuration",fullfile(app.filedir(t),tr.Configuration(t)+".xml"),... %"TrialUnits",tu, ...% "Configuration",fullfile(app.filedir(t),tr.Configuration(t)+".xml"),... %"TrialUnits",tu, ...
                    "RotateGround",Tg,...
                    "RotateSegmentAngles",Ta,...
                    "ClearUnlabeled",cl,...
                    "KinematicFilterOrder",app.KinematicOrderEditField.Value*app.FilterKinematicsCheckBox.Value,"KinematicFilterCutOff",app.KinematicCutOffHzEditField.Value,...
                    "FilterOrder",app.ForceOrderEditField.Value*app.FilterForcePlatesCheckBox.Value,"FilterCutOff",app.ForceCutOffHzEditField.Value,...
                    "ActiveThreshold",app.ValidThresholdNEditField.Value,"MaxNumContacts",app.MaxContactsEditField.Value,...
                    "MaxRadius",app.MaxDurationmsEditField.Value,...
                    "Reflect",app.ReflectSignalforfilteringCheckBox.Value,...
                    "CombineFP",index,"GravityVector",g,...
                    "SVDmode",svdmode,"EditEvents",app.DetectEventsCheckBox.Value,"EventTreshold",app.EventThresholdEditField.Value, "TaskSelector", lower(app.TASKSELECTIONButtonGroup.SelectedObject.Text), ...
                    "InertialProperties", app.UseInertialPropertiesCheckBox.Value);

                app.TrialGroup.Static=stat;
                app.TrialGroup.Dynamic(t)=dyn;
                app.UITable.Data.Status(t)="ANALYSIS SUCCEDED!";
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', char(app.UITable.Data.Trial(t)), ' Processed!']};
                app.LogTextArea.Value = app.LogRecord;
                app.SaveTool.Enable=true;
                app.SaveButton.Enable=true;
                app.addtoDBCheckBox.Enable=true;
                % catch ME
                %     app.UITable.Data.Status(t)="ERROR:" +ME.message;
                %     warning(ME.message);
                %
                % end
            end

            app.IdleLamp.Color = app.lampoff;
            app.IdleLampLabel.Text = "Idle";
        end

        % Callback function: SaveButton, SaveTool
        function SaveButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            path=app.rootdir;
            DBindex=false(height(app.UITable.Data.Trial),1);
            if app.C3DCheckBox.Value||app.matCheckBox.Value %if any is true user wants to save
                for i=1:length(app.TrialGroup) %each TrialGroup has 1 static and N associated dynamic trials
                    j=1;
                    for dyn=[app.TrialGroup(i).Dynamic]
                        %set default name
                        [path,name]=fileparts(dyn.c3dfile);
                        DBindex=DBindex|matches(app.UITable.Data.Trial,name);
                        def_name=[name,app.KeepOriginalEditField.Value];

                        %save the matfile
                        if app.matCheckBox.Value
                            [name,path]=uiputfile(fullfile(path,[name '.mat']));
                            if name==0 %no file selected
                                %skip saving
                            else
                                [~,name]=fileparts(name);
                                stat=app.TrialGroup(i).Static;
                                matfile(j)=string(fullfile(path,name));
                                save(matfile(j),'dyn','stat');
                                j=j+1;
                            end
                        end

                        if app.C3DCheckBox.Value
                            [name,path]=uiputfile(dyn.c3dfile);
                            if name==0 %no file selected
                                %skip saving
                            else
                                if app.KeepOriginalCheckBox.Value
                                    backup=dyn.c3dfile;
                                    if not(contains(backup,app.KeepOriginalEditField.Value))
                                        backup=string(backup(1:end-4))+string(app.KeepOriginalEditField.Value)+".c3d";
                                    end

                                    if not(isfile(backup))
                                        copyfile(dyn.c3dfile,backup);
                                    end
                                end
                                dyn.writeC3D(fullfile(path,name),'newfile');

                                if app.OpenC3DafterexportCheckBox.Value
                                    app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Opening the ', name(1:end-4), ' C3D file.']};
                                    app.LogTextArea.Value = app.LogRecord;
                                    try
                                        winopen(fullfile(path,name));
                                    catch
                                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Unable to open ', name(1:end-4), ' for preview!']};
                                        app.LogTextArea.Value = app.LogRecord;
                                        warning("Unable to open C3D file for preview!")
                                    end
                                end
                            end
                        end


                        app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - ', name(1:end-4), ' SAVED!']};
                        app.LogTextArea.Value = app.LogRecord;
                        app.UITable.Data.Status(DBindex)="SAVED!";

                    end
                end
            else
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - Either choose C3D or MAT file format to save the output.']};
                app.LogTextArea.Value = app.LogRecord;
            end
            if app.combinedmatCheckBox.Value
                TrialGroup=app.TrialGroup;
                uisave('TrialGroup',path);
            end

            % aggiungere parte in cui generi XML/JSON con parole
            % chiave (da salvare in ViconData):
            % - patient classification (TF/TT/...)
            % - allineamento
            % - nome piede
            % - categoria piede
            % a cui si devono associare la lista dei file statico e
            % dinamici utilizzati
            % <processing patient_class="" alignment="" foot="" cat="">
            %     <stat_file>vattelappesca.c3d</stat_file>
            %     <dyn_file>file1.c3d</dyn_file>
            %     <dyn_file>file2.c3d</dyn_file>
            %     <dyn_file>file3.c3d</dyn_file>
            % </processing>


            if app.addtoDBCheckBox.Value
                if app.DBfileEditField.Value==""
                    app.BrowseButton.ButtonPushedFcn;
                end
                if app.relativepathCheckBox.Value
                    path=fileparts(app.DBfileEditField.Value);
                else
                    path="";
                end

                T=[app.UITable.Data(DBindex,2:end),array2table((strrep(matfile(DBindex),path,"")+".mat")',"VariableNames","MATfile")];
                if isfile(app.DBfileEditField.Value)
                    writetable(T,app.DBfileEditField.Value,"WriteMode","append");
                end
            end

            app.IdleLamp.Color = app.lampoff;
            app.IdleLampLabel.Text = "Idle";
        end

        % Button pushed function: RemovefromWorkspaceButton
        function RemovefromWorkspaceButtonPushed(app, event)
            app.IdleLamp.Color = app.lampon;
            app.IdleLampLabel.Text = "Operating";
            pause(0.3)

            [~,indx]=listdlg("ListString",app.UITable.Data.Trial,"PromptString","Select Trials to remove","SelectionMode","multiple");
            app.UITable.Data(indx,:)=[];
            app.filedir(indx)=[];

            app.IdleLamp.Color = app.lampoff;
            app.IdleLampLabel.Text = "Idle";
        end

        % Value changed function: C3DCheckBox
        function C3DCheckBoxValueChanged(app, event)
            value = app.C3DCheckBox.Value;
            app.OpenC3DafterexportCheckBox.Enable=value;
            app.OpenC3DafterexportCheckBox.Value=false;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [f,p]=uigetfile(fullfile(app.mainroot,'*.xlsx'),"Select the DB file");
             if f==0
             [f,p]=uiputfile(fullfile(app.mainroot,'*.xlsx'),"Create DB fi");
             end
             app.DBfileEditField.Value=fullfile(p,f);
             %app.Label.Text=app.DatabaseFile;
        end

        % Value changed function: matCheckBox
        function matCheckBoxValueChanged(app, event)
            value = app.matCheckBox.Value;
            app.addtoDBCheckBox.Enable=value;
            app.addtoDBCheckBox.Value=false;
        end

        % Callback function: CloseTool
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: TASKSELECTIONButtonGroup
        function TASKSELECTIONButtonGroupSelectionChanged(app, event)
            %selectedButton = app.TASKSELECTIONButtonGroup.SelectedObject;
            switch lower(app.TASKSELECTIONButtonGroup.SelectedObject.Text)
                case "walking"
                    app.CombineForcePlatesCheckBox.Value = 0;
                    app.MaxDurationmsEditField.Value = 500;
                    app.ForceCutOffHzEditField.Value = 15;
                    app.CombineForcePlatesCheckBox.Enable = "off";
                    app.CombineForcePlatesLabel.Enable = "off";
                    app.EventThresholdEditField.Enable = "off";
                    app.DetectEventsCheckBox.Enable = "off";
                    app.ThresholdNEditField_2Label.Enable = "off";
                    app.EventThresholdEditField.Enable = "off";
                case "running"
                    app.CombineForcePlatesCheckBox.Value = 1;
                    app.MaxDurationmsEditField.Value = 100;
                    app.ForceCutOffHzEditField.Value = 100;
                    app.CombineForcePlatesCheckBox.Enable = "on";
                    app.CombineForcePlatesLabel.Enable = "on";
                    app.EventThresholdEditField.Enable = "on";
                    app.DetectEventsCheckBox.Enable = "on";
                    app.EventThresholdEditField.Enable = "on";
                    app.ThresholdNEditField_2Label.Enable = "on";
                case "general"
                    app.CombineForcePlatesCheckBox.Value = 0;
                    app.CombineForcePlatesCheckBox.Enable = "off";
                    app.CombineForcePlatesLabel.Enable = "off";
                    app.ForceCutOffHzEditField.Value = 15;
                    app.MaxDurationmsEditField.Value = 500;
                    app.EventThresholdEditField.Enable = "off";
                    app.DetectEventsCheckBox.Value = 0;
                    app.DetectEventsCheckBox.Enable = "off";
                    app.ThresholdNEditField_2Label.Enable = "off";
                    app.EventThresholdEditField.Enable = "off";
                    app.UseInertialPropertiesCheckBox.Value = 0;
                    app.FilterForcePlatesCheckBox.Value = 0;
                    app.CrossTalkRemovalCheckBox.Value = 0;
                    app.AdvancedoptionsCheckBox.Value = 0;
                    app.AdvancedoptionsCheckBox.Enable = "off";
            end
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogRecord = cell(0);
            app.LogTextArea.Value = cell(0,1);
        end

        % Button pushed function: ExportlogButton
        function ExportlogButtonPushed(app, event)
            if isempty(app.MainPathEditField)
                app.LogRecord(length(app.LogRecord)+1) = {[char(datetime('now')), ' - WARNING: No folder selected!']};
                app.LogTextArea.Value = app.LogRecord;
            else
                fid = fopen(fullfile(app.MainPathEditField.Value, ['log_export_', char(datetime('now','Format','yyyy_MM_dd_HH_mm_ss')), '.txt']), 'w');
                for i = 1:length(app.LogTextArea.Value)
                    fprintf(fid, '%s\n', app.LogTextArea.Value{i});
                end
                fclose(fid);
            end
        end

        % Value changed function: FilterForcePlatesCheckBox
        function FilterForcePlatesCheckBoxValueChanged(app, event)
            if app.FilterForcePlatesCheckBox.Value
                app.ForceOrderEditField.Enable = "on";
                app.ForceCutOffHzEditField.Enable = "on";
                app.ReflectSignalforfilteringCheckBox.Enable = "on";
            else
                app.ForceOrderEditField.Enable = "off";
                app.ForceCutOffHzEditField.Enable = "off";
                app.ReflectSignalforfilteringCheckBox.Enable = "off";
            end
        end

        % Value changed function: AdvancedoptionsCheckBox
        function AdvancedoptionsCheckBoxValueChanged(app, event)
            if app.AdvancedoptionsCheckBox.Value
                app.ValidThresholdNEditField.Enable = "on";
                app.ValidThresholdNLabel.Enable = "on";
                app.MaxContactsEditField.Enable = "on";
                app.MaxContactsEditFieldLabel.Enable = "on";
                app.MaxDurationmsEditField.Enable = "on";
                app.MaxDurationmsEditFieldLabel.Enable = "on";
            else
                app.ValidThresholdNEditField.Enable = "off";
                app.ValidThresholdNLabel.Enable = "off";
                app.MaxContactsEditField.Enable = "off";
                app.MaxContactsEditFieldLabel.Enable = "off";
                app.MaxDurationmsEditField.Enable = "off";
                app.MaxDurationmsEditFieldLabel.Enable = "off";
            end
        end

        % Value changed function: CrossTalkRemovalCheckBox
        function CrossTalkRemovalCheckBoxValueChanged(app, event)
            if app.CrossTalkRemovalCheckBox.Value
                app.AdvancedoptionsCheckBox.Value = 0;
                app.AdvancedoptionsCheckBox.Enable = "on";
                app.EventThresholdEditField.Enable = "on";
                app.ThresholdNEditField_2Label.Enable = "on";
            else
                app.AdvancedoptionsCheckBox.Value = 0;
                app.AdvancedoptionsCheckBox.Enable = "off";
                app.EventThresholdEditField.Enable = "off";
                app.ThresholdNEditField_2Label.Enable = "off";
            end
        end

        % Value changed function: FilterKinematicsCheckBox
        function FilterKinematicsCheckBoxValueChanged(app, event)
            if app.FilterKinematicsCheckBox.Value
                app.KinematicCutOffHzEditField.Enable = "on";
                app.CutOffHzEditFieldLabel.Enable = "on";
                app.KinematicOrderEditField.Enable = "on";
                app.OrderEditFieldLabel.Enable = "on";
            else
                app.KinematicCutOffHzEditField.Enable = "off";
                app.CutOffHzEditFieldLabel.Enable = "off";
                app.KinematicOrderEditField.Enable = "off";
                app.OrderEditFieldLabel.Enable = "off";
            end
        end

        % Button pushed function: ExportWorkspace
        function ExportWorkspaceButtonPushed(app, event)
            v.Trials=table2struct(app.UITable.Data);
            v.Settingsapp.getSettings;
            [f,p]=uiputfile(app.MainPathEditField.Value+".xml","Select export file");
            writestruct(v,fullfile(p,f));
        end

        % Button pushed function: LoadWorkspace
        function LoadWorkspaceButtonPushed(app, event)
            if height(app.UITable.Data)>0
                answer=questdlg("The following operation will clear your existing workspace, prooced?");
                if answer=="Yes"
                else
                    return
                end
            end
            [f,p]=uigetfile(app.MainPathEditField.Value+".xml","Select workspace file");
            v=readstruct(fullfile(p,f));
            app.UITable.Data=struct2table(v.Trials);
            if isfield(v,"Settings")
            app.setSettings(v.Settings);
            end

        end

        % Button pushed function: SaveSettingsasdefaultButton
        function SaveSettingsasdefaultButtonPushed(app, event)
            sett.Settings=app.getSettings;
            mfilePath = fileparts(mfilename('fullpath'));
            writestruct(sett,fullfile(mfilePath,"default_settings.xml"));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SaveTool = uipushtool(app.Toolbar);
            app.SaveTool.ClickedCallback = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveTool.Enable = 'off';
            app.SaveTool.Icon = 'file_save.png';
            app.CloseTool = uipushtool(app.Toolbar);
            app.CloseTool.ClickedCallback = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseTool.Icon = 'vote-no.png';
            app.AddTrialtoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.AddTrialtoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialtoWorkspaceButtonPushed, true);
            app.AddTrialtoWorkspaceButton.Tooltip = {'Import a static trial with a configuration and one or more dynamic trials into the workspace'};
            app.AddTrialtoWorkspaceButton.Text = 'Add Trial to Workspace';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Status'; 'Trial'; 'Static'; 'Configuration'; 'Classification'; 'Subject'; 'FootModel'; 'FootCat'; 'SocketName'; 'SocketAlignment'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Icon = 'help_gs.png';
            app.RunAnalysisButton.Tooltip = {'Run the analysis for the workspace using the selected options.'};
            app.RunAnalysisButton.Text = 'RunAnalysis';
            app.RemovefromWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.RemovefromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefromWorkspaceButtonPushed, true);
            app.RemovefromWorkspaceButton.Tooltip = {'Remove items from the workspace'};
            app.RemovefromWorkspaceButton.Text = 'Remove from Workspace';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.TrialAnalyzerLabel = uilabel(app.UIFigure);
            app.TrialAnalyzerLabel.Text = 'Trial Analyzer';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.png';
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'unipd.png';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.MainsubjectfolderLabel = uilabel(app.UIFigure);
            app.MainsubjectfolderLabel.Text = 'Main working directory:';
            app.MainPathEditField = uieditfield(app.UIFigure, 'text');
            app.MainPathEditField.Enable = 'off';
            app.GENERALPanel = uipanel(app.UIFigure);
            app.GENERALPanel.Title = 'GENERAL';
            app.TargetGroundDropDown = uidropdown(app.GENERALPanel);
            app.TargetGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.TargetGroundDropDown.Value = 'Z-UP, X-LAT';
            app.AbsoluteanglescorrectionLabel = uilabel(app.GENERALPanel);
            app.AbsoluteanglescorrectionLabel.Tooltip = {'Applies a rotation to point coordinates and force plates to adjust the ground orientation.'};
            app.AbsoluteanglescorrectionLabel.Text = 'Absolute angles correction';
            app.FileGroundDropDown = uidropdown(app.GENERALPanel);
            app.FileGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.FileGroundDropDown.Value = 'Z-UP, X-LAT';
            app.rightarrowLabel = uilabel(app.GENERALPanel);
            app.rightarrowLabel.Interpreter = 'tex';
            app.rightarrowLabel.Text = '\rightarrow';
            app.UseInertialPropertiesCheckBox = uicheckbox(app.GENERALPanel);
            app.UseInertialPropertiesCheckBox.Text = 'Use Inertial Properties';
            app.UseInertialPropertiesCheckBox.Value = true;
            app.Switch = uiswitch(app.GENERALPanel, 'slider');
            app.Switch.Items = {'All Entities', 'Absolute Angles'};
            app.Switch.Tooltip = {'Decide wheter to apply the rotation to the whole C3D file or just to Absolute angles, by referencing to an auxiliary and fixed reference system'};
            app.Switch.Value = 'Absolute Angles';
            app.TASKSELECTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.TASKSELECTIONButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TASKSELECTIONButtonGroupSelectionChanged, true);
            app.TASKSELECTIONButtonGroup.Title = 'TASK SELECTION';
            app.WalkingButton = uitogglebutton(app.TASKSELECTIONButtonGroup);
            app.WalkingButton.Text = 'Walking';
            app.RunningButton = uitogglebutton(app.TASKSELECTIONButtonGroup);
            app.RunningButton.Text = 'Running';
            app.RunningButton.Value = true;
            app.LongjumpingButton = uitogglebutton(app.TASKSELECTIONButtonGroup);
            app.LongjumpingButton.Enable = 'off';
            app.LongjumpingButton.Text = 'Long jumping';
            app.GeneralButton = uitogglebutton(app.TASKSELECTIONButtonGroup);
            app.GeneralButton.Tooltip = {'(Kinematics only) This task seclection should be used for general purposes, when segments and joints kinematics is sought and no event associated with specific tasks are stored and used to refer the model output to.'};
            app.GeneralButton.Text = 'General';
            app.FORCEPLATEPanel = uipanel(app.UIFigure);
            app.FORCEPLATEPanel.Title = 'FORCE PLATE';
            app.FilterForcePlatesCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.FilterForcePlatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterForcePlatesCheckBoxValueChanged, true);
            app.FilterForcePlatesCheckBox.Tooltip = {'Applies a low-pass Butterworth filter to the force plates using the filtfilt function. Note that the function will double the specified order.'};
            app.FilterForcePlatesCheckBox.Text = 'Filter ForcePlates';
            app.FilterForcePlatesCheckBox.Value = true;
            app.OrderEditField_2Label = uilabel(app.FORCEPLATEPanel);
            app.OrderEditField_2Label.Text = 'Order';
            app.ForceOrderEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.ForceOrderEditField.Limits = [1 Inf];
            app.ForceOrderEditField.Value = 4;
            app.CutOffHzEditField_2Label = uilabel(app.FORCEPLATEPanel);
            app.CutOffHzEditField_2Label.Text = 'Cut-Off (Hz)';
            app.ForceCutOffHzEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.ForceCutOffHzEditField.Limits = [1 Inf];
            app.ForceCutOffHzEditField.Value = 100;
            app.MaxDurationmsEditFieldLabel = uilabel(app.FORCEPLATEPanel);
            app.MaxDurationmsEditFieldLabel.Enable = 'off';
            app.MaxDurationmsEditFieldLabel.Tooltip = {'This parameter sets the width of a mask  centered on the maximum value recorded by the individual force platform. Any value outside of this mask will be automatically set 0 '};
            app.MaxDurationmsEditFieldLabel.Text = 'Max Duration (ms)';
            app.MaxDurationmsEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.MaxDurationmsEditField.Limits = [0 Inf];
            app.MaxDurationmsEditField.Enable = 'off';
            app.MaxDurationmsEditField.Tooltip = {'Maxiumum duration of a contact'};
            app.MaxDurationmsEditField.Value = 100;
            app.MaxContactsEditFieldLabel = uilabel(app.FORCEPLATEPanel);
            app.MaxContactsEditFieldLabel.Enable = 'off';
            app.MaxContactsEditFieldLabel.Tooltip = {'This parameter controls the maximum number of contacts allowed for each force platform.'};
            app.MaxContactsEditFieldLabel.Text = 'Max Contacts';
            app.MaxContactsEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.MaxContactsEditField.Limits = [1 1000000];
            app.MaxContactsEditField.Enable = 'off';
            app.MaxContactsEditField.Tooltip = {'Maximum number of contacts for each force platform'};
            app.MaxContactsEditField.Value = 100;
            app.ValidThresholdNLabel = uilabel(app.FORCEPLATEPanel);
            app.ValidThresholdNLabel.Enable = 'off';
            app.ValidThresholdNLabel.Tooltip = {'Sets the threshold under which the forceplatform could not be considered active (i.e., is recording only noise and cross-talk)'};
            app.ValidThresholdNLabel.Text = 'Valid Threshold (N)';
            app.ValidThresholdNEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.ValidThresholdNEditField.Limits = [1 Inf];
            app.ValidThresholdNEditField.Enable = 'off';
            app.ValidThresholdNEditField.Tooltip = {'Threshold for determining if a force platform is active or not'};
            app.ValidThresholdNEditField.Value = 100;
            app.DetectEventsCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.DetectEventsCheckBox.Tooltip = {'Detects the FootStrike and FootOff events using force platform data. Any existing FootOff and FootStrike event will be deleted.'};
            app.DetectEventsCheckBox.Text = 'Detect Events';
            app.ThresholdNEditField_2Label = uilabel(app.FORCEPLATEPanel);
            app.ThresholdNEditField_2Label.Text = 'Threshold (N)';
            app.EventThresholdEditField = uieditfield(app.FORCEPLATEPanel, 'numeric');
            app.EventThresholdEditField.Limits = [1 Inf];
            app.EventThresholdEditField.Tooltip = {'Threshold for event detection'};
            app.EventThresholdEditField.Value = 20;
            app.CombineForcePlatesCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.CombineForcePlatesCheckBox.Tooltip = {''};
            app.CombineForcePlatesCheckBox.Text = '';
            app.CombineForcePlatesLabel = uilabel(app.FORCEPLATEPanel);
            app.CombineForcePlatesLabel.Tooltip = {'Combines all the force plates in the acquisition to a single one'};
            app.CombineForcePlatesLabel.Text = 'Combine ForcePlates';
            app.ReflectSignalforfilteringCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.ReflectSignalforfilteringCheckBox.Text = 'Reflect Signal for filtering';
            app.CrossTalkRemovalCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.CrossTalkRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossTalkRemovalCheckBoxValueChanged, true);
            app.CrossTalkRemovalCheckBox.Text = 'Cross-Talk Removal';
            app.CrossTalkRemovalCheckBox.Value = true;
            app.AdvancedoptionsCheckBox = uicheckbox(app.FORCEPLATEPanel);
            app.AdvancedoptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedoptionsCheckBoxValueChanged, true);
            app.AdvancedoptionsCheckBox.Text = 'Advanced options';
            app.KINEMATICSPanel = uipanel(app.UIFigure);
            app.KINEMATICSPanel.Title = 'KINEMATICS';
            app.FilterKinematicsCheckBox = uicheckbox(app.KINEMATICSPanel);
            app.FilterKinematicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterKinematicsCheckBoxValueChanged, true);
            app.FilterKinematicsCheckBox.Tooltip = {'Apply a Butterworth low-pass filter using filtfilt function. Note that the specified Order will be doubled by the filtfilt algorithm'};
            app.FilterKinematicsCheckBox.Text = 'Filter Kinematics';
            app.FilterKinematicsCheckBox.Value = true;
            app.OrderEditFieldLabel = uilabel(app.KINEMATICSPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.KinematicOrderEditField = uieditfield(app.KINEMATICSPanel, 'numeric');
            app.KinematicOrderEditField.Limits = [1 Inf];
            app.KinematicOrderEditField.Value = 2;
            app.CutOffHzEditFieldLabel = uilabel(app.KINEMATICSPanel);
            app.CutOffHzEditFieldLabel.Text = 'Cut-Off (Hz)';
            app.KinematicCutOffHzEditField = uieditfield(app.KINEMATICSPanel, 'numeric');
            app.KinematicCutOffHzEditField.Limits = [0 Inf];
            app.KinematicCutOffHzEditField.Value = 15;
            app.ApplySVDoptimizationCheckBox = uicheckbox(app.KINEMATICSPanel);
            app.ApplySVDoptimizationCheckBox.Tooltip = {'Apply the SVD algorithm to optimize the marker position based on cluster coordinates. This won''t affect the SVD reconstruction of static-only markers'};
            app.ApplySVDoptimizationCheckBox.Text = 'Apply SVD optimization';
            app.ApplySVDoptimizationCheckBox.Value = true;
            app.ClearUnlabeledTracksCheckBox = uicheckbox(app.KINEMATICSPanel);
            app.ClearUnlabeledTracksCheckBox.Tooltip = {'Removes any unlabeled point from the trial, unlabeled track are usually identified by a C_ prefix'};
            app.ClearUnlabeledTracksCheckBox.Text = 'Clear Unlabeled Tracks';
            app.ClearUnlabeledTracksCheckBox.Value = true;
            app.ClearUnlabeledEditField = uieditfield(app.KINEMATICSPanel, 'text');
            app.ClearUnlabeledEditField.Value = 'C_';
            app.SAVEOPTIONSPanel = uipanel(app.UIFigure);
            app.SAVEOPTIONSPanel.Title = 'SAVE OPTIONS';
            app.C3DCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.C3DCheckBox.ValueChangedFcn = createCallbackFcn(app, @C3DCheckBoxValueChanged, true);
            app.C3DCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.C3DCheckBox.Text = 'C3D';
            app.C3DCheckBox.Value = true;
            app.combinedmatCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.combinedmatCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.combinedmatCheckBox.Text = 'combined .mat';
            app.OpenC3DafterexportCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.OpenC3DafterexportCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.OpenC3DafterexportCheckBox.Enable = 'off';
            app.OpenC3DafterexportCheckBox.Text = 'Open C3D after export';
            app.matCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.matCheckBox.ValueChangedFcn = createCallbackFcn(app, @matCheckBoxValueChanged, true);
            app.matCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.matCheckBox.Text = '.mat';
            app.matCheckBox.Value = true;
            app.KeepOriginalEditField = uieditfield(app.SAVEOPTIONSPanel, 'text');
            app.KeepOriginalEditField.Editable = 'off';
            app.KeepOriginalEditField.Tooltip = {'Suffix to be assigned to identify the original file'};
            app.KeepOriginalEditField.Value = '.labeled';
            app.addtoDBCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.addtoDBCheckBox.Tooltip = {'Add the trials in the workspace to the selected excel Database'};
            app.addtoDBCheckBox.Text = 'add to DB';
            app.BrowseButton = uibutton(app.SAVEOPTIONSPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'file_open.png';
            app.BrowseButton.Tooltip = {'Open a db file or create a new one'};
            app.BrowseButton.Text = 'Browse';
            app.relativepathCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.relativepathCheckBox.Tooltip = {'Set the path of the file in the DB to be relative with respect to the DB'};
            app.relativepathCheckBox.Text = 'relative path';
            app.relativepathCheckBox.Value = true;
            app.DBfileEditField = uieditfield(app.SAVEOPTIONSPanel, 'text');
            app.DBfileEditField.Placeholder = 'DB file';
            app.KeepOriginalCheckBox = uicheckbox(app.SAVEOPTIONSPanel);
            app.KeepOriginalCheckBox.Tooltip = {'Specify wheter to keep an original copy of the file during saving'};
            app.KeepOriginalCheckBox.Text = 'KeepOriginal';
            app.KeepOriginalCheckBox.Value = true;
            app.SaveSettingsasdefaultButton = uibutton(app.SAVEOPTIONSPanel, 'push');
            app.SaveSettingsasdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsasdefaultButtonPushed, true);
            app.SaveSettingsasdefaultButton.Text = 'Save Settings as default';
            app.LogpanelPanel = uipanel(app.UIFigure);
            app.LogpanelPanel.Title = 'Log panel';
            app.LogTextArea = uitextarea(app.LogpanelPanel);
            app.LogTextArea.Editable = 'off';
            app.ClearlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ExportlogButton = uibutton(app.LogpanelPanel, 'push');
            app.ExportlogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlogButtonPushed, true);
            app.ExportlogButton.Icon = 'pageicon.gif';
            app.ExportlogButton.Text = 'Export log';
            app.IdleLampLabel = uilabel(app.LogpanelPanel);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.LogpanelPanel);
            app.LoadWorkspace = uibutton(app.UIFigure, 'push');
            app.LoadWorkspace.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkspaceButtonPushed, true);
            app.LoadWorkspace.Text = 'Load Workspace';
            app.ExportWorkspace = uibutton(app.UIFigure, 'push');
            app.ExportWorkspace.ButtonPushedFcn = createCallbackFcn(app, @ExportWorkspaceButtonPushed, true);
            app.ExportWorkspace.Text = 'Export Workspace';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'file_save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end